classdef AB_listening_2_GUI < matlab.apps.AppBase
    %AB_LISTENING_2_GUI Runs A/B paired comparison listening test
    %   Runs A/B paired comparison listening test for grouped audio
    %   contained in \Audio\Ref and \Audio\Test\ subdirectories.
    %   
    %   Participant can play each audio file as many times as wanted.
    %   'Next' button is disabled until both sounds have been
    %   played through once.
    %   
    %   Participant response is set to 'which sound is more annoying?'.
    %   
    %   Records play/next function timing for further response analysis.
    %   
    %   Outputs to .xlsx spreadsheet files (1 response data, 1 timing
    %   data).
    %   
    %   Requirements
    %   ------------
    %   Audio Toolbox
    %   DSP Systems Toolbox
    %   
    %   Assumptions
    %   -----------
    %   The audio device used must support the audio file properties. For
    %   example, an error will arise if the output device does not support
    %   the number of channels in an audio file. The device to be used is
    %   selected from those detected on launch. On Windows OS, the driver
    %   type is selected prior to selecting a device compatible with the
    %   selected driver.
    %   The test files in the \Test\ folder must be arranged in at least 1
    %   subfolder(s), and the reference files in the \Ref folder will each
    %   be compared with each test file, in groups according to the
    %   subfolders in \Test\.
    %   
    %   Ownership and Quality Assurance
    %   -------------------------------
    %   Author: Mike JB Lotinga (m.j.lotinga@edu.salford.ac.uk)
    %   Institution: University of Salford
    %   
    %   Date created: 29/09/2023
    %   Date last modified: 19/10/2023
    %   MATLAB version: 2023b
    %   
    %   Copyright statement: This file and code is part of work undertaken
    %   within the RefMap project (www.refmap.eu), and is subject to
    %   licence as detailed in the code repository
    %   (https://github.com/acoustics-code-salford/refmap-psychoacoustics)
    %   
    %   This code was developed from original files 'Test_Part2.mlapp' and
    %   'AB_Loudness_GUI_v0.mlapp' authored by University of Salford
    %   students.
    %   
    %   Checked by:
    %   Date last checked:

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        TabGroup          matlab.ui.container.TabGroup
        READMETab         matlab.ui.container.Tab
        GridLayout2       matlab.ui.container.GridLayout
        Instruction_3     matlab.ui.control.Label
        TESTTab           matlab.ui.container.Tab
        GridLayout3       matlab.ui.container.GridLayout
        AB_pick           matlab.ui.container.ButtonGroup
        BButton           matlab.ui.control.RadioButton
        AButton           matlab.ui.control.RadioButton
        Instruction_2     matlab.ui.control.Label
        LampB             matlab.ui.control.Lamp
        trialsLabel       matlab.ui.control.Label
        ofLabel           matlab.ui.control.Label
        TotalN            matlab.ui.control.Label
        CurrentN          matlab.ui.control.Label
        LampA             matlab.ui.control.Lamp
        PlayBSwitch       matlab.ui.control.Switch
        PlayBSwitchLabel  matlab.ui.control.Label
        Instruction       matlab.ui.control.Label
        NextButton        matlab.ui.control.Button
        PlayASwitch       matlab.ui.control.Switch
        PlayASwitchLabel  matlab.ui.control.Label
        StopButton        matlab.ui.control.Button
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        directory  % Main working directory
        subdirRef  % Subdirectory for reference stimuli
        subdirTest  % Subdirectory for test stimuli
        TestOrder  % Randomised order for test stimuli groups
        subsubdirTest  % Subsubdirectory for test stimuli
        TestSubFolders  % list of test group folders
        
        StimuliRef  % Reference stimuli set
        StimuliTest  % Test stimuli set

        readerRef  % dsp.AudioFileReader object for current reference stimuli
        readerTest  % dsp.AudioFileReader object for current test stimuli
        readerCell  % cell array containing dsp.AudioFileReader objects for both sets

        player  % audioDeviceWriter object
        audiodevice  % audio device to use
        audiodriver  % audio device driver to use
        
        AudioDeviceSettingsInputFig  % UI figure to input samples per frame
        AudioDeviceSettingsOKButton  % UI control for input figure

        ASIOSettingsCheck  % check box for audio device-specific ASIO settings

        SamplesPerFrame  % number of samples per frame to use for audio device
        SamplesPerFrameKnob  % UI control for input figure

        ChannelCheck  % check box for audio device channel mapping mode
        ChannelMapMode  % audio device channel mapping mode
        ChannelMapFields  % audio device channel mapping data entry fields
        ChannelMap  % audio device channel mapping

        randAB  % indices to randomly assign stimuli sets to A or B
        whichA  % index assigned to player A
        whichB  % index assigned to player B

        NTrials  % Total number of trials
        nTrial  % Trial number counter
        NGroup  % Number of test stimuli groups
        nGroup  % Group number counter
        NTestInGroup   % Total number of test files in current group
        NRef  % Total number of reference files
        NTrialsInGroup  % Total number of trials in group
        nTrialInGroup  % Trials in group counter
        
        ParticipantNo  % Participant ID number
        Participant_TestData  % Participant test data output table

        t_date  % timestamp date
        t_time  % timestamp time

        Timescale  % Test timing data output table

        % PlayASwitched  % flag to indicate Play A switched on
        % PlayBSwitched  % flag to indicate Play B switched on
        PlayAComplete  % flag to indicate stimulus A played completely
        PlayBComplete  % flag to indicate stimulus B played completely

        PlayASwitchVal  % value of the Play A switch
        PlayBSwitchVal  % value of the Play B switch

        TogglePlayA  % flag to toggle play of stimulus A
        TogglePlayB  % flag to toggle play of stimulus B

        ABselected  % value of the A/B pick selection
    end
    
    methods (Access = private)
        % function to initialise audio device and driver selection
        function AudioDeviceDriverSelection(app)
            % select from connected audio devices (Windows OS)
            if ispc
                % check which driver devices are present
                drivers = ["ASIO", "WASAPI", "DirectSound"];
                driversOn = [];
                for ii = 1:length(drivers)
                    adw = audioDeviceWriter('Driver', drivers(ii));
                    devices = getAudioDevices(adw);
                    if contains('No audio output device detected', devices)
                    else
                        driversOn = cat(1, driversOn, [drivers(ii)]);
                    end  % end of if branch for storing drivers present
                end  % end of if branch for identifying drivers present
                
                % open selection dialog for device drivers detected
                tfDriver = 0;
                while tfDriver == 0    
                    [indx,tfDriver] = listdlg('ListString', driversOn,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio driver type");
                end  % end while loop for audio driver selection

                % assign driver type
                app.audiodriver = driversOn(indx);

                % assign devices for selected audio driver
                adw = audioDeviceWriter('Driver', app.audiodriver);
                devices = getAudioDevices(adw);

                % open selection dialog for audio devices
                tfDevice = 0;
                while tfDevice == 0
                    
                    [indx,tfDevice] = listdlg('ListString', devices,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio output device");
                end  % end while loop for audio device selection

                % assign device
                app.audiodevice = devices{indx};

            else  % mac OS and linux
                % assign devices for selected driver
                adw = audioDeviceWriter;
                devices = getAudioDevices(adw);

                % open selection dialog for devices
                tfDevice = 0;
                while tfDevice == 0
                    
                    [indx,tfDevice] = listdlg('ListString', devices,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio output device");
                end

                % assign device
                app.audiodevice = devices{indx};
                app.audiodriver = [];  % mac OS and linux use native drivers
            end  % end of if branch to check connected audio devices
            release(adw)  % release audioDeviceWriter object

        end

        % interface function to select settings for audio device
        function AudioDeviceSettings(app)
 
            % get audio device info
            adw = audioDeviceWriter('Device', app.audiodevice,...
                                    'Driver', app.audiodriver);
            deviceInfo = info(adw);

            app.AudioDeviceSettingsInputFig = uifigure('Name', "Audio device settings",...
                                                       'Position',...
                                                       [300, 250, 500, 300]);
            % centre the figure
            movegui(app.AudioDeviceSettingsInputFig, 'center');
            
            % input knob for samples per frame
            app.SamplesPerFrameKnob = uiknob(app.AudioDeviceSettingsInputFig,...
                                             "discrete", 'Position', [100, 120, 60, 60]);
            app.SamplesPerFrameKnob.Items = ["512", "1024", "2048", "4096", "8192"];
            app.SamplesPerFrameKnob.Value = app.SamplesPerFrameKnob.Items(2);
            % assign knob callback function
            app.SamplesPerFrameKnob.ValueChangedFcn = {@app.SamplesPerFrameInputChange};
            
            uilabel(app.AudioDeviceSettingsInputFig, 'Position', [10, 40, 220, 50],...
                    'VerticalAlignment', 'top', 'WordWrap', 'on',...
                    'Text', "Set the number of samples per frame for the selected audio device");

            % ASIO device settings option
            app.ASIOSettingsCheck = uicheckbox(app.AudioDeviceSettingsInputFig,...
                                               'Value', 0, 'Text',...
                                               "Open device-specific ASIO settings",...
                                               'WordWrap', 'on',...
                                               'Position', [10, 240, 220, 44]);
            % if ASIO driver not selected, disable check box
            if strcmp(app.audiodriver, 'ASIO')
                app.ASIOSettingsCheck.Enable = 'on';
                app.ASIOSettingsCheck.Visible = 'on';
            else
                app.ASIOSettingsCheck.Enable = 'off';
                app.ASIOSettingsCheck.Visible = 'off';
            end

            % channel mode check box
            app.ChannelCheck = uicheckbox(app.AudioDeviceSettingsInputFig,...
                                          'Value', 1, 'Text', "Auto",...
                                          'Position', [258, 100, 84, 22],...
                                          'Enable', 'on');
            % set default channel map mode
            app.ChannelMapMode = 'Auto';

            % assign check box callback function
            app.ChannelCheck.ValueChangedFcn = {@app.AutoChannelMap};

            % define the channel mapping input fields
            for ii = 1:deviceInfo.MaximumOutputChannels
                if ii <= 96
                    app.ChannelMapFields{ii} = uieditfield(app.AudioDeviceSettingsInputFig,...
                                                           'numeric', 'Limits', ...
                                                           [min(adw.ChannelMapping), ...
                                                            max(adw.ChannelMapping)], ...
                                                           'Value', adw.ChannelMapping(ii), ...
                                                           'ValueDisplayFormat', '%g', ...
                                                           'AllowEmpty', 'on',...
                                                           'Position', [290, 100 + (ii*30), 31, 22],...
                                                           'Enable', 'off',...
                                                           'RoundFractionalValues', 'on');
    
                    uilabel(app.AudioDeviceSettingsInputFig, 'Position', [260, 100 + (ii*30), 31, 22],...
                            'Text', num2str(adw.ChannelMapping(ii)) + ":");
                elseif ii <= 192
                    app.ChannelMapFields{ii} = uieditfield(app.AudioDeviceSettingsInputFig,...
                                                           'numeric', 'Limits', ...
                                                           [min(adw.ChannelMapping), ...
                                                            max(adw.ChannelMapping)], ...
                                                           'Value', adw.ChannelMapping(ii), ...
                                                           'ValueDisplayFormat', '%g', ...
                                                           'AllowEmpty', 'on',...
                                                           'Position', [360, 100 + ((ii - 96)*30), 31, 22],...
                                                           'Enable', 'off',...
                                                           'RoundFractionalValues', 'on');
    
                    uilabel(app.AudioDeviceSettingsInputFig, 'Position', [330, 100 + ((ii - 96)*30), 31, 22],...
                            'Text', num2str(adw.ChannelMapping(ii)) + ":");
                else
                    app.ChannelMapFields{ii} = uieditfield(app.AudioDeviceSettingsInputFig,...
                                                           'numeric', 'Limits', ...
                                                           [min(adw.ChannelMapping), ...
                                                            max(adw.ChannelMapping)], ...
                                                           'Value', adw.ChannelMapping(ii), ...
                                                           'ValueDisplayFormat', '%g', ...
                                                           'AllowEmpty', 'on',...
                                                           'Position', [430, 100 + ((ii - 192)*30), 31, 22],...
                                                           'Enable', 'off',...
                                                           'RoundFractionalValues', 'on');
    
                    uilabel(app.AudioDeviceSettingsInputFig, 'Position', [400, 100 + ((ii - 192)*30), 31, 22],...
                            'Text', num2str(adw.ChannelMapping(ii)) + ":");
                end

            end             
            uilabel(app.AudioDeviceSettingsInputFig, 'Position', [260, 40, 220, 50],...
                    'VerticalAlignment', 'top', 'WordWrap', 'on',...
                    'Text', "Define the channel mapping for the selected audio device (audio file channel: device output)");
            
            app.AudioDeviceSettingsInputFig.Scrollable = 'on';

            % input OK button to confirm settings
            app.AudioDeviceSettingsOKButton = uibutton(app.AudioDeviceSettingsInputFig,...
                                                       'push', 'Text', 'OK',...
                                                       'Position', [220, 10, 60, 22]);
            % assign OK button callback function
            app.AudioDeviceSettingsOKButton.ButtonPushedFcn = {@app.CloseAudioDeviceSettingsInput};

            % set window position to origin
            pause(0.01);  % a hack line to get MATLAB to recognise the scroll function
            scroll(app.AudioDeviceSettingsInputFig, 'left', 'bottom')

            release(adw)  % release audioDeviceWriter object
            
        end

        % callback function for SamplesPerFrameInputFig knob value
        function SamplesPerFrameInputChange(app, ~, ~)
            % assign SamplesPerFrame value to variable
            app.SamplesPerFrame = str2double(app.SamplesPerFrameKnob.Value);
        end

        % callback function for AudioDeviceSettingsInputFig OK button
        function CloseAudioDeviceSettingsInput(app, ~, ~)
            % assign SamplesPerFrame value to variable
            app.SamplesPerFrame = str2double(app.SamplesPerFrameKnob.Value);
            for ii = 1:info(audioDeviceWriter('Device', app.audiodevice,...
                                              'Driver', app.audiodriver)).MaximumOutputChannels
                app.ChannelMap(ii) = app.ChannelMapFields{ii}.Value;
            end

            % if open ASIO settings checked, 
            if app.ASIOSettingsCheck.Enable
                asiosettings(app.audiodevice)
            end
            
            close(app.AudioDeviceSettingsInputFig)  % close figure

        end

        % callback function for ChannelCheck box
        function AutoChannelMap(app, ~, ~)
            % enable/disable channel map fields depending on selected mode
            for ii = 1:info(audioDeviceWriter('Device', app.audiodevice,...
                                              'Driver', app.audiodriver)).MaximumOutputChannels
                app.ChannelMapFields{ii}.Enable = ~app.ChannelCheck.Value;
            end

            % update channel map mode according to input
            switch app.ChannelCheck.Value
                case 0
                    app.ChannelMapMode = 'Property';
                otherwise
                    app.ChannelMapMode = 'Auto';
            end
        end

        % function to trigger when stimulus playback ends
        function StimTogglePlay = StimEnd(app, StimPlayer, StimSwitch, StimLamp)
            StimTogglePlay = StimOff(app, StimPlayer, StimSwitch, StimLamp);
        end  % end of StimEnd function
        
        % function to trigger when stimulus switched off
        function StimTogglePlay = StimOff(~, StimPlayer, StimSwitch, StimLamp)
            StimSwitch.Value = 'Off';
            StimLamp.Color = [0.5, 0.5, 0.5];
            StimTogglePlay = false;
            release(StimPlayer)
        end  % end of StimOff function
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';  % hide UI
            addpath(genpath(pwd));  % add subfolders to path

            % if results subfolder does not exist, create it
            if ~exist("Results", 'dir')
                mkdir("Results")
                addpath("Results")
            end  % end of results subfolder if branch

            % assign reference and test file folders
            app.directory = pwd;  % current working dir
            app.subdirRef = fullfile(app.directory, "Audio", "Ref");  % Ref stimuli dir
            app.subdirTest = fullfile(app.directory, "Audio", "Test");  % Test stimuli dir

            % generate random Test stimuli group order based on number of Test subfolders
            TestSubItems = dir(app.subdirTest);
            app.TestSubFolders = {TestSubItems.name};  % names of all folders and files
            app.TestSubFolders = app.TestSubFolders([TestSubItems.isdir]);  % filters to folders
            app.TestSubFolders = app.TestSubFolders(3:end);  % removes '.' and '..'

            app.NGroup = length(app.TestSubFolders);  % number of subfolders (groups)
            app.TestOrder = randperm(app.NGroup);  % randomise order
            % get path of subdirectory
            app.subsubdirTest = fullfile(app.subdirTest,...
                                         app.TestSubFolders(app.TestOrder(1)));

            % calculate total number of trials
            % number of reference files
            app.NRef = length(dir(fullfile(app.subdirRef, '*.wav')));
            % calculate total number of test files
            NTestAll = length(dir(fullfile(app.subdirTest, '**', '*.wav')));

            % total number of trials
            app.NTrials = NTestAll*app.NRef;

            % total number of test files in first group
            app.NTestInGroup = length(dir(fullfile(fullfile(app.subdirTest,...
                                                   app.TestSubFolders(app.TestOrder(1))),...
                                                   '*.wav')));

            % total number of trials in first group
            app.NTrialsInGroup = app.NTestInGroup*app.NRef;

            % form paired Ref/Test stimuli list
            refFiles = audioDatastore(app.subdirRef).Files;
            testFiles = audioDatastore(app.subsubdirTest).Files;
            % loop to duplicate each reference file for each test file
            reftestArray = {};
            for ii = 0:app.NRef-1
                [reftestArray{ii*app.NTestInGroup + 1:(ii + 1)*app.NTestInGroup, 1}] = deal(refFiles{ii + 1});
                reftestArray(ii*app.NTestInGroup + 1:(ii + 1)*app.NTestInGroup, 2) = testFiles;
            end  % end of for loop for duplicating filenames

            % shuffle paired list to give pseudo-random order
            s = size(reftestArray, 1);
            r = numel(unique([reftestArray{:, 1}]));
            idx = randperm(s);
            % while loop randomisation repeats until each row has a
            % reference file that is different to its adjacent files
            while any(diff(rem(idx, r)) == 0)
                idx = randperm(s);
            end  % end of while loop for pseudo-randomisation
            reftestArrayShuffled = reftestArray(idx, :);

            % assign stimuli files to variables
            app.StimuliRef = reftestArrayShuffled(:, 1);
            app.StimuliTest = reftestArrayShuffled(:, 2);
            
            % set switch initial state
            % app.PlayASwitched = 0;
            % app.PlayBSwitched = 0;
            % set play complete initial state
            app.PlayAComplete = 0;
            app.PlayBComplete = 0;

            % assign counters
            app.nTrial = 1;
            app.nGroup = 1;
            app.nTrialInGroup = 1;

            % add initial trial count text to UI
            app.CurrentN.Text = "1";
            app.TotalN.Text = sprintf("%s", string(app.NTrials));
            
            % disable next button on start
            app.NextButton.Enable = 'off';
            
            % initialise participant data
            app.Participant_TestData = table();
            
            % initialise audio stimuli
            app.readerRef = dsp.AudioFileReader(cell2mat(app.StimuliRef(1)));
            app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(1)));
            app.readerCell = {app.readerRef; app.readerTest};

            % randomise assignment of ref or test stimuli to A or B
            app.randAB = randperm(2);
            app.whichA = app.randAB(1);
            app.whichB = app.randAB(2);

            % select from connected audio devices (Windows OS)
            AudioDeviceDriverSelection(app);

            % open UI figure to enter settings for audio device
            AudioDeviceSettings(app);          
            waitfor(app.AudioDeviceSettingsInputFig)  % keeps app from opening until close of input

            % sort out app visual
            movegui(app.UIFigure, 'center');
            app.UIFigure.WindowState = 'fullscreen';

            % open participant number entry dialog
            app.ParticipantNo = inputdlg({'Enter participant number'});

            % if dialog input is non-numeric or is cancelled, reopen dialog
            if isempty(app.ParticipantNo) || isnan(str2double(cell2mat(app.ParticipantNo(1))))
              startupFcn(app)
              app.UIFigure.WindowState = 'fullscreen';
              return
            end  % end of if branch for input dialog

            app.UIFigure.Visible = 'on';  % show UI
        end

        % Value changed function: PlayASwitch
        function PlayASwitchValueChanged(app, event)
            % assign switch value to property
            app.PlayASwitchVal = app.PlayASwitch.Value;
            % % flag to indicate stimulus B played
            % app.PlayASwitched = 1;
            % % if both switches used, enable next button
            % if app.PlayASwitched*app.PlayBSwitched > 0
            %     app.NextButton.Enable = 'on';
            % end  % end of if branch for enabling next button

            % if switched on
            if strcmp(app.PlayASwitchVal, 'On')
                % switch off B stimulus (try in case player object has
                % not been created)
                try
                    app.TogglePlayB = StimOff(app, app.player, app.PlayBSwitch, app.LampB);
                    release(app.readerCell{app.whichB})
                catch
                end  % end of try catch branch to switch off stimulus B

                % update timing data
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.Timescale = [app.Timescale;...
                                 table(app.nTrial, string(app.t_date),...
                                 string(app.t_time), "Play A Switch",...
                                 'VariableNames', ["Trial Number",...
                                                   "Date", "Time",...
                                                   "Button Pressed"])];
                % switch on lamp
                app.LampA.Color = 'g';
                                
                % get audio file info
                fileInfo = audioinfo(app.readerCell{app.whichA}.Filename);

                % convert BitsPerSample number into BitDepth string
                try
                    switch fileInfo.BitsPerSample
                        case 8
                            BitDepth = "8-bit integer";
                        case 16
                            BitDepth = "16-bit integer";
                        case 24
                            BitDepth = "24-bit integer";
                        case 32
                            BitDepth = "32-bit float";
                    end
                catch
                    BitDepth = "16-bit integer";
                end

                % create player object
                if strcmp(app.ChannelMapMode, 'Property')
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerCell{app.whichB}.SampleRate,...
                                                   'BitDepth', BitDepth,...
                                                   'ChannelMappingSource', app.ChannelMapMode,...
                                                   'ChannelMapping', app.ChannelMap);
                else
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerCell{app.whichB}.SampleRate,...
                                                   'BitDepth', BitDepth);
                end

                % check device can support file channels and vice versa
                if info(app.player).MaximumOutputChannels < fileInfo.NumChannels
                    error("Error: audio device used does not support number of channels in file")
                elseif fileInfo.NumChannels == 1 && strcmp(app.ChannelMapMode, 'Property')
                    app.player.ChannelMapping = 1;
                elseif fileInfo.NumChannels < info(app.player).MaximumOutputChannels && strcmp(app.ChannelMapMode, 'Property')
                    error("Error: number of channels in file does not match channel mapping")
                end

                % Call setup to reduce the computational load of initialization in an audio stream loop.
                % openExample('dsp/ReadFromFileAndWriteToAudioDeviceExample')
                setup(app.player, zeros(app.SamplesPerFrame, fileInfo.NumChannels))

                % start playback functionality
                app.TogglePlayA = true;
                
                % while loop to stream audio data frames to player object
                % while loop to stream audio data frames to player object
                frameCount = 0;  % frame counter
                % count total number of frames in the reader file
                frameTotal = ceil(fileInfo.TotalSamples/app.readerCell{app.whichA}.SamplesPerFrame);
                try  % avoids error on app exit deletion if stimulus still playing
                    while ~isDone(app.readerCell{app.whichA}) && app.TogglePlayA
                        drawnow()  % updates app values
                        frameCount = frameCount + 1;  % update frame counter
                        % if filereader reaches end of file or stimulus A 
                        % switched off, toggle the switch and lamp, and
                        % release the file reader object
                        if strcmp(app.PlayASwitchVal, 'Off')
                            app.TogglePlayA = StimEnd(app, app.player, app.PlayASwitch, app.LampA);
                            release(app.readerCell{app.whichA})
                            break

                        else  % keep playing file
                            audioData = app.readerCell{app.whichA}();
                            app.player(audioData);

                        end  % end of if branch for end of stimulus A file
                    end  % end of while loop for streaming audio to player
                    if frameCount == frameTotal
                        app.PlayAComplete = 1;
                    end  % end of if branch for file played check

                    app.TogglePlayA = StimEnd(app, app.player, app.PlayASwitch, app.LampA);
                    release(app.readerCell{app.whichA})

                catch
                end  % end of try catch branch

                % if both stimuli played, enable next button
                if app.PlayAComplete*app.PlayBComplete > 0
                    app.NextButton.Enable = 'on';
                end  % end of if branch for enabling next button

            % if switched off, stop stimulus A
            else
                app.TogglePlayA = StimOff(app, app.player, app.PlayASwitch, app.LampA);
                release(app.readerCell{app.whichA})
            end  % end of if branch for stimulus A switched on
        end

        % Value changed function: PlayBSwitch
        function PlayBSwitchValueChanged(app, event)
            % assign switch value to property
            app.PlayBSwitchVal = app.PlayBSwitch.Value;
            % % flag to indicate stimulus B played
            % app.PlayBSwitched = 1;
            % % if both switches used, enable next button
            % if app.PlayBSwitched*app.PlayASwitched > 0
            %     app.NextButton.Enable = 'on';
            % end  % end of if branch for enabling next button

            % if switched on
            if strcmp(app.PlayBSwitchVal, 'On')
                % switch off A stimulus (try in case player object has
                % not been created)
                try
                    app.TogglePlayA = StimOff(app, app.player, app.PlayASwitch, app.LampA);
                    release(app.readerCell{app.whichA})
                catch
                end  % end of try catch branch to switch off stimulus A

                % update timing data
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.Timescale = [app.Timescale;...
                                 table(app.nTrial, string(app.t_date),...
                                 string(app.t_time), "Play B Switch",...
                                 'VariableNames', ["Trial Number",...
                                                   "Date", "Time",...
                                                   "Button Pressed"])];
                % switch on lamp
                app.LampB.Color = 'g';
   
                % get audio file info
                fileInfo = audioinfo(app.readerCell{app.whichB}.Filename);

                % convert BitsPerSample number into BitDepth string
                try
                    switch fileInfo.BitsPerSample
                        case 8
                            BitDepth = "8-bit integer";
                        case 16
                            BitDepth = "16-bit integer";
                        case 24
                            BitDepth = "24-bit integer";
                        case 32
                            BitDepth = "32-bit float";
                    end
                catch
                    BitDepth = "16-bit integer";
                end

                % create player object
                if strcmp(app.ChannelMapMode, 'Property')
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerCell{app.whichB}.SampleRate,...
                                                   'BitDepth', BitDepth,...
                                                   'ChannelMappingSource', app.ChannelMapMode,...
                                                   'ChannelMapping', app.ChannelMap);
                else
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerCell{app.whichB}.SampleRate,...
                                                   'BitDepth', BitDepth);
                end

                % check device can support file channels and vice versa
                if info(app.player).MaximumOutputChannels < fileInfo.NumChannels
                    error("Error: audio device used does not support number of channels in file")
                elseif fileInfo.NumChannels == 1 && strcmp(app.ChannelMapMode, 'Property')
                    app.player.ChannelMapping = 1;
                elseif fileInfo.NumChannels < info(app.player).MaximumOutputChannels && strcmp(app.ChannelMapMode, 'Property')
                    error("Error: number of channels in file does not match channel mapping")
                end

                % Call setup to reduce the computational load of initialization in an audio stream loop.
                % openExample('dsp/ReadFromFileAndWriteToAudioDeviceExample')
                setup(app.player, zeros(app.SamplesPerFrame, fileInfo.NumChannels))

                % start playback functionality
                app.TogglePlayB = true;
                
                % while loop to stream audio data frames to player object
                frameCount = 0;  % frame counter
                % count total number of frames in the reader file
                frameTotal = ceil(fileInfo.TotalSamples/app.readerCell{app.whichB}.SamplesPerFrame);
                try  % avoids error on app exit deletion if stimulus still playing
                    while ~isDone(app.readerCell{app.whichB}) && app.TogglePlayB
                        drawnow()  % updates app values
                        frameCount = frameCount + 1;  % update frame counter
                        % if filereader reaches end of file or stimulus B 
                        % switched off, toggle the switch and lamp, and
                        % release the file reader object
                        if strcmp(app.PlayBSwitchVal, 'Off')
                            app.TogglePlayB = StimEnd(app, app.player, app.PlayBSwitch, app.LampB);
                            release(app.readerCell{app.whichB})
                            break
                            
                        else  % keep playing file
                            audioData = app.readerCell{app.whichB}();
                            app.player(audioData);
                            
                        end  % end of if branch for end of stimulus A file
                    end  % end of while loop for streaming audio to player
                    % check file played through
                    if frameCount == frameTotal
                        app.PlayBComplete = 1;
                    end  % end of if branch for file played check

                    app.TogglePlayB = StimEnd(app, app.player, app.PlayBSwitch, app.LampB);
                    release(app.readerCell{app.whichB})

                catch
                end  % end of try catch branch

                % if both stimuli played, enable next button
                if app.PlayBComplete*app.PlayAComplete > 0
                    app.NextButton.Enable = 'on';
                end  % end of if branch for enabling next button

            % if switched off, stop stimulus B
            else
                app.TogglePlayB = StimOff(app, app.player, app.PlayBSwitch, app.LampB);
                release(app.readerCell{app.whichB})
            end  % end of if branch for stimulus B switched on
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % switch off A stimulus, release player and file reader
            try
                app.TogglePlayA = StimOff(app, app.player, app.PlayASwitch, app.LampA);
            catch
            end  % end of try catch branch for switching off stimulus A
            release(app.readerCell{app.whichA})

            % switch off B stimulus, release player and file reader
            try
                app.TogglePlayB = StimOff(app, app.player, app.PlayBSwitch, app.LampB);
            catch
            end  % end of try catch branch for switching off stimulus B
            release(app.readerCell{app.whichB})
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % switch off A stimulus, release player and file reader
            try
                app.TogglePlayA = StimOff(app, app.player, app.PlayASwitch, app.LampA);
            catch
            end  % end of try catch branch for switching off stimulus A
            release(app.readerCell{app.whichA})

            % switch off B stimulus, release player and file reader
            try
                app.TogglePlayB = StimOff(app, app.player, app.PlayBSwitch, app.LampB);
            catch
            end  % end of try catch branch for switching off stimulus B
            release(app.readerCell{app.whichB})

            % reset flags to indicate stimuli played
            % app.PlayASwitched = 0;
            % app.PlayBSwitched = 0;
            app.PlayAComplete = 0;
            app.PlayBComplete = 0;

            % reassign current AB pick selection
            app.ABselected = app.AB_pick.SelectedObject;

            ResponseNames = ["Trial Number", "StimA File",...
                             "StimB File", "Stim AB selected"];

            % get filenames assigned to A and B
            filepathA = app.readerCell{app.whichA}.Filename;
            [~, filenameA, extA] = fileparts(filepathA);
            filenameA = {strcat(filenameA, extA)};

            filepathB = app.readerCell{app.whichB}.Filename;
            [~, filenameB, extB] = fileparts(filepathB);
            filenameB = {strcat(filenameB, extB)};

            % append participant test to table
            app.Participant_TestData = [app.Participant_TestData;...
                                        table(app.nTrial, filenameA, filenameB,...
                                              string(app.ABselected.Text),...
                                              'VariableNames', ResponseNames)];
            
            % append participant timing data to table
            app.t_date = datetime('now','Format','yyyy-MM-dd');
            app.t_time = datetime('now','Format','HH:mm:ss.SSS');
            
            app.Timescale = [app.Timescale;...
                             table(app.nTrial, string(app.t_date), ...
                                   string(app.t_time), "Next Button", 'VariableNames',...
                                   ["Trial Number", "Date", "Time", ...
                                   "Button Pressed"])];
            
            % reset switch initial state
            app.PlayASwitch.Value = 'Off';
            app.PlayBSwitch.Value = 'Off';
            
            % increment current trial number
            app.nTrial = app.nTrial + 1;

            % deactivate Next button for new trial
            app.NextButton.Enable = 'off';
            
            % if total trial number is reached, end test and write data
            if app.nTrial > app.NTrials             
                t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
                writetable(app.Participant_TestData, ...
                           fullfile(app.directory, "Results",...
                           sprintf('%s_ABTest_ParticipantNo%s_Response.xlsx',...
                           t_end, string(app.ParticipantNo))));
                writetable(app.Timescale, ...
                           fullfile(app.directory, "Results",...
                           sprintf('%s_ABTest_ParticipantNo%s_Timing.xlsx',...
                           t_end, string(app.ParticipantNo))));

                app.UIFigure.Visible = 'off';
                msgbox("Thank you for completing the test", ...
                       "Test complete", "help");
                delete(app);
            else
                % update trial count on UI
                app.CurrentN.Text = string(app.nTrial);

                % update trial in group counter
                app.nTrialInGroup = app.nTrialInGroup + 1;

                % if test files in group remaining
                if app.nTrialInGroup <= app.NTrialsInGroup
                    
                    % re-initialise reader objects
                    app.readerRef = dsp.AudioFileReader(cell2mat(app.StimuliRef(app.nTrialInGroup)));
                    app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrialInGroup)));
                    app.readerCell = {app.readerRef; app.readerTest};

                else  % if no trials in group remaining, move to next group
                    app.nGroup = app.nGroup + 1;
                    % reassign test file folder for new group
                    app.subsubdirTest = fullfile(app.subdirTest,...
                                                 app.TestSubFolders(app.TestOrder(app.nGroup)));
                    % total number of test files in next group
                    app.NTestInGroup = length(dir(fullfile(fullfile(app.subdirTest,...
                                                           app.TestSubFolders(app.TestOrder(app.nGroup))),...
                                                           '**', '*.wav')));

                    % total number of trials in next group
                    app.NTrialsInGroup = app.NTestInGroup*app.NRef;

                    % reassign stimuli sets
                    refFiles = audioDatastore(app.subdirRef).Files;
                    testFiles = audioDatastore(app.subsubdirTest).Files;
                    % loop to duplicate each reference file for each test file
                    reftestArray = {};
                    for ii = 0:app.NRef-1
                        [reftestArray{ii*app.NTestInGroup + 1:(ii + 1)*app.NTestInGroup, 1}] = deal(refFiles{ii + 1});
                        reftestArray(ii*app.NTestInGroup + 1:(ii + 1)*app.NTestInGroup, 2) = testFiles;
                    end  % end of for loop for duplicating filenames

                    % shuffle paired list to give pseudo-random order
                    s = size(reftestArray, 1);
                    r = numel(unique([reftestArray{:, 1}]));
                    % while loop randomisation repeats until each row has a
                    % reference file that is different to its adjacent files
                    idx = randperm(s);
                    while any(diff(rem(idx, r)) == 0)
                        idx = randperm(s);
                    end  % end of while loop for pseudo-randomisation
                    reftestArrayShuffled = reftestArray(idx, :);
        
                    % assign stimuli files to variables
                    app.StimuliRef = reftestArrayShuffled(:, 1);
                    app.StimuliTest = reftestArrayShuffled(:, 2);
                    
                    % set switch initial state
                    % app.PlayASwitched = 0;
                    % app.PlayBSwitched = 0;
                    app.PlayAComplete = 0;
                    app.PlayBComplete = 0;
        
                    % re-initialise audio stimuli
                    app.readerRef = dsp.AudioFileReader(cell2mat(app.StimuliRef(1)));
                    app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(1)));
                    app.readerCell = {app.readerRef; app.readerTest};

                    % reset trial in group counter
                    app.nTrialInGroup = 1;

                end  % end of if branch for test files in group remaining

                % re-randomise assignment of ref or test to A or B
                app.randAB = randperm(2);
                app.whichA = app.randAB(1);
                app.whichB = app.randAB(2);

            end  % end of if branch for total number of trials reached

        end

        % Selection changed function: AB_pick
        function AB_pickSelectionChanged(app, event)
            app.ABselected = app.AB_pick.SelectedObject;     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.READMETab = uitab(app.TabGroup);
            app.READMETab.Title = 'README';
            app.GridLayout2 = uigridlayout(app.READMETab);
            app.Instruction_3 = uilabel(app.GridLayout2);
            app.Instruction_3.Text = {'In each trial of this test, a pair of sounds is presented. Your task is to choose which of the pair you judge to be more annoying. There are no correct or incorrect answers in this test, only personal judgements of subjective impressions.'; ''; 'Each sound is played back independently using the controls. To advance to the next trial, each sound must be played to completion once. When each sound has completed playing, the playback control will automatically switch off. Sounds can also be stopped prematurely using the controls. The sounds can be played back as many times as desired.'; ''; 'Please enter your choice for each pair using the A/B selection control. When you have made your choice and advanced to the next trial, you will not be able to return to a previous trial to change your selection.'; ''; 'Please open the ''TEST'' tab above to start the test. You can review this information at any time during the test by clicking on the ''README'' tab above.'};
            app.TESTTab = uitab(app.TabGroup);
            app.TESTTab.Title = 'TEST';
            app.GridLayout3 = uigridlayout(app.TESTTab);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'Which sound is more annoying?';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayASwitchLabel = uilabel(app.GridLayout3);
            app.PlayASwitchLabel.Text = 'Play A';
            app.PlayASwitch = uiswitch(app.GridLayout3, 'slider');
            app.PlayASwitch.ValueChangedFcn = createCallbackFcn(app, @PlayASwitchValueChanged, true);
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Instruction = uilabel(app.GridLayout3);
            app.Instruction.Text = 'Listen to both sounds A & B, and choose which one is more annoying. ';
            app.PlayBSwitchLabel = uilabel(app.GridLayout3);
            app.PlayBSwitchLabel.Text = 'Play B';
            app.PlayBSwitch = uiswitch(app.GridLayout3, 'slider');
            app.PlayBSwitch.ValueChangedFcn = createCallbackFcn(app, @PlayBSwitchValueChanged, true);
            app.LampA = uilamp(app.GridLayout3);
            app.CurrentN = uilabel(app.GridLayout3);
            app.CurrentN.Text = 'n';
            app.TotalN = uilabel(app.GridLayout3);
            app.TotalN.Text = 'N';
            app.ofLabel = uilabel(app.GridLayout3);
            app.ofLabel.Text = 'of';
            app.trialsLabel = uilabel(app.GridLayout3);
            app.trialsLabel.Text = 'trials';
            app.LampB = uilamp(app.GridLayout3);
            app.Instruction_2 = uilabel(app.GridLayout3);
            app.Instruction_2.Text = '(You can listen to each sound more than once, but each sound must be played through once to advance.)';
            app.AB_pick = uibuttongroup(app.GridLayout3);
            app.AB_pick.SelectionChangedFcn = createCallbackFcn(app, @AB_pickSelectionChanged, true);
            app.AButton = uiradiobutton(app.AB_pick);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.AB_pick);
            app.BButton.Text = 'B';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AB_listening_2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end