classdef AB_annoyance_GUI < matlab.apps.AppBase
    %AB_ANNOYANCE_GUI Runs A/B paired comparison listening test
    %   Runs A/B paired comparison listening test for audio contained in
    %   \Audio\A and \Audio\B subdirectories.
    %   
    %   Participant can play each audio file as many times as wanted.
    %   'Next' button is disabled until both play switches have been
    %   activated once.
    %   
    %   Participant response is set to 'which sound is more annoying?'.
    %   
    %   Records play/next function timing for further response analysis.
    %   
    %   Outputs to .xlsx spreadsheet files (1 response data, 1 timing
    %   data).
    %   
    %   
    %   Requirements
    %   ------------
    %   Audio Toolbox
    %   
    %   Ownership and Quality Assurance
    %   -------------------------------
    %   Author: Mike JB Lotinga (m.j.lotinga@edu.salford.ac.uk)
    %   Institution: University of Salford
    %   
    %   Date created: 22/08/2023
    %   Date last modified: 04/09/2023
    %   MATLAB version: 2022b
    %   
    %   Copyright statement: This file and code is part of work undertaken
    %   within
    %   the RefMap project (www.refmap.eu), and is subject to licence as
    %   detailed
    %   in the code repository
    %   (https://github.com/acoustics-code-salford/refmap-psychoacoustics)
    %   
    %   This code was developed from original files 'Test_Part2.mlapp' and
    %   'AB_Loudness_GUI_v0.mlapp' authored by University of Salford
    %   students.
    %   
    %   Checked by:
    %   Date last checked:

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        PlayASwitch       matlab.ui.control.Switch
        PlayASwitchLabel  matlab.ui.control.Label
        PlayBSwitch       matlab.ui.control.Switch
        PlayBSwitchLabel  matlab.ui.control.Label
        trialsLabel       matlab.ui.control.Label
        ofLabel           matlab.ui.control.Label
        TotalN            matlab.ui.control.Label
        CurrentN          matlab.ui.control.Label
        LampB             matlab.ui.control.Lamp
        LampA             matlab.ui.control.Lamp
        AB_pick           matlab.ui.container.ButtonGroup
        BButton           matlab.ui.control.RadioButton
        AButton           matlab.ui.control.RadioButton
        Instruction       matlab.ui.control.Label
        NextButton        matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        directory % Main working directory
        subdirectoryA % Subdirectory A - UAS stimuli
        subdirectoryB % Subdirectory B - Other stimuli
        
        StimuliA % UAS stimuli set A
        StimuliB % Other stimuli set B

        playerA % audioplayer object for stimuli set A
        playerB % audioplayer object for stimuli set B
 
        nTrial % Trial number counter
        NTrials % Total number of trials (quantity of stimuli set A)
        
        ParticipantNo % Participant ID number
        Participant_TestData % Participant test data output table

        t_date  % timestamp date
        t_time  % timestamp time

        Timescale  % Test timing data output table

        PlayASwitched  % flag to indicate Play A switched on
        PlayBSwitched  % flag to indicate Play B switched on

        PlayASwitchVal % value of the Play A switch
        PlayBSwitchVal % value of the Play B switch

        ABselected  % value of the A/B pick selection
    end
    
    methods (Access = private)
        
        % function to trigger when stimuli playback ends
        function StimEnd(app, StimPlayer, StimSwitch, StimLamp)
            % for looped playback uncomment this block and comment next)
%             play(StimPlayer)
            % for end playback (uncomment this block and comment previous)
            StimOff(app, StimPlayer, StimSwitch, StimLamp);
        end
        
        % function to trigger when stimuli switched off
        function StimOff(~, StimPlayer, StimSwitch, StimLamp)
            StimSwitch.Value = 'Off';
            StimLamp.Color = [0.5, 0.5, 0.5];
            StimPlayer.StopFcn = [];  % line only needed when loop playback active
            stop(StimPlayer)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            addpath(genpath(pwd));
            app.directory = pwd;  % current working dir
            app.subdirectoryA = app.directory + "\Audio\A";  % stimuli A dir
            app.subdirectoryB = app.directory + "\Audio\B";  % stimuli B dir

            % assign stimuli files to variables
            app.StimuliA = shuffle(audioDatastore(app.subdirectoryA)).Files(1:end);
            app.StimuliB = shuffle(audioDatastore(app.subdirectoryB)).Files(1:end);
            app.NTrials = length(app.StimuliA);  % total number of trials
            % set switch initial state
            app.PlayASwitched = 0;
            app.PlayBSwitched = 0;

            app.nTrial = 1;
            app.CurrentN.Text = "1";
            app.TotalN.Text = sprintf("%s", string(app.NTrials));
            
            app.NextButton.Enable = 'off';  % disable next button on start
            
            % initialise participant data
            app.Participant_TestData = table();
            
            % initialise audio stimuli
            [x_A, fs_A] = audioread(cell2mat(app.StimuliA(app.nTrial)));
            app.playerA = audioplayer(x_A, fs_A);
            [x_B, fs_B] = audioread(cell2mat(app.StimuliB(app.nTrial)));
            app.playerB = audioplayer(x_B, fs_B);

            % sort out app visual
            movegui(app.UIFigure, 'center');
%             drawnow;
            app.UIFigure.WindowState = 'fullscreen';


            app.ParticipantNo = inputdlg({'Enter participant number'});
            % if input is non-numeric or is cancelled, reopen dialog
            if isempty(app.ParticipantNo) || isnan(str2double(cell2mat(app.ParticipantNo(1))))
              startupFcn(app)
              app.UIFigure.WindowState = 'fullscreen';
              return
            end

            app.UIFigure.Visible = 'on';
        end

        % Value changed function: PlayASwitch
        function PlayASwitchValueChanged(app, event)
            % assign switch value to property
            app.PlayASwitchVal = app.PlayASwitch.Value;
            % flag to indicate stimulus A played
            app.PlayASwitched = 1;
            % if both switches used, enable next button
            if app.PlayASwitched*app.PlayBSwitched > 0
                app.NextButton.Enable = 'on';
            end

            % if switched on
            if strcmp(app.PlayASwitchVal, 'On')
                % switch off B stimulus
                StimOff(app, app.playerB, app.PlayBSwitch, app.LampB);

                % switch on A stimulus
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.Timescale = [app.Timescale; table(app.nTrial,...
                                 string(app.t_date), string(app.t_time),...
                                 "Play A Switch", 'VariableNames',...
                                 ["Trial Number", "Date", "Time",...
                                  "Button Pressed"])];
                app.LampA.Color = 'g';
                % start playback functionality
                app.playerA.StopFcn = @(StimPlayer, event) StimEnd(app,...
                                                                   StimPlayer,...
                                                                   app.PlayASwitch,...
                                                                   app.LampA);
                play(app.playerA)


            else
                % if switched off, stop stimulus A
                StimOff(app, app.playerA, app.PlayASwitch, app.LampA);

            end

        end

        % Value changed function: PlayBSwitch
        function PlayBSwitchValueChanged(app, event)
            % assign switch value to property
            app.PlayBSwitchVal = app.PlayBSwitch.Value;
            % flag to indicate stimulus B played
            app.PlayBSwitched = 1;
            % if both switches used, enable next button
            if app.PlayBSwitched*app.PlayASwitched > 0
                app.NextButton.Enable = 'on';
            end

            % if switched on
            if strcmp(app.PlayBSwitchVal, 'On')
                % switch off A stimulus
                StimOff(app, app.playerA, app.PlayASwitch, app.LampA);

                % switch on B stimulus
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.Timescale = [app.Timescale;...
                                 table(app.nTrial, string(app.t_date),...
                                 string(app.t_time), "Play B Switch",...
                                 'VariableNames', ["Trial Number",...
                                                   "Date", "Time",...
                                                   "Button Pressed"])];
                app.LampB.Color = 'g';
                % get stimulus length and loop play
                app.playerB.StopFcn = @(StimPlayer, event) StimEnd(app, StimPlayer,...
                                                            app.PlayBSwitch,...
                                                            app.LampB);
                play(app.playerB)

            % if switched off, stop stimulus B
            else
                StimOff(app, app.playerB, app.PlayBSwitch, app.LampB);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % switch off A stimulus
            StimOff(app, app.playerA, app.PlayASwitch, app.LampA);

            % switch off B stimulus
            StimOff(app, app.playerB, app.PlayBSwitch, app.LampB);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % switch off A stimulus
            StimOff(app, app.playerA, app.PlayASwitch, app.LampA);

            % switch off B stimulus
            StimOff(app, app.playerB, app.PlayBSwitch, app.LampB);

            % reset flags to indicate stimuli played
            app.PlayASwitched = 0;
            app.PlayBSwitched = 0;

            % reassign current AB pick selection
            app.ABselected = app.AB_pick.SelectedObject;

            ResponseNames = ["Trial Number", "StimA File",...
                             "StimB File", "Stim AB selected"];

            filenameA = erase(string(app.StimuliA(app.nTrial)), ...
                              strcat(app.directory, '\Audio\A\'));

            filenameB = erase(string(app.StimuliB(app.nTrial)), ...
                              strcat(app.directory, '\Audio\B\'));


            % append participant test to table
            app.Participant_TestData = [app.Participant_TestData;...
                                        table(app.nTrial, filenameA, filenameB,...
                                        string(app.ABselected.Text),...
                                        'VariableNames', ResponseNames)];
            
            % append participant timing data to table
            app.t_date = datetime('now','Format','yyyy-MM-dd');
            app.t_time = datetime('now','Format','HH:mm:ss.SSS');
            
            app.Timescale =[app.Timescale;...
                table(app.nTrial, string(app.t_date), ...
                      string(app.t_time), "Next Button", 'VariableNames',...
                      ["Trial Number", "Date", "Time", ...
                       "Button Pressed"])];
            
            % reset switch initial state
            app.PlayASwitch.Value = 'Off';
            app.PlayBSwitch.Value = 'Off';
            
            % increment current trial number
            app.nTrial = app.nTrial + 1;
            app.CurrentN.Text = string(app.nTrial);
            
            app.NextButton.Enable = 'off';
            
            % if total trial number is reached, end test and write data
            if app.nTrial == app.NTrials + 1             
                t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
                writetable(app.Participant_TestData, ...
                           sprintf('%s_ABTest_ParticipantNo%s_Response.xlsx',...
                           t_end, string(app.ParticipantNo)));
                writetable(app.Timescale, ...
                           sprintf('%s_ABTest_ParticipantNo%s_Timing.xlsx',...
                           t_end, string(app.ParticipantNo)))

                app.UIFigure.Visible = 'off';
                msgbox("Thank you for completing the test", ...
                       "Test complete", "help");
                delete(app);
            else
                % update audio stimuli
                [x_A, fs_A] = audioread(cell2mat(app.StimuliA(app.nTrial)));
                app.playerA = audioplayer(x_A, fs_A);
                [x_B, fs_B] = audioread(cell2mat(app.StimuliB(app.nTrial)));
                app.playerB = audioplayer(x_B, fs_B);
            end

        end

        % Selection changed function: AB_pick
        function AB_pickSelectionChanged(app, event)
            app.ABselected = app.AB_pick.SelectedObject;     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Which sound is more annoying?';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Instruction = uilabel(app.GridLayout);
            app.Instruction.Text = 'Listen to both sounds A & B and choose which one is more annoying.';
            app.AB_pick = uibuttongroup(app.GridLayout);
            app.AB_pick.SelectionChangedFcn = createCallbackFcn(app, @AB_pickSelectionChanged, true);
            app.AButton = uiradiobutton(app.AB_pick);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.AB_pick);
            app.BButton.Text = 'B';
            app.LampA = uilamp(app.GridLayout);
            app.LampB = uilamp(app.GridLayout);
            app.CurrentN = uilabel(app.GridLayout);
            app.CurrentN.Text = 'n';
            app.TotalN = uilabel(app.GridLayout);
            app.TotalN.Text = 'N';
            app.ofLabel = uilabel(app.GridLayout);
            app.ofLabel.Text = 'of';
            app.trialsLabel = uilabel(app.GridLayout);
            app.trialsLabel.Text = 'trials';
            app.PlayBSwitchLabel = uilabel(app.GridLayout);
            app.PlayBSwitchLabel.Text = 'Play B';
            app.PlayBSwitch = uiswitch(app.GridLayout, 'slider');
            app.PlayBSwitch.ValueChangedFcn = createCallbackFcn(app, @PlayBSwitchValueChanged, true);
            app.PlayASwitchLabel = uilabel(app.GridLayout);
            app.PlayASwitchLabel.Text = 'Play A';
            app.PlayASwitch = uiswitch(app.GridLayout, 'slider');
            app.PlayASwitch.ValueChangedFcn = createCallbackFcn(app, @PlayASwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AB_annoyance_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end