classdef REFMAP_Listest1_GUI_2023a < matlab.apps.AppBase
    %REFMAP_LISTEST1_GUI_2023A Runs REFMAP listening test 1
    %   Runs REFMAP listening test 1 for audio contained in "\Audio\Part A"
    %   and "\Audio\Part B" subdirectories.
    %   
    %   Participant can play each audio file as many times as wanted.
    %   'Next' button is disabled until the audio has played through once.
    %   
    %   Participant responses include 0-10 discrete sliding rating scale,
    %   self-assessment manikin selection and identified source typology
    %   selection.
    %   
    %   Records play/next function timing for further response analysis.
    %   
    %   Includes instructions, preliminary training section, and post-test
    %   questionnaire.
    %   
    %   Outputs to .xlsx spreadsheet files (1, pre-test questionnaire data,
    %   1 Part A response data, 1 Part A timing data,, 1 Part B reponse and
    %   1 Part B timing data, 1 post-test questionnaire data).
    %   
    %   Requirements
    %   ------------
    %   Audio Toolbox
    %   DSP Systems Toolbox
    %   
    %   Assumptions
    %   -----------
    %   The audio device used must support the audio file properties. For
    %   example, an error will arise if the output device does not support
    %   the number of channels in an audio file. The device to be used is
    %   selected from those detected on launch. On Windows OS, the driver
    %   type is selected prior to selecting a device compatible with the
    %   selected driver.
    %   
    %   Ownership and Quality Assurance
    %   -------------------------------
    %   Author: Mike JB Lotinga (m.j.lotinga@edu.salford.ac.uk)
    %   Institution: University of Salford
    %   
    %   Date created: 01/11/2023
    %   Date last modified: 07/12/2023
    %   MATLAB version: 2023a
    %   
    %   Copyright statement: This file and code is part of work undertaken
    %   within the RefMap project (www.refmap.eu), and is subject to
    %   licence as detailed in the code repository
    %   (https://github.com/acoustics-code-salford/refmap-psychoacoustics)
    %   
    %   Checked by:
    %   Date last checked:

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        PretestTab                    matlab.ui.container.Tab
        GridLayoutPreTest             matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        HearDetailsEditField          matlab.ui.control.EditField
        DetailsLabel                  matlab.ui.control.Label
        HearButtonGroup               matlab.ui.container.ButtonGroup
        HearNoButton                  matlab.ui.control.RadioButton
        HearYesButton                 matlab.ui.control.RadioButton
        InstructionPreTestHear        matlab.ui.control.Label
        InstructionPreTest            matlab.ui.control.Label
        PANASGroup                    matlab.ui.container.Panel
        GridLayoutPreTestPANAS        matlab.ui.container.GridLayout
        PANASPanel                    matlab.ui.container.Panel
        GridLayoutPreTestPANASPanel   matlab.ui.container.GridLayout
        PANASafraidField              matlab.ui.control.NumericEditField
        PANASactiveField              matlab.ui.control.NumericEditField
        PANASjitteryField             matlab.ui.control.NumericEditField
        PANASattentiveField           matlab.ui.control.NumericEditField
        PANASdeterminedField          matlab.ui.control.NumericEditField
        PANASnervousField             matlab.ui.control.NumericEditField
        PANASinspiredField            matlab.ui.control.NumericEditField
        PANASashamedField             matlab.ui.control.NumericEditField
        PANASalertField               matlab.ui.control.NumericEditField
        PANASirritableField           matlab.ui.control.NumericEditField
        PANASproudField               matlab.ui.control.NumericEditField
        PANASenthusiasticField        matlab.ui.control.NumericEditField
        PANAShostileField             matlab.ui.control.NumericEditField
        PANASscaredField              matlab.ui.control.NumericEditField
        PANASguiltyField              matlab.ui.control.NumericEditField
        PANASstrongField              matlab.ui.control.NumericEditField
        PANASupsetField               matlab.ui.control.NumericEditField
        PANASexcitedField             matlab.ui.control.NumericEditField
        PANASdistressedField          matlab.ui.control.NumericEditField
        PANASinterestedField          matlab.ui.control.NumericEditField
        interestedFieldLabel          matlab.ui.control.Label
        distressedFieldLabel          matlab.ui.control.Label
        excitedFieldLabel             matlab.ui.control.Label
        upsetFieldLabel               matlab.ui.control.Label
        strongFieldLabel              matlab.ui.control.Label
        guiltyFieldLabel              matlab.ui.control.Label
        scaredFieldLabel              matlab.ui.control.Label
        hostileFieldLabel             matlab.ui.control.Label
        enthusiasticFieldLabel        matlab.ui.control.Label
        proudFieldLabel               matlab.ui.control.Label
        irritableFieldLabel           matlab.ui.control.Label
        alertFieldLabel               matlab.ui.control.Label
        ashamedFieldLabel             matlab.ui.control.Label
        inspiredFieldLabel            matlab.ui.control.Label
        nervousFieldLabel             matlab.ui.control.Label
        determinedFieldLabel          matlab.ui.control.Label
        attentiveFieldLabel           matlab.ui.control.Label
        jitteryFieldLabel             matlab.ui.control.Label
        activeFieldLabel              matlab.ui.control.Label
        afraidFieldLabel              matlab.ui.control.Label
        InstructionPANAS              matlab.ui.control.Label
        InstructionPANAS_2            matlab.ui.control.Label
        InstructionPANAS_3            matlab.ui.control.Label
        ReadbriefButton               matlab.ui.control.Button
        READMEA1Tab                   matlab.ui.container.Tab
        GridLayoutA1                  matlab.ui.container.GridLayout
        InstructionA1                 matlab.ui.control.Label
        PageA2Button                  matlab.ui.control.Button
        READMEA2Tab                   matlab.ui.container.Tab
        GridLayoutA2                  matlab.ui.container.GridLayout
        InstructionA2_1               matlab.ui.control.Label
        InstructionA2_2               matlab.ui.control.Label
        ImageA2Valence                matlab.ui.control.Image
        InstructionA2_3               matlab.ui.control.Label
        ImageA2Arousal                matlab.ui.control.Image
        InstructionA2_4               matlab.ui.control.Label
        PageA3Button                  matlab.ui.control.Button
        READMEA3Tab                   matlab.ui.container.Tab
        GridLayoutA3                  matlab.ui.container.GridLayout
        InstructionA3                 matlab.ui.control.Label
        StartPracticeAButton          matlab.ui.control.Button
        PartATab                      matlab.ui.container.Tab
        GridLayoutTestA               matlab.ui.container.GridLayout
        PanelPlayA                    matlab.ui.container.Panel
        GridLayoutPlayA               matlab.ui.container.GridLayout
        InstructionPlayA_1            matlab.ui.control.Label
        InstructionPlayA_2            matlab.ui.control.Label
        InstructionPlayA_3            matlab.ui.control.Label
        PlaySwitchLabel               matlab.ui.control.Label
        PlaySwitchA                   matlab.ui.control.Switch
        LampA                         matlab.ui.control.Lamp
        PanelSAMA                     matlab.ui.container.Panel
        GridLayoutSAMA                matlab.ui.container.GridLayout
        InstructionSAMA               matlab.ui.control.Label
        Arousal5CheckA                matlab.ui.control.CheckBox
        Arousal4CheckA                matlab.ui.control.CheckBox
        Arousal3CheckA                matlab.ui.control.CheckBox
        Arousal2CheckA                matlab.ui.control.CheckBox
        Arousal1CheckA                matlab.ui.control.CheckBox
        Valence5CheckA                matlab.ui.control.CheckBox
        Valence4CheckA                matlab.ui.control.CheckBox
        Valence3CheckA                matlab.ui.control.CheckBox
        Valence2CheckA                matlab.ui.control.CheckBox
        Valence1CheckA                matlab.ui.control.CheckBox
        valence5img                   matlab.ui.control.Image
        valence4img                   matlab.ui.control.Image
        valence3img                   matlab.ui.control.Image
        valence2img                   matlab.ui.control.Image
        valence1img                   matlab.ui.control.Image
        arousal5img                   matlab.ui.control.Image
        arousal4img                   matlab.ui.control.Image
        arousal3img                   matlab.ui.control.Image
        arousal2img                   matlab.ui.control.Image
        arousal1img                   matlab.ui.control.Image
        PanelAnnoyanceA               matlab.ui.container.Panel
        GridLayoutAnnoyanceA          matlab.ui.container.GridLayout
        movethesliderALabel           matlab.ui.control.Label
        NotatallLabel                 matlab.ui.control.Label
        ExtremelyLabel                matlab.ui.control.Label
        AnnoyQALabel                  matlab.ui.control.Label
        AnnoyanceSliderA              matlab.ui.control.Slider
        PanelTypology                 matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        OtherspecifyEditField         matlab.ui.control.EditField
        otherpleasespecifyLabel       matlab.ui.control.Label
        WindleavesCheckBox            matlab.ui.control.CheckBox
        MusicCheckBox                 matlab.ui.control.CheckBox
        OtherCheckBox                 matlab.ui.control.CheckBox
        AircraftCheckBox              matlab.ui.control.CheckBox
        RoadvehiclesCheckBox          matlab.ui.control.CheckBox
        BirdsCheckBox                 matlab.ui.control.CheckBox
        PeopleCheckBox                matlab.ui.control.CheckBox
        SourceTypeLabel               matlab.ui.control.Label
        ProceedButtonA                matlab.ui.control.Button
        CurrentNA                     matlab.ui.control.Label
        ofLabel                       matlab.ui.control.Label
        TotalNA                       matlab.ui.control.Label
        trialsLabel                   matlab.ui.control.Label
        READMEB1Tab                   matlab.ui.container.Tab
        GridLayoutB1                  matlab.ui.container.GridLayout
        InstructionB1                 matlab.ui.control.Label
        PageB2Button                  matlab.ui.control.Button
        READMEB2Tab                   matlab.ui.container.Tab
        GridLayoutB2                  matlab.ui.container.GridLayout
        InstructionB2_1               matlab.ui.control.Label
        InstructionB2_2               matlab.ui.control.Label
        ImageB2Valence                matlab.ui.control.Image
        InstructionB2_3               matlab.ui.control.Label
        ImageB2Arousal                matlab.ui.control.Image
        InstructionB2_4               matlab.ui.control.Label
        PageB3Button                  matlab.ui.control.Button
        READMEB3Tab                   matlab.ui.container.Tab
        GridLayoutB3                  matlab.ui.container.GridLayout
        InstructionB3                 matlab.ui.control.Label
        StartPracticeBButton          matlab.ui.control.Button
        PartBTab                      matlab.ui.container.Tab
        GridLayoutTestB               matlab.ui.container.GridLayout
        PanelPlayB                    matlab.ui.container.Panel
        GridLayoutPlayB               matlab.ui.container.GridLayout
        InstructionPlayB_1            matlab.ui.control.Label
        InstructionPlayB_2            matlab.ui.control.Label
        InstructionPlayB_3            matlab.ui.control.Label
        PlaySwitchLabelB              matlab.ui.control.Label
        PlaySwitchB                   matlab.ui.control.Switch
        LampB                         matlab.ui.control.Lamp
        PanelSAMB                     matlab.ui.container.Panel
        GridLayoutSAMB                matlab.ui.container.GridLayout
        InstructionSAMB               matlab.ui.control.Label
        Arousal5CheckB                matlab.ui.control.CheckBox
        Arousal4CheckB                matlab.ui.control.CheckBox
        Arousal3CheckB                matlab.ui.control.CheckBox
        Arousal2CheckB                matlab.ui.control.CheckBox
        Arousal1CheckB                matlab.ui.control.CheckBox
        Valence5CheckB                matlab.ui.control.CheckBox
        Valence4CheckB                matlab.ui.control.CheckBox
        Valence3CheckB                matlab.ui.control.CheckBox
        Valence2CheckB                matlab.ui.control.CheckBox
        Valence1CheckB                matlab.ui.control.CheckBox
        valence5img_2                 matlab.ui.control.Image
        valence4img_2                 matlab.ui.control.Image
        valence3img_2                 matlab.ui.control.Image
        valence2img_2                 matlab.ui.control.Image
        valence1img_2                 matlab.ui.control.Image
        arousal5img_2                 matlab.ui.control.Image
        arousal4img_2                 matlab.ui.control.Image
        arousal3img_2                 matlab.ui.control.Image
        arousal2img_2                 matlab.ui.control.Image
        arousal1img_2                 matlab.ui.control.Image
        PanelAnnoyanceB               matlab.ui.container.Panel
        GridLayoutAnnoyanceB          matlab.ui.container.GridLayout
        movethesliderBLabel           matlab.ui.control.Label
        NotatallLabel_2               matlab.ui.control.Label
        ExtremelyLabel_2              matlab.ui.control.Label
        AnnoyQBLabel                  matlab.ui.control.Label
        AnnoyanceSliderB              matlab.ui.control.Slider
        ProceedButtonB                matlab.ui.control.Button
        CurrentNB                     matlab.ui.control.Label
        ofLabel_2                     matlab.ui.control.Label
        TotalNB                       matlab.ui.control.Label
        trialsLabel_2                 matlab.ui.control.Label
        Posttest1Tab                  matlab.ui.container.Tab
        GridLayoutPost1               matlab.ui.container.GridLayout
        InstructionPostTest1          matlab.ui.control.Label
        SceneConsiderationsEditField  matlab.ui.control.EditField
        SourceCommentsEditField       matlab.ui.control.EditField
        ExperienceEditField           matlab.ui.control.EditField
        EditFieldLabel_4              matlab.ui.control.Label
        EditFieldLabel_3              matlab.ui.control.Label
        EditFieldLabel_2              matlab.ui.control.Label
        EditFieldLabel                matlab.ui.control.Label
        PanelPostAttitude             matlab.ui.container.Panel
        GridLayoutPostAttitude        matlab.ui.container.GridLayout
        ConcernedCheckBox             matlab.ui.control.CheckBox
        AmbivalentCheckBox            matlab.ui.control.CheckBox
        NeutralCheckBox               matlab.ui.control.CheckBox
        SupportiveCheckBox            matlab.ui.control.CheckBox
        Label                         matlab.ui.control.Label
        FeedbackEditField             matlab.ui.control.EditField
        PagePost2Button               matlab.ui.control.Button
        Posttest2Tab                  matlab.ui.container.Tab
        GridLayoutPost2               matlab.ui.container.GridLayout
        InstructionPostTest2_1        matlab.ui.control.Label
        NSSQ1Label                    matlab.ui.control.Label
        AgreestronglyLabel_6          matlab.ui.control.Label
        DisagreestronglyLabel_6       matlab.ui.control.Label
        NSSQ1Slider                   matlab.ui.control.Slider
        NSSQ2Label                    matlab.ui.control.Label
        AgreestronglyLabel_7          matlab.ui.control.Label
        DisagreestronglyLabel_7       matlab.ui.control.Label
        NSSQ2Slider                   matlab.ui.control.Slider
        NSSQ3Label                    matlab.ui.control.Label
        AgreestronglyLabel_8          matlab.ui.control.Label
        DisagreestronglyLabel_8       matlab.ui.control.Label
        NSSQ3Slider                   matlab.ui.control.Slider
        NSSQ4Label                    matlab.ui.control.Label
        AgreestronglyLabel_9          matlab.ui.control.Label
        DisagreestronglyLabel_9       matlab.ui.control.Label
        NSSQ4Slider                   matlab.ui.control.Slider
        NSSQ5Label                    matlab.ui.control.Label
        AgreestronglyLabel_10         matlab.ui.control.Label
        DisagreestronglyLabel_10      matlab.ui.control.Label
        NSSQ5Slider                   matlab.ui.control.Slider
        PagePost3Button               matlab.ui.control.Button
        Posttest3Tab                  matlab.ui.container.Tab
        GridLayoutPost3               matlab.ui.container.GridLayout
        LanguageEditField             matlab.ui.control.EditField
        pleasespecifyEditFieldLabel   matlab.ui.control.Label
        OtherLanguageCheckBox         matlab.ui.control.CheckBox
        EnglishLanguageCheckBox       matlab.ui.control.CheckBox
        WhatisyourfirstnativelanguageLabel  matlab.ui.control.Label
        PrefernotNationCheckBox       matlab.ui.control.CheckBox
        NationalityEditField          matlab.ui.control.EditField
        NationalityEditFieldLabel     matlab.ui.control.Label
        InstructionPostTest3          matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.NumericEditField
        PrefernotAgeCheckBox          matlab.ui.control.CheckBox
        AgeEditFieldLabel             matlab.ui.control.Label
        SexDropDown                   matlab.ui.control.DropDown
        SexDropDownLabel              matlab.ui.control.Label
        PrefernotSexCheckBox          matlab.ui.control.CheckBox
        HomeClassLabel                matlab.ui.control.Label
        RuralCheckBox                 matlab.ui.control.CheckBox
        SuburbanCheckBox              matlab.ui.control.CheckBox
        UrbanCheckBox                 matlab.ui.control.CheckBox
        HomeScapeLabel                matlab.ui.control.Label
        MonotonousCheckBox            matlab.ui.control.CheckBox
        VibrantCheckBox               matlab.ui.control.CheckBox
        ChaoticCheckBox               matlab.ui.control.CheckBox
        CalmCheckBox                  matlab.ui.control.CheckBox
        SubmitPostButton              matlab.ui.control.Button
    end

    
    properties (Access = private)

        uiFigureSize  % the figure size, used to scale fonts
        resizeFactor  % resize factor to use for font scaling

        directory  % Main working directory
        subdirTest  % Subdirectory for test stimuli
        
        StimuliTest  % Test stimuli set

        readerTest  % dsp.AudioFileReader object for current test stimulus

        player  % audioDeviceWriter object
        audiodevice  % audio device to use
        audiodriver  % audio device driver to use

        AudioDeviceSettingsInputFig  % UI figure to input samples per frame
        AudioDeviceSettingsOKButton  % UI control for input figure

        SamplesPerFrame  % number of samples per frame to use for audio device
        SamplesPerFrameKnob  % UI control for input figure

        ASIOSettingsCheck  % check box for audio device-specific ASIO settings

        ChannelCheck  % check box for audio device channel mapping mode
        ChannelMapMode  % audio device channel mapping mode
        ChannelMapFields  % audio device channel mapping data entry fields
        ChannelMap  % audio device channel mapping
        
        NTrials  % Total number of trials
        nTrial  % Trial number counter
        
        ParticipantNo  % Participant ID number
        Participant_PreTestData  % Participant pre-test data output table
        Participant_TestDataA  % Participant test data output table part A
        Participant_TestDataB  % Participant test data output table part B
        Participant_PostTestData  % Participant post-test data output table

        t_date  % timestamp date
        t_time  % timestamp time

        TimescaleA  % Test timing data output table part A
        TimescaleB  % Test timing data output table part B

        % PlaySwitched  % flag to indicate Play switched on
        PlayComplete  % flag to indicate stimulus played completely

        PlaySwitchVal  % value of the Play switch

        TogglePlay  % flag to toggle play of stimulus

        PracticeFlag  % flag to indicate practice session
        StartTest  % flag to indicate first trial of test

        Valence  % number indicating SAM valence rating
        Arousal  % number indicating SAM arousal rating
        Typology  % string indicating sounds noticed during trial

        ResponseCheck  % flag to indicate whether SAM responses entered

        NSSQuestions  % noise sensitivity scale (short form) questions
        NSSQitemNo  % noise sensitivity scale (short form) question item ID #
        NSSQitemNoRand  % randomised order of noise sensitivity scale (short form) question items

        PageA2ButtonPushedFlag  % flag to indicate button used (controls scaling)
        PageA3ButtonPushedFlag  % flag to indicate button used (controls scaling)
        PageB2ButtonPushedFlag  % flag to indicate button used (controls scaling)
        PageB3ButtonPushedFlag  % flag to indicate button used (controls scaling)
        PagePost2ButtonPushedFlag  % flag to indicate button used (controls scaling)
        PagePost3ButtonPushedFlag  % flag to indicate button used (controls scaling)
        StartPracticeAButtonPushedFlag  % flag to indicate button used (controls scaling)
        StartPracticeBButtonPushedFlag  % flag to indicate button used (controls scaling)

    end
    
    methods (Access = private)

        % function to initialise audio device and driver selection
        function AudioDeviceDriverSelection(app)
            % select from connected audio devices (Windows OS)
            if ispc
                % check which driver devices are present
                drivers = ["ASIO", "WASAPI", "DirectSound"];
                driversOn = [];
                for ii = 1:length(drivers)
                    adw = audioDeviceWriter('Driver', drivers(ii));
                    devices = getAudioDevices(adw);
                    if contains('No audio output device detected', devices)
                    else
                        driversOn = cat(1, driversOn, [drivers(ii)]);
                    end  % end of if branch for storing drivers present
                end  % end of if branch for identifying drivers present
                
                % open selection dialog for device drivers detected
                tfDriver = 0;
                while tfDriver == 0    
                    [indx,tfDriver] = listdlg('ListString', driversOn,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio driver type");
                end  % end while loop for audio driver selection

                % assign driver type
                app.audiodriver = driversOn(indx);

                % assign devices for selected audio driver
                adw = audioDeviceWriter('Driver', app.audiodriver);
                devices = getAudioDevices(adw);

                % open selection dialog for audio devices
                tfDevice = 0;
                while tfDevice == 0 
                    
                    [indx,tfDevice] = listdlg('ListString', devices,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio output device");
                end  % end while loop for audio device selection

                % assign device
                app.audiodevice = devices{indx};

            else  % mac OS and linux
                % assign devices for selected driver
                adw = audioDeviceWriter;
                devices = getAudioDevices(adw);

                % open selection dialog for devices
                tfDevice = 0;
                while tfDevice == 0
                    
                    [indx,tfDevice] = listdlg('ListString', devices,...
                                              'SelectionMode', 'single',...
                                              'PromptString', "Select audio output device");
                end

                % assign device
                app.audiodevice = devices{indx};
                app.audiodriver = [];  % mac OS and linux use native drivers
            end  % end of if branch to check connected audio devices
            release(adw)  % release audioDeviceWriter object

        end

        % interface function to select settings for audio device
        function AudioDeviceSettings(app)
 
            % get audio device info
            adw = audioDeviceWriter('Device', app.audiodevice,...
                                    'Driver', app.audiodriver);
            deviceInfo = info(adw);

            app.AudioDeviceSettingsInputFig = uifigure('Name', "Audio device settings",...
                                                       'Position',...
                                                       [300, 250, 500, 300]);
            % centre the figure
            movegui(app.AudioDeviceSettingsInputFig, 'center');
            
            % input knob for samples per frame
            app.SamplesPerFrameKnob = uiknob(app.AudioDeviceSettingsInputFig,...
                                             "discrete", 'Position', [100, 120, 60, 60]);
            app.SamplesPerFrameKnob.Items = ["512", "1024", "2048", "4096", "8192"];
            app.SamplesPerFrameKnob.Value = app.SamplesPerFrameKnob.Items(2);
            % assign knob callback function
            app.SamplesPerFrameKnob.ValueChangedFcn = {@app.SamplesPerFrameInputChange};
            
            uilabel(app.AudioDeviceSettingsInputFig, 'Position', [10, 40, 220, 50],...
                    'VerticalAlignment', 'top', 'WordWrap', 'on',...
                    'Text', "Set the number of samples per frame for the selected audio device");

            % ASIO device settings option
            app.ASIOSettingsCheck = uicheckbox(app.AudioDeviceSettingsInputFig,...
                                               'Value', 0, 'Text',...
                                               "Open device-specific ASIO settings",...
                                               'WordWrap', 'on',...
                                               'Position', [10, 240, 220, 44]);
            % if ASIO driver not selected, disable check box
            if strcmp(app.audiodriver, 'ASIO')
                app.ASIOSettingsCheck.Enable = 'on';
                app.ASIOSettingsCheck.Visible = 'on';
            else
                app.ASIOSettingsCheck.Enable = 'off';
                app.ASIOSettingsCheck.Visible = 'off';
            end

            % channel mode check box
            app.ChannelCheck = uicheckbox(app.AudioDeviceSettingsInputFig,...
                                          'Value', 1, 'Text', "Auto",...
                                          'Position', [258, 100, 84, 22],...
                                          'Enable', 'on');
            % set default channel map mode
            app.ChannelMapMode = 'Auto';

            % assign check box callback function
            app.ChannelCheck.ValueChangedFcn = {@app.AutoChannelMap};

            % define the channel mapping input fields
            for ii = 1:deviceInfo.MaximumOutputChannels
                if ii <= 96
                    app.ChannelMapFields{ii} = uieditfield(app.AudioDeviceSettingsInputFig,...
                                                           'numeric', 'Limits', ...
                                                           [min(adw.ChannelMapping), ...
                                                            max(adw.ChannelMapping)], ...
                                                           'Value', adw.ChannelMapping(ii), ...
                                                           'ValueDisplayFormat', '%g', ...
                                                           'AllowEmpty', 'on',...
                                                           'Position', [290, 100 + (ii*30), 31, 22],...
                                                           'Enable', 'off',...
                                                           'RoundFractionalValues', 'on');
    
                    uilabel(app.AudioDeviceSettingsInputFig, 'Position', [260, 100 + (ii*30), 31, 22],...
                            'Text', num2str(adw.ChannelMapping(ii)) + ":");
                elseif ii <= 192
                    app.ChannelMapFields{ii} = uieditfield(app.AudioDeviceSettingsInputFig,...
                                                           'numeric', 'Limits', ...
                                                           [min(adw.ChannelMapping), ...
                                                            max(adw.ChannelMapping)], ...
                                                           'Value', adw.ChannelMapping(ii), ...
                                                           'ValueDisplayFormat', '%g', ...
                                                           'AllowEmpty', 'on',...
                                                           'Position', [360, 100 + ((ii - 96)*30), 31, 22],...
                                                           'Enable', 'off',...
                                                           'RoundFractionalValues', 'on');
    
                    uilabel(app.AudioDeviceSettingsInputFig, 'Position', [330, 100 + ((ii - 96)*30), 31, 22],...
                            'Text', num2str(adw.ChannelMapping(ii)) + ":");
                else
                    app.ChannelMapFields{ii} = uieditfield(app.AudioDeviceSettingsInputFig,...
                                                           'numeric', 'Limits', ...
                                                           [min(adw.ChannelMapping), ...
                                                            max(adw.ChannelMapping)], ...
                                                           'Value', adw.ChannelMapping(ii), ...
                                                           'ValueDisplayFormat', '%g', ...
                                                           'AllowEmpty', 'on',...
                                                           'Position', [430, 100 + ((ii - 192)*30), 31, 22],...
                                                           'Enable', 'off',...
                                                           'RoundFractionalValues', 'on');
    
                    uilabel(app.AudioDeviceSettingsInputFig, 'Position', [400, 100 + ((ii - 192)*30), 31, 22],...
                            'Text', num2str(adw.ChannelMapping(ii)) + ":");
                end

            end             
            uilabel(app.AudioDeviceSettingsInputFig, 'Position', [260, 40, 220, 50],...
                    'VerticalAlignment', 'top', 'WordWrap', 'on',...
                    'Text', "Define the channel mapping for the selected audio device (audio file channel: device output)");
            
            app.AudioDeviceSettingsInputFig.Scrollable = 'on';

            % input OK button to confirm settings
            app.AudioDeviceSettingsOKButton = uibutton(app.AudioDeviceSettingsInputFig,...
                                                       'push', 'Text', 'OK',...
                                                       'Position', [220, 10, 60, 22]);
            % assign OK button callback function
            app.AudioDeviceSettingsOKButton.ButtonPushedFcn = {@app.CloseAudioDeviceSettingsInput};

            % set window position to origin
            pause(0.1);  % a hack line to get MATLAB to recognise the scroll function
            scroll(app.AudioDeviceSettingsInputFig, 'left', 'bottom')

            release(adw)  % release audioDeviceWriter object
            
        end

        % callback function for SamplesPerFrameInputFig knob value
        function SamplesPerFrameInputChange(app, ~, ~)
            % assign SamplesPerFrame value to variable
            app.SamplesPerFrame = str2double(app.SamplesPerFrameKnob.Value);
        end

        % callback function for AudioDeviceSettingsInputFig OK button
        function CloseAudioDeviceSettingsInput(app, ~, ~)
            % assign SamplesPerFrame value to variable
            app.SamplesPerFrame = str2double(app.SamplesPerFrameKnob.Value);
            for ii = 1:info(audioDeviceWriter('Device', app.audiodevice,...
                                              'Driver', app.audiodriver)).MaximumOutputChannels
                app.ChannelMap(ii) = app.ChannelMapFields{ii}.Value;
            end

            % if open ASIO settings checked
            if app.ASIOSettingsCheck.Value
                asiosettings(app.audiodevice)
            end

            close(app.AudioDeviceSettingsInputFig)  % close figure

        end

        % callback function for ChannelCheck box
        function AutoChannelMap(app, ~, ~)
            % enable/disable channel map fields depending on selected mode
            for ii = 1:info(audioDeviceWriter('Device', app.audiodevice,...
                                              'Driver', app.audiodriver)).MaximumOutputChannels
                app.ChannelMapFields{ii}.Enable = ~app.ChannelCheck.Value;
            end

            % update channel map mode according to input
            switch app.ChannelCheck.Value
                case 0
                    app.ChannelMapMode = 'Property';
                otherwise
                    app.ChannelMapMode = 'Auto';
            end
        end

        % function to check pre-test responses have been entered
        function CheckPreTestResponse(app)
            % check hearing details entered
            if (strcmp(app.HearButtonGroup.SelectedObject.Text, "Yes")...
                && (~all(isspace(app.HearDetailsEditField.Value))...
                || app.HearDetailsEditField.Value ~= "")) || strcmp(app.HearButtonGroup.SelectedObject.Text, "No")
                checkHearing = true;
            else
                checkHearing = false;
            end  % end of if branch for hearing details check

            % check PANAS box inputs all entered
            checkPANAS = all([~isempty(app.PANASinterestedField.Value),...
                              ~isempty(app.PANASdistressedField.Value),...
                              ~isempty(app.PANASexcitedField.Value),...
                              ~isempty(app.PANASupsetField.Value),...
                              ~isempty(app.PANASstrongField.Value),...
                              ~isempty(app.PANASguiltyField.Value),...
                              ~isempty(app.PANASscaredField.Value),...
                              ~isempty(app.PANAShostileField.Value),...
                              ~isempty(app.PANASenthusiasticField.Value),...
                              ~isempty(app.PANASproudField.Value),...
                              ~isempty(app.PANASirritableField.Value),...
                              ~isempty(app.PANASalertField.Value),...
                              ~isempty(app.PANASashamedField.Value),...
                              ~isempty(app.PANASinspiredField.Value),...
                              ~isempty(app.PANASnervousField.Value),...
                              ~isempty(app.PANASdeterminedField.Value),...
                              ~isempty(app.PANASattentiveField.Value),...
                              ~isempty(app.PANASjitteryField.Value),...
                              ~isempty(app.PANASactiveField.Value),...
                              ~isempty(app.PANASafraidField.Value)]);
            
            Check = checkHearing*checkPANAS;

            % if responses entered, enable button to advance
            if Check == 1
                app.ReadbriefButton.Enable = 'on';
            else
                app.ReadbriefButton.Enable = 'off';
            end  % end of if branch to enable advance button
        end
        % function to trigger when stimulus playback ends
        function StimTogglePlay = StimEnd(app, StimPlayer, StimSwitch, StimLamp)
            StimTogglePlay = StimOff(app, StimPlayer, StimSwitch, StimLamp);
        end  % end of StimEnd function
        
        % function to trigger when stimulus switched off
        function StimTogglePlay = StimOff(~, StimPlayer, StimSwitch, StimLamp)
            StimSwitch.Value = 'Stop';
            StimLamp.Color = [0.5, 0.5, 0.5];
            StimTogglePlay = false;
            release(StimPlayer)
        end  % end of StimOff function
        
        % function to reset all input fields for Part A
        function ResetResponsesA(app)
            app.Valence1CheckA.Value = false;
            app.Valence2CheckA.Value = false;
            app.Valence3CheckA.Value = false;
            app.Valence4CheckA.Value = false;
            app.Valence5CheckA.Value = false;
            app.Arousal1CheckA.Value = false;
            app.Arousal2CheckA.Value = false;
            app.Arousal3CheckA.Value = false;
            app.Arousal4CheckA.Value = false;
            app.Arousal5CheckA.Value = false;
            app.PeopleCheckBox.Value = false;
            app.BirdsCheckBox.Value = false;
            app.RoadvehiclesCheckBox.Value = false;
            app.AircraftCheckBox.Value = false;
            app.MusicCheckBox.Value = false;
            app.WindleavesCheckBox.Value = false;
            app.OtherCheckBox.Value = false;
            app.OtherspecifyEditField.Value = "";
            app.OtherspecifyEditField.Enable = 'off';
        end
        
        % function to check all responses have been entered in Part A
        function Check = CheckResponseA(app)
            ValenceCheck = max([app.Valence1CheckA.Value,...
                                app.Valence2CheckA.Value,...
                                app.Valence3CheckA.Value,...
                                app.Valence4CheckA.Value,...
                                app.Valence5CheckA.Value]);

            ArousalCheck = max([app.Arousal1CheckA.Value,...
                                app.Arousal2CheckA.Value,...
                                app.Arousal3CheckA.Value,...
                                app.Arousal4CheckA.Value,...
                                app.Arousal5CheckA.Value]);

            % typology check
            typeboxcheck = max([app.RoadvehiclesCheckBox.Value,...
                               app.BirdsCheckBox.Value,...
                               app.RoadvehiclesCheckBox.Value,...
                               app.AircraftCheckBox.Value,...
                               app.MusicCheckBox.Value,...
                               app.WindleavesCheckBox.Value,...
                               app.OtherCheckBox.Value]);

            otherfieldempty = isempty(app.OtherspecifyEditField.Value) ||...
                              app.OtherspecifyEditField.Value == "" ||...
                              all(isspace(app.OtherspecifyEditField.Value));

            if app.OtherCheckBox.Value && otherfieldempty
                otherfield = false;
            else
                otherfield = true;
            end

            if typeboxcheck > 0 && otherfield
                TypologyCheck = 1;
            else
                TypologyCheck = 0;
            end  % end of if branch for typology response check

            Check = ValenceCheck*ArousalCheck*TypologyCheck;

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && Check == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling next button
        end

        % function to reset all input fields for Part B
        function ResetResponsesB(app)
            app.Valence1CheckB.Value = false;
            app.Valence2CheckB.Value = false;
            app.Valence3CheckB.Value = false;
            app.Valence4CheckB.Value = false;
            app.Valence5CheckB.Value = false;
            app.Arousal1CheckB.Value = false;
            app.Arousal2CheckB.Value = false;
            app.Arousal3CheckB.Value = false;
            app.Arousal4CheckB.Value = false;
            app.Arousal5CheckB.Value = false;
        end
        
        % function to check both SAM responses have been entered in Part A
        function Check = CheckResponseB(app)
            ValenceCheck = max([app.Valence1CheckB.Value,...
                                app.Valence2CheckB.Value,...
                                app.Valence3CheckB.Value,...
                                app.Valence4CheckB.Value,...
                                app.Valence5CheckB.Value]);

            ArousalCheck = max([app.Arousal1CheckB.Value,...
                                app.Arousal2CheckB.Value,...
                                app.Arousal3CheckB.Value,...
                                app.Arousal4CheckB.Value,...
                                app.Arousal5CheckB.Value]);

            Check = ValenceCheck*ArousalCheck;

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && Check == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling next button
        end
        
        % function to check post-test responses have been entered
        function CheckPostTestResponse(app)
            AttitudeCheck = max([app.ConcernedCheckBox.Value,...
                                 app.AmbivalentCheckBox.Value,...
                                 app.NeutralCheckBox.Value,...
                                 app.SupportiveCheckBox.Value]);
            HomeClassCheck = max([app.UrbanCheckBox.Value,...
                                  app.SuburbanCheckBox.Value,...
                                  app.RuralCheckBox.Value]);
            HomeScapeCheck = max([app.CalmCheckBox.Value,...
                                  app.VibrantCheckBox.Value,...
                                  app.ChaoticCheckBox.Value,...
                                  app.MonotonousCheckBox.Value]);
            
            % if age 'prefer not to answer' is checked, or if age has been
            % entered, AgeCheck is true
            if app.PrefernotAgeCheckBox.Value || ~isempty(app.AgeEditField.Value)
                AgeCheck = 1;
            else
                AgeCheck = 0;
            end  % end of if branch for age entered check

            % if nationality 'prefer not to answer' is checked, or if nationality 
            % has been entered, NationCheck is true
            if app.PrefernotNationCheckBox.Value ||...
                    (~isempty(app.NationalityEditField.Value) && ~all(isspace(app.NationalityEditField.Value)))
                NationCheck = 1;
            else
                NationCheck = 0;
            end  % end of if branch for nationality entered check

            % if English language has been selected, or if Other language
            % has been selected, and specified, LangCheck is true
            if app.EnglishLanguageCheckBox.Value ||...
                    (app.OtherLanguageCheckBox.Value &&...
                    (~isempty(app.LanguageEditField.Value) && ~all(isspace(app.LanguageEditField.Value))))
                LangCheck = 1;
            else
                LangCheck = 0;
            end  % end of if branch for language entered check

            Check = AttitudeCheck*HomeClassCheck*HomeScapeCheck*AgeCheck*NationCheck*LangCheck;
            
            % if responses entered, enable submit button
            if Check == 1
                app.SubmitPostButton.Enable = 'on';
            else
                app.SubmitPostButton.Enable = 'off';
            end  % end of if branch to enable submit button
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';  % hide UI
            addpath(genpath(pwd));  % add subfolders to path

            % if results subfolder does not exist, create it
            if ~exist("Results", 'dir')
                mkdir("Results")
                addpath("Results")
            end  % end of results subfolder if branch

            % assign audio file folder
            app.directory = pwd;  % current working dir
            app.subdirTest = fullfile(app.directory, "Audio", "Part A", "Practice");  % Test stimuli dir

            % assign stimuli files to variables
            app.StimuliTest = shuffle(audioDatastore(app.subdirTest,...
                                                     'FileExtensions',...
                                                     ".wav")).Files(1:end);

            % calculate total number of trials
            % number of test files
            app.NTrials = length(dir(fullfile(app.subdirTest, '*.wav')));
            
            % set switch initial state
            % app.PlaySwitched = 0;
            % set play complete initial state
            app.PlayComplete = 0;

            % assign trial counter
            app.nTrial = 1;
            
            % add initial trial count text to UI
            app.CurrentNA.Text = "1";
            app.TotalNA.Text = sprintf("%s", string(app.NTrials));
            
            % disable next button on start
            app.ProceedButtonA.Enable = 'off';
            
            % initialise audio stimuli
            app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(1)));

            % select from connected audio devices (Windows OS)
            AudioDeviceDriverSelection(app);

            % open UI figure to enter settings for audio device
            AudioDeviceSettings(app);
            waitfor(app.AudioDeviceSettingsInputFig)  % keeps app from opening until close of input

            % open participant number entry dialog
            app.ParticipantNo = inputdlg({'Enter participant number'});

            % if dialog input is non-numeric or is cancelled, reopen dialog
            while isempty(app.ParticipantNo) || isnan(str2double(cell2mat(app.ParticipantNo(1))))
              app.ParticipantNo = inputdlg({'Enter participant number'});
            end  % end of while loop for input dialog

            % reset or randomise initial judgement scales and answers
            ResetResponsesA(app)
            ResetResponsesB(app)
            app.AnnoyanceSliderA.Value = app.AnnoyanceSliderA.MajorTicks(randi(length(app.AnnoyanceSliderA.MajorTicks)));
            app.AnnoyanceSliderB.Value = app.AnnoyanceSliderB.MajorTicks(randi(length(app.AnnoyanceSliderB.MajorTicks)));

            % randomise noise sensitivity questions into post-test page
            NSSQuestiontext = ["I am sensitive to noise",...
                               "I find it hard to relax in a place that's noisy",...
                               "I get mad at people who make noise that keeps me from falling asleep or getting work done",...
                               "I get annoyed when my neighbours are noisy",...
                               "I get used to most noises without difficulty"];
            app.NSSQitemNo = ["NSS21", "NSS18", "NSS19", "NSS7", "NSS8"];
            app.NSSQuestions = dictionary(app.NSSQitemNo, NSSQuestiontext);
            app.NSSQitemNoRand = app.NSSQitemNo(randperm(length(app.NSSQitemNo)));
            app.NSSQ1Label.Text = app.NSSQuestions(app.NSSQitemNoRand(1));
            app.NSSQ2Label.Text = app.NSSQuestions(app.NSSQitemNoRand(2));
            app.NSSQ3Label.Text = app.NSSQuestions(app.NSSQitemNoRand(3));
            app.NSSQ4Label.Text = app.NSSQuestions(app.NSSQitemNoRand(4));
            app.NSSQ5Label.Text = app.NSSQuestions(app.NSSQitemNoRand(5));
            app.NSSQ1Slider.Value = app.NSSQ1Slider.MajorTicks(randi(length(app.NSSQ5Slider.MajorTicks)));
            app.NSSQ2Slider.Value = app.NSSQ2Slider.MajorTicks(randi(length(app.NSSQ5Slider.MajorTicks)));
            app.NSSQ3Slider.Value = app.NSSQ3Slider.MajorTicks(randi(length(app.NSSQ5Slider.MajorTicks)));
            app.NSSQ4Slider.Value = app.NSSQ4Slider.MajorTicks(randi(length(app.NSSQ5Slider.MajorTicks)));
            app.NSSQ5Slider.Value = app.NSSQ5Slider.MajorTicks(randi(length(app.NSSQ5Slider.MajorTicks)));

            % initialise source typology
            app.Typology = ["people;", "birds;", "road vehicles;",...
                            "aircraft;", "music;","wind/leaves;",...
                            "other: "];

            % disable details field for hearing check
            app.HearDetailsEditField.Enable = 'off';
            
            % disable read brief button
            app.ReadbriefButton.Enable = 'off';
            % sort out app visual
            movegui(app.UIFigure, 'center');
            % reveal UI
            app.UIFigure.Visible = 'on';
            app.UIFigure.WindowState = 'fullscreen';

            % make tabs invisible
            app.PartATab.Parent = [];
            app.READMEA1Tab.Parent = [];
            app.READMEA2Tab.Parent = [];
            app.READMEA3Tab.Parent = [];
            app.PartBTab.Parent = [];
            app.READMEB1Tab.Parent = [];
            app.READMEB2Tab.Parent = [];
            app.READMEB3Tab.Parent = [];
            app.Posttest1Tab.Parent = [];
            app.Posttest2Tab.Parent = [];
            app.Posttest3Tab.Parent = [];

            % lower tab flags
            app.PageA2ButtonPushedFlag = false;
            app.PageA3ButtonPushedFlag = false;
            app.PageB2ButtonPushedFlag = false;
            app.PageB3ButtonPushedFlag = false;
            app.PagePost2ButtonPushedFlag = false;
            app.PagePost3ButtonPushedFlag = false;
            app.StartPracticeAButtonPushedFlag = false;
            app.StartPracticeBButtonPushedFlag = false;

            % raise practice flag
            app.PracticeFlag = true;
            app.StartTest = false;

            % sizing for radio button group
            app.HearButtonGroup.Units = 'pixel';

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % get the current size and position of UIFigure
            position = app.UIFigure.Position;
            
            % assignment of uiFigureSize
            if isempty(app.uiFigureSize)
                app.uiFigureSize = position(3:4);
            end

            % get the old size          
            oldSize = app.uiFigureSize(1)*app.uiFigureSize(2);
            
            % get the current size
            newSize = position(3)*position(4);

            % get the resize factor using root
            if newSize ~= oldSize
                app.resizeFactor = (newSize/oldSize)^(1/3);
            else
                app.resizeFactor = 1;
            end
            
            % store the current position for next time resizing
            app.uiFigureSize = position(3:4);
            
            % get the handles of object, which have the property about font size
            uihandle = findobj(app.UIFigure, '-property', 'FontSize');
            % get the current font size
            hFontSize = cell2mat(get(uihandle, 'FontSize'));
            % change the font size using resize factor
            newFontSize =  hFontSize*app.resizeFactor;
            % set the new font size
            set(uihandle, {'FontSize'}, num2cell(newFontSize));
        end

        % Selection changed function: HearButtonGroup
        function HearButtonGroupSelectionChanged(app, event)
            selectedButton = app.HearButtonGroup.SelectedObject;

            % if branch to enable details field
            if selectedButton.Text == "Yes"
                app.HearDetailsEditField.Enable = 'on';
            else
                app.HearDetailsEditField.Enable = 'off';
                app.HearDetailsEditField.Value = "";
            end  % end of if branch to enable/disable details field

            % check if pre-test responses entered
            CheckPreTestResponse(app);
        end

        % Value changed function: HearDetailsEditField
        function HearDetailsEditFieldValueChanged(app, event)
            %value = app.HearDetailsEditField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);
        end

        % Value changed function: PANASinterestedField
        function PANASinterestedFieldValueChanged(app, event)
            %value = app.PANASinterestedField.Value;

            % check if pre-test responses entered
            CheckPreTestResponse(app);
        end

        % Value changed function: PANASdistressedField
        function PANASdistressedFieldValueChanged(app, event)
            %value = app.PANASdistressedField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);
        end

        % Value changed function: PANASexcitedField
        function PANASexcitedFieldValueChanged(app, event)
            %value = app.PANASexcitedField.Value;

            % check if pre-test responses entered
            CheckPreTestResponse(app);
        end

        % Value changed function: PANASupsetField
        function PANASupsetFieldValueChanged(app, event)
            %value = app.PANASupsetField.Value;

            % check if pre-test responses entered
            CheckPreTestResponse(app);
        end

        % Value changed function: PANASstrongField
        function PANASstrongFieldValueChanged(app, event)
            %value = app.PANASstrongField.Value;

            % check if pre-test responses entered
            CheckPreTestResponse(app);      
        end

        % Value changed function: PANASguiltyField
        function PANASguiltyFieldValueChanged(app, event)
            %value = app.PANASguiltyField.Value;

            % check if pre-test responses entered
            CheckPreTestResponse(app);         
        end

        % Value changed function: PANASscaredField
        function PANASscaredFieldValueChanged(app, event)
            %value = app.PANASscaredField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);         
        end

        % Value changed function: PANAShostileField
        function PANAShostileFieldValueChanged(app, event)
            %value = app.PANAShostileField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);            
        end

        % Value changed function: PANASenthusiasticField
        function PANASenthusiasticFieldValueChanged(app, event)
            %value = app.PANASenthusiasticField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);           
        end

        % Value changed function: PANASproudField
        function PANASproudFieldValueChanged(app, event)
            %value = app.PANASproudField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);          
        end

        % Value changed function: PANASirritableField
        function PANASirritableFieldValueChanged(app, event)
            %value = app.PANASirritableField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);           
        end

        % Value changed function: PANASashamedField
        function PANASashamedFieldValueChanged(app, event)
            %value = app.PANASashamedField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);          
        end

        % Value changed function: PANASalertField
        function PANASalertFieldValueChanged(app, event)
            %value = app.PANASalertField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);      
        end

        % Value changed function: PANASinspiredField
        function PANASinspiredFieldValueChanged(app, event)
            %value = app.PANASinspiredField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);           
        end

        % Value changed function: PANASnervousField
        function PANASnervousFieldValueChanged(app, event)
            %value = app.PANASnervousField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);            
        end

        % Value changed function: PANASdeterminedField
        function PANASdeterminedFieldValueChanged(app, event)
            %value = app.PANASdeterminedField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);           
        end

        % Value changed function: PANASattentiveField
        function PANASattentiveFieldValueChanged(app, event)
            %value = app.PANASattentiveField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);          
        end

        % Value changed function: PANASjitteryField
        function PANASjitteryFieldValueChanged(app, event)
            %value = app.PANASjitteryField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);          
        end

        % Value changed function: PANASactiveField
        function PANASactiveFieldValueChanged(app, event)
            %value = app.PANASactiveField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);         
        end

        % Value changed function: PANASafraidField
        function PANASafraidFieldValueChanged(app, event)
            %value = app.PANASafraidField.Value;
            
            % check if pre-test responses entered
            CheckPreTestResponse(app);          
        end

        % Button pushed function: ReadbriefButton
        function ReadbriefButtonPushed(app, event)
            % select README A tab
            app.READMEA1Tab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.READMEA1Tab;
 
            % get the handles of object, with the font size property 
            uitabhandle = findobj(app.READMEA1Tab, '-property', 'FontSize');
            % get the current font size
            tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
            % change the font size using resize factor
            tabnewFontSize =  tabhFontSize*app.resizeFactor;
            % set the new font size
            set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));

            % make pre-test tab invisible
            app.PretestTab.Parent = [];

            % calculate PANAS totals (the plus 1 rescales to the original
            % 1-5 PANAS scale, while omitting any words not understood)
            PANASpositive = sum([app.PANASinspiredField.Value + 1,...
                                 app.PANASexcitedField.Value + 1,...
                                 app.PANASstrongField.Value + 1,...
                                 app.PANASenthusiasticField.Value + 1,...
                                 app.PANASproudField.Value + 1,...
                                 app.PANASalertField.Value + 1,...
                                 app.PANASinspiredField.Value + 1,...
                                 app.PANASdeterminedField.Value + 1,...
                                 app.PANASattentiveField.Value + 1,...
                                 app.PANASactiveField.Value + 1]);
            PANASnegative = sum([app.PANASdistressedField.Value + 1,...
                                 app.PANASupsetField.Value + 1,...
                                 app.PANASguiltyField.Value + 1,...
                                 app.PANASscaredField.Value + 1,...
                                 app.PANAShostileField.Value + 1,...
                                 app.PANASirritableField.Value + 1,...
                                 app.PANASashamedField.Value + 1,...
                                 app.PANASnervousField.Value + 1,...
                                 app.PANASjitteryField.Value + 1,...
                                 app.PANASafraidField.Value + 1]);

            % save pre-test data to file (the plus 1 rescales to the original
            % 1-5 PANAS scale, while omitting any words not understood)
            ResponseNames = ["ID#", "Hearing_impaired", "Impairment_details",...
                             "PANAS_positive", "PANAS_negative",...
                             "PANAS_interested", "PANAS_distressed",...
                             "PANAS_excited", "PANAS_upset", "PANAS_strong",...
                             "PANAS_guilty", "PANAS_scared", "PANAS_hostile",...
                             "PANAS_enthusiastic", "PANAS_proud",...
                             "PANAS_irritable", "PANAS_alert", "PANAS_ashamed",...
                             "PANAS_inspired", "PANAS_nervous",...
                             "PANAS_determined", "PANAS_attentive",...
                             "PANAS_jittery", "PANAS_active", "PANAS_afraid"];
            app.Participant_PreTestData = table(app.ParticipantNo,...
                                                {app.HearButtonGroup.SelectedObject.Text},...
                                                {app.HearDetailsEditField.Value},...
                                                PANASpositive, PANASnegative,...
                                                app.PANASinspiredField.Value + 1,...
                                                app.PANASdistressedField.Value + 1,...
                                                app.PANASexcitedField.Value + 1,...
                                                app.PANASupsetField.Value + 1,...
                                                app.PANASstrongField.Value + 1,...
                                                app.PANASguiltyField.Value + 1,...
                                                app.PANASscaredField.Value + 1,...
                                                app.PANAShostileField.Value + 1,...
                                                app.PANASenthusiasticField.Value + 1,...
                                                app.PANASproudField.Value + 1,...
                                                app.PANASirritableField.Value + 1,...
                                                app.PANASalertField.Value + 1,...
                                                app.PANASashamedField.Value + 1,...
                                                app.PANASinspiredField.Value + 1,...
                                                app.PANASnervousField.Value + 1,...
                                                app.PANASdeterminedField.Value + 1,...
                                                app.PANASattentiveField.Value + 1,...
                                                app.PANASjitteryField.Value + 1,...
                                                app.PANASactiveField.Value + 1,...
                                                app.PANASafraidField.Value + 1,...
                                                'VariableNames', ResponseNames);
            t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
            writetable(app.Participant_PreTestData, ...
                       fullfile(app.directory, "Results",...
                       sprintf('%s_PreTestQ_ParticipantNo%s_Response.xlsx',...
                       t_end, string(app.ParticipantNo))));
        end

        % Button pushed function: PageA2Button
        function PageA2ButtonPushed(app, event)
            % select second briefing page
            app.READMEA2Tab.Parent = app.TabGroup;            
            app.TabGroup.SelectedTab = app.READMEA2Tab;

            % if first time tab opened, scale fonts
            if app.PageA2ButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.READMEA2Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for font scaling

            % set button pushed flag
            app.PageA2ButtonPushedFlag = true;

        end

        % Button pushed function: PageA3Button
        function PageA3ButtonPushed(app, event)
            % select third briefing page
            app.READMEA3Tab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.READMEA3Tab;

            % if first time tab opened, scale fonts
            if app.PageA3ButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.READMEA3Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for font scaling

            % set button pushed flag
            app.PageA3ButtonPushedFlag = true;
        end

        % Button pushed function: StartPracticeAButton
        function StartPracticeButtonAPushed(app, event)
            % make training Part A tab visible
            app.PartATab.Parent = app.TabGroup;
            % select training Part A tab
            app.TabGroup.SelectedTab = app.PartATab;
            % if first time pushed, apply rescaling
            if app.StartPracticeAButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.PartATab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for rescaling
            app.StartPracticeAButtonPushedFlag = true;  % raise rescaled flag
            % raise practice flag
            app.PracticeFlag = true;
            % disable proceed button
            app.ProceedButtonA.Enable = 'off';
            % disable start practice button
            app.StartPracticeAButton.Enable = 'off';
        end

        % Value changed function: PlaySwitchA
        function PlaySwitchAValueChanged(app, event)
            % assign switch value to property
            app.PlaySwitchVal = app.PlaySwitchA.Value;
            
            % if switched on
            if strcmp(app.PlaySwitchVal, 'Play')
                % disable proceed button while playing
                app.ProceedButtonA.Enable = 'off';

                % update timing data
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.TimescaleA = [app.TimescaleA;...
                                  table(app.ParticipantNo, "A",...
                                        app.nTrial, string(app.t_date),...
                                        string(app.t_time), "Play Switch",...
                                        'VariableNames', ["ID#", "Part",...
                                                          "Trial Number",...
                                                          "Date", "Time",...
                                                          "Button Pressed"])];
                % switch on lamp
                app.LampA.Color = 'g';
                                
                % get audio file info
                fileInfo = audioinfo(app.readerTest.Filename);

                % convert BitsPerSample number into BitDepth string
                try
                    switch fileInfo.BitsPerSample
                        case 8
                            BitDepth = "8-bit integer";
                        case 16
                            BitDepth = "16-bit integer";
                        case 24
                            BitDepth = "24-bit integer";
                        case 32
                            BitDepth = "32-bit float";
                    end
                catch
                    BitDepth = "16-bit integer";
                end

                % create player object
                if strcmp(app.ChannelMapMode, 'Property')
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerTest.SampleRate,...
                                                   'BitDepth', BitDepth,...
                                                   'ChannelMappingSource', app.ChannelMapMode,...
                                                   'ChannelMapping', app.ChannelMap);
                else
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerTest.SampleRate,...
                                                   'BitDepth', BitDepth);
                end

                % check device can support file channels
                if info(app.player).MaximumOutputChannels < fileInfo.NumChannels
                    error("Error: audio device used does not support number of channels in file")
                elseif fileInfo.NumChannels == 1 && strcmp(app.ChannelMapMode, 'Property')
                    app.player.ChannelMapping = 1;
                elseif fileInfo.NumChannels < info(app.player).MaximumOutputChannels && strcmp(app.ChannelMapMode, 'Property')
                    error("Error: number of channels in file does not match channel mapping")
                end

                % Call setup to reduce the computational load of initialization in an audio stream loop.
                % openExample('dsp/ReadFromFileAndWriteToAudioDeviceExample')
                setup(app.player, zeros(app.SamplesPerFrame, fileInfo.NumChannels))

                % start playback functionality
                app.TogglePlay = true;
                
                % while loop to stream audio data frames to player object
                frameCount = 0;  % frame counter
                % count total number of frames in the reader file
                frameTotal = ceil(fileInfo.TotalSamples/app.readerTest.SamplesPerFrame);
                try  % avoids error on app exit deletion if stimulus still playing
                    while ~isDone(app.readerTest) && app.TogglePlay
                        drawnow()  % updates app values
                        frameCount = frameCount + 1;
                        % if filereader reaches end of file or stimulus
                        % switched off, toggle the switch and lamp, and
                        % release the file reader object
                        if strcmp(app.PlaySwitchVal, 'Stop')
                            app.TogglePlay = StimEnd(app, app.player, app.PlaySwitchA, app.LampA);
                            release(app.readerTest)
                            break
                        else  % keep playing file
                            audioData = app.readerTest();
                            app.player(audioData);  % scaled down from file by 15 dB

                        end  % end of if branch for end of stimulus file
                    end  % end of while loop for streaming audio to player
                    % check file played through
                    if frameCount == frameTotal
                        app.PlayComplete = 1;
                    end  % end of if branch for file played check
                    app.TogglePlay = StimEnd(app, app.player, app.PlaySwitchA, app.LampA);
                    release(app.readerTest)

                catch
                end  % end of try catch branch

                % evaluate response check
                app.ResponseCheck = CheckResponseA(app);

                % if stimulus played and responses entered, enable next button
                if app.PlayComplete > 0 && app.ResponseCheck == 1
                    app.ProceedButtonA.Enable = 'on';
                else
                    app.ProceedButtonA.Enable = 'off';
                end  % end of if branch for enabling next button

            % if switched off, stop stimulus
            else
                app.TogglePlay = StimOff(app, app.player, app.PlaySwitchA, app.LampA);
                release(app.readerTest)
            end  % end of if branch for stimulus switched on
        end

        % Button pushed function: ProceedButtonA
        function ProceedButtonAPushed(app, event)
            % if start of test (not practice)
            if app.ProceedButtonA.Text == "Start Test"
                % lower practice flag
                app.PracticeFlag = false;
                % notify participant the test has started
                msgbox("This is the first part of the test - please commence with listening and rating the sounds", ...
                       "Test started", "help");
                % make controls visible
                app.PlaySwitchA.Visible = 'on';
                app.Valence1CheckA.Visible = 'on';
                app.Valence2CheckA.Visible = 'on';
                app.Valence3CheckA.Visible = 'on';
                app.Valence4CheckA.Visible = 'on';
                app.Valence5CheckA.Visible = 'on';
                app.Arousal1CheckA.Visible = 'on';
                app.Arousal2CheckA.Visible = 'on';
                app.Arousal3CheckA.Visible = 'on';
                app.Arousal4CheckA.Visible = 'on';
                app.Arousal5CheckA.Visible = 'on';
                app.AnnoyanceSliderA.Visible = 'on';
                app.PeopleCheckBox.Visible = 'on';
                app.BirdsCheckBox.Visible = 'on';
                app.RoadvehiclesCheckBox.Visible = 'on';
                app.AircraftCheckBox.Visible = 'on';
                app.MusicCheckBox.Visible = 'on';
                app.WindleavesCheckBox.Visible = 'on';
                app.OtherCheckBox.Visible = 'on';
                app.OtherspecifyEditField.Visible = 'on';
                app.InstructionPlayA_1.Visible = 'on';
                app.InstructionPlayA_2.Visible = 'on';
                app.InstructionPlayA_3.Visible = 'on';
                app.InstructionSAMA.Visible = 'on';
                app.AnnoyQALabel.Visible = 'on';
                app.movethesliderALabel.Visible = 'on';
                app.SourceTypeLabel.Visible = 'on';
                app.PlaySwitchLabel.Visible = 'on';
                app.valence1img.Visible = 'on';
                app.valence2img.Visible = 'on';
                app.valence3img.Visible = 'on';
                app.valence4img.Visible = 'on';
                app.valence5img.Visible = 'on';
                app.arousal1img.Visible = 'on';
                app.arousal2img.Visible = 'on';
                app.arousal3img.Visible = 'on';
                app.arousal4img.Visible = 'on';
                app.arousal5img.Visible = 'on';
                app.NotatallLabel.Visible = 'on';
                app.ExtremelyLabel.Visible = 'on';
                app.LampA.Visible = 'on';
                app.OtherspecifyEditField.Visible = 'on';

            end  % end of if branch for lowering practice flag

            % switch off stimulus, release player and file reader
            app.TogglePlay = StimOff(app, app.player, app.PlaySwitchA, app.LampA);
            release(app.readerTest)

            % reset flags to indicate stimuli played
            % app.PlaySwitched = 0;
            app.PlayComplete = 0;

            % reset proceed button for new trial
            app.ProceedButtonA.Text = "Next";
            app.ProceedButtonA.Enable = 'off';

            % if a test round, add data to table
            if ~app.PracticeFlag && ~app.StartTest
                ResponseNames = ["ID#", "Part", "Trial Number", "Stim File",...
                                 "Valence", "Arousal", "Annoyance", "Typology"];

                % get filenames assigned to stimulus
                filepath = app.readerTest.Filename;
                [~, filename, ext] = fileparts(filepath);
                filename = {strcat(filename, ext)};

                % concatenate source typology boxes and field
                typology_join = [app.PeopleCheckBox.Value,...
                                 app.BirdsCheckBox.Value,...
                                 app.RoadvehiclesCheckBox.Value,...
                                 app.AircraftCheckBox.Value,...
                                 app.MusicCheckBox.Value,...
                                 app.WindleavesCheckBox.Value,...
                                 app.OtherCheckBox.Value];
                app.Typology = strjoin(app.Typology(typology_join));
                app.Typology = strjoin([app.Typology, app.OtherspecifyEditField.Value]);

                % append participant test to table
                app.Participant_TestDataA = [app.Participant_TestDataA;...
                                             table(app.ParticipantNo, "A",...
                                                   app.nTrial, filename,...
                                                   app.Valence, app.Arousal,...
                                                   app.AnnoyanceSliderA.Value,...
                                                   app.Typology,...
                                                   'VariableNames', ResponseNames)];
                
                % append participant timing data to table
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');
                
                app.TimescaleA = [app.TimescaleA;...
                                  table(app.ParticipantNo, "A", app.nTrial,...
                                        string(app.t_date), ...
                                        string(app.t_time), "Next Button",...
                                        'VariableNames',...
                                        ["ID#", "Part", "Trial Number",...
                                         "Date", "Time", "Button Pressed"])];
            
            end  % end of if branch for appending test data   
            
            % reset switch initial state
            app.PlaySwitchA.Value = 'Stop';

            % reset or randomise initial judgement scales
            ResetResponsesA(app)
            app.AnnoyanceSliderA.Value = randi(11) - 1;
           
            if ~app.StartTest
                % increment current trial number
                app.nTrial = app.nTrial + 1;
            end

            app.StartTest = false;

            % if total trial number is reached, end test and write data
            if app.nTrial > app.NTrials
                % make controls invisible
                app.PlaySwitchA.Visible = 'off';
                app.Valence1CheckA.Visible = 'off';
                app.Valence2CheckA.Visible = 'off';
                app.Valence3CheckA.Visible = 'off';
                app.Valence4CheckA.Visible = 'off';
                app.Valence5CheckA.Visible = 'off';
                app.Arousal1CheckA.Visible = 'off';
                app.Arousal2CheckA.Visible = 'off';
                app.Arousal3CheckA.Visible = 'off';
                app.Arousal4CheckA.Visible = 'off';
                app.Arousal5CheckA.Visible = 'off';
                app.AnnoyanceSliderA.Visible = 'off';
                app.PeopleCheckBox.Visible = 'off';
                app.BirdsCheckBox.Visible = 'off';
                app.RoadvehiclesCheckBox.Visible = 'off';
                app.AircraftCheckBox.Visible = 'off';
                app.MusicCheckBox.Visible = 'off';
                app.WindleavesCheckBox.Visible = 'off';
                app.OtherCheckBox.Visible = 'off';
                app.OtherspecifyEditField.Visible = 'off';
                app.InstructionPlayA_1.Visible = 'off';
                app.InstructionPlayA_2.Visible = 'off';
                app.InstructionPlayA_3.Visible = 'off';
                app.InstructionSAMA.Visible = 'off';
                app.AnnoyQALabel.Visible = 'off';
                app.movethesliderALabel.Visible = 'off';
                app.SourceTypeLabel.Visible = 'off';
                app.PlaySwitchLabel.Visible = 'off';
                app.valence1img.Visible = 'off';
                app.valence2img.Visible = 'off';
                app.valence3img.Visible = 'off';
                app.valence4img.Visible = 'off';
                app.valence5img.Visible = 'off';
                app.arousal1img.Visible = 'off';
                app.arousal2img.Visible = 'off';
                app.arousal3img.Visible = 'off';
                app.arousal4img.Visible = 'off';
                app.arousal5img.Visible = 'off';
                app.NotatallLabel.Visible = 'off';
                app.ExtremelyLabel.Visible = 'off';
                app.LampA.Visible = 'off';
                app.OtherspecifyEditField.Visible = 'off';

                if app.PracticeFlag
                    msgbox("You have completed the practice round - when you are ready, please push the 'Start Test' button below", ...
                           "Practice complete", "help");
                    
                    % reset proceed button
                    app.ProceedButtonA.Text = "Start Test";
                    pause(0.1);
                    app.ProceedButtonA.Enable = 'on';
                    
                    % reassign audio file folder
                    app.directory = pwd;  % current working dir
                    app.subdirTest = fullfile(app.directory, "Audio", "Part A", "Test");  % Test stimuli dir
        
                    % assign stimuli files to variables
                    app.StimuliTest = shuffle(audioDatastore(app.subdirTest,...
                                                             'FileExtensions',...
                                                             ".wav")).Files(1:end);

                    % calculate total number of trials
                    % number of test files
                    app.NTrials = length(dir(fullfile(app.subdirTest, '*.wav')));
                    
                    % set switch initial state
                    % app.PlaySwitched = 0;
                    % set play complete initial state
                    app.PlayComplete = 0;
        
                    % reset trial counter
                    app.nTrial = 1;
                    
                    % add initial trial count text to UI
                    app.CurrentNA.Text = "1";
                    app.TotalNA.Text = sprintf("%s", string(app.NTrials));
            
                    % re-initialise reader object
                    app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrial)));
                
                    app.StartTest = true;
                    app.ProceedButtonA.Enable = 'on';
                else
                    t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
                    writetable(app.Participant_TestDataA, ...
                               fullfile(app.directory, "Results",...
                               sprintf('%s_TestPtA_ParticipantNo%s_Response.xlsx',...
                               t_end, string(app.ParticipantNo))));
                    writetable(app.TimescaleA, ...
                               fullfile(app.directory, "Results",...
                               sprintf('%s_TestPtA_ParticipantNo%s_Timing.xlsx',...
                               t_end, string(app.ParticipantNo))));
                    msgbox("You have completed the first part - please take a break", ...
                           "Part A complete", "help");
                    
                    % switch to part B
                    app.READMEB1Tab.Parent = app.TabGroup;
                    app.TabGroup.SelectedTab = app.READMEB1Tab;
                    app.READMEA1Tab.Parent = [];
                    app.READMEA2Tab.Parent = [];
                    app.READMEA3Tab.Parent = [];
                    app.PartATab.Parent = [];
                    
                    % get the handles of object, with the font size property 
                    uitabhandle = findobj(app.READMEB1Tab, '-property', 'FontSize');
                    % get the current font size
                    tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                    % change the font size using resize factor
                    tabnewFontSize =  tabhFontSize*app.resizeFactor;
                    % set the new font size
                    set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));

                end  % end of if branch for practice or test complete

            else  % if test files remaining, continue with next stimulus
                    
                % update trial count on UI
                app.CurrentNA.Text = string(app.nTrial);

                % re-initialise reader object
                app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrial)));
     
                % re-initialise source typology
                app.Typology = ["people;", "birds;", "road vehicles;",...
                                "aircraft;", "music;","wind/leaves;",...
                                "other: "];

            end  % end of if branch for total number of trials reached
        end

        % Value changed function: AnnoyanceSliderA
        function AnnoyanceSliderAValueChngd(app, event)
            value = app.AnnoyanceSliderA.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.AnnoyanceSliderA.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: Valence1CheckA
        function Valence1CheckAValueChanged(app, event)
            value = app.Valence1CheckA.Value;
            
            % if switched on, switch off others in group
            if value
                app.Valence2CheckA.Value = false;
                app.Valence3CheckA.Value = false;
                app.Valence4CheckA.Value = false;
                app.Valence5CheckA.Value = false;
                % assign result
                app.Valence = 1;
            end  % end of if branch for group toggle
            
            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Valence2CheckA
        function Valence2CheckAValueChanged(app, event)
            value = app.Valence2CheckA.Value;
            
            % if switched on, switch off others in group
            if value
                app.Valence1CheckA.Value = false;
                app.Valence3CheckA.Value = false;
                app.Valence4CheckA.Value = false;
                app.Valence5CheckA.Value = false;
                % assign result
                app.Valence = 2;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Valence3CheckA
        function Valence3CheckAValueChanged(app, event)
            value = app.Valence3CheckA.Value;
            
            % if switched on, switch off others in group
            if value
                app.Valence1CheckA.Value = false;
                app.Valence2CheckA.Value = false;
                app.Valence4CheckA.Value = false;
                app.Valence5CheckA.Value = false;
                % assign result
                app.Valence = 3;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Valence4CheckA
        function Valence4CheckAValueChanged(app, event)
            value = app.Valence4CheckA.Value;
            
            % if switched on, switch off others in group
            if value
                app.Valence1CheckA.Value = false;
                app.Valence2CheckA.Value = false;
                app.Valence3CheckA.Value = false;
                app.Valence5CheckA.Value = false;
                % assign result
                app.Valence = 4;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button

        end

        % Value changed function: Valence5CheckA
        function Valence5CheckAValueChanged(app, event)
            value = app.Valence5CheckA.Value;
           
            % if switched on, switch off others in group
            if value
                app.Valence1CheckA.Value = false;
                app.Valence2CheckA.Value = false;
                app.Valence3CheckA.Value = false;
                app.Valence4CheckA.Value = false;
                % assign result
                app.Valence = 5;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button

        end

        % Value changed function: Arousal1CheckA
        function Arousal1CheckAValueChanged(app, event)
            value = app.Arousal1CheckA.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal2CheckA.Value = false;
                app.Arousal3CheckA.Value = false;
                app.Arousal4CheckA.Value = false;
                app.Arousal5CheckA.Value = false;
                % assign result
                app.Arousal = 1;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Arousal2CheckA
        function Arousal2CheckAValueChanged(app, event)
            value = app.Arousal2CheckA.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal1CheckA.Value = false;
                app.Arousal3CheckA.Value = false;
                app.Arousal4CheckA.Value = false;
                app.Arousal5CheckA.Value = false;
                % assign result
                app.Arousal = 2;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Arousal3CheckA
        function Arousal3CheckAValueChanged(app, event)
            value = app.Arousal3CheckA.Value;

            % if switched on, switch off others in group
            if value
                app.Arousal1CheckA.Value = false;
                app.Arousal2CheckA.Value = false;
                app.Arousal4CheckA.Value = false;
                app.Arousal5CheckA.Value = false;
                % assign result
                app.Arousal = 3;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Arousal4CheckA
        function Arousal4CheckAValueChanged(app, event)
            value = app.Arousal4CheckA.Value;

            % if switched on, switch off others in group
            if value
                app.Arousal1CheckA.Value = false;
                app.Arousal2CheckA.Value = false;
                app.Arousal3CheckA.Value = false;
                app.Arousal5CheckA.Value = false;
                % assign result
                app.Arousal = 4;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Arousal5CheckA
        function Arousal5CheckAValueChanged(app, event)
            value = app.Arousal5CheckA.Value;

            % if switched on, switch off others in group
            if value
                app.Arousal1CheckA.Value = false;
                app.Arousal2CheckA.Value = false;
                app.Arousal3CheckA.Value = false;
                app.Arousal4CheckA.Value = false;
                % assign result
                app.Arousal = 5;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseA(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonA.Enable = 'on';
            else
                app.ProceedButtonA.Enable = 'off';
            end  % end of if branch for enabling/disabling next button          
        end

        % Value changed function: PeopleCheckBox
        function PeopleCheckBoxValueChanged(app, event)
            %value = app.PeopleCheckBox.Value;
            
            % check if post-test responses complete
            CheckResponseA(app)
        end

        % Value changed function: BirdsCheckBox
        function BirdsCheckBoxValueChanged(app, event)
            %value = app.BirdsCheckBox.Value;
            
            % check if post-test responses complete
            CheckResponseA(app)
        end

        % Value changed function: RoadvehiclesCheckBox
        function RoadvehiclesCheckBoxValueChanged(app, event)
            %value = app.RoadvehiclesCheckBox.Value;
            
            % check if post-test responses complete
            CheckResponseA(app)
        end

        % Value changed function: AircraftCheckBox
        function AircraftCheckBoxValueChanged(app, event)
            %value = app.AircraftCheckBox.Value;
            
            % check if post-test responses complete
            CheckResponseA(app)
        end

        % Value changed function: MusicCheckBox
        function MusicCheckBoxValueChanged(app, event)
            %value = app.MusicCheckBox.Value;
            
            % check if post-test responses complete
            CheckResponseA(app)
        end

        % Value changed function: WindleavesCheckBox
        function WindleavesCheckBoxValueChanged(app, event)
            %value = app.WindleavesCheckBox.Value;
            
            % check if post-test responses complete
            CheckResponseA(app)
        end

        % Value changed function: OtherCheckBox
        function OtherCheckBoxValueChanged(app, event)
            value = app.OtherCheckBox.Value;
            % if checked, enable input text field
            if value == true
                app.OtherspecifyEditField.Enable = 'on';
            else
                app.OtherspecifyEditField.Enable = 'off';
            end  % end of if branch for enabling/disabling text field

            % run check to see all responses entered
            CheckResponseA(app)
        end

        % Value changed function: OtherspecifyEditField
        function OtherspecifyEditFieldValueChanged(app, event)
            %value = app.OtherspecifyEditField.Value;
            
            % run check to see all responses entered
            CheckResponseA(app)
        end

        % Button pushed function: PageB2Button
        function PageB2ButtonPushed(app, event)
            % select second briefing page
            app.READMEB2Tab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.READMEB2Tab;
            
            % if first time tab opened, scale fonts
            if app.PageB2ButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.READMEB2Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for font scaling

            % set button pushed flag
            app.PageB2ButtonPushedFlag = true;

        end

        % Button pushed function: PageB3Button
        function PageB3ButtonPushed(app, event)
            % select third briefing page
            app.READMEB3Tab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.READMEB3Tab;

            % if first time tab opened, scale fonts
            if app.PageB3ButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.READMEB3Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for font scaling

            % set button pushed flag
            app.PageB3ButtonPushedFlag = true;

        end

        % Button pushed function: StartPracticeBButton
        function StartPracticeBButtonPushed(app, event)
            % reassign audio file folder
            app.directory = pwd;  % current working dir
            app.subdirTest = fullfile(app.directory, "Audio", "Part B", "Practice");  % Practice stimuli dir

            % assign stimuli files to variables
            app.StimuliTest = shuffle(audioDatastore(app.subdirTest,...
                                                     'FileExtensions',...
                                                     ".wav")).Files(1:end);
            
            % calculate total number of trials
            % number of test files
            app.NTrials = length(dir(fullfile(app.subdirTest, '*.wav')));
            
            % assign trial counter
            app.nTrial = 1;
            
            % add initial trial count text to UI
            app.CurrentNB.Text = "1";
            app.TotalNB.Text = sprintf("%s", string(app.NTrials));

            % re-initialise reader object
            app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrial)));

            % make training Part B tab visible
            app.PartBTab.Parent = app.TabGroup;

            % select training Part B tab
            app.TabGroup.SelectedTab = app.PartBTab;

            if app.StartPracticeBButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.PartBTab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for rescaling
            app.StartPracticeBButtonPushedFlag = true;  % raise flag for rescaled

            % raise practice flag
            app.PracticeFlag = true;
            % disable proceed button
            app.ProceedButtonB.Enable = 'off';
            % disable start practice button
            app.StartPracticeBButton.Enable = 'off';

        end

        % Value changed function: PlaySwitchB
        function PlaySwitchBValueChanged(app, event)
            % assign switch value to property
            app.PlaySwitchVal = app.PlaySwitchB.Value;
            
            % if switched on
            if strcmp(app.PlaySwitchVal, 'Play')
                % disable proceed button while playing
                app.ProceedButtonB.Enable = 'off';
                
                % update timing data
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');

                app.TimescaleB = [app.TimescaleB;...
                                  table(app.ParticipantNo, "B",...
                                  app.nTrial, string(app.t_date),...
                                  string(app.t_time), "Play Switch",...
                                  'VariableNames', ["ID#", "Part",...
                                                    "Trial Number",...
                                                    "Date", "Time",...
                                                    "Button Pressed"])];
                % switch on lamp
                app.LampB.Color = 'g';
                                
                % get audio file info
                fileInfo = audioinfo(app.readerTest.Filename);

                % convert BitsPerSample number into BitDepth string
                try
                    switch fileInfo.BitsPerSample
                        case 8
                            BitDepth = "8-bit integer";
                        case 16
                            BitDepth = "16-bit integer";
                        case 24
                            BitDepth = "24-bit integer";
                        case 32
                            BitDepth = "32-bit float";
                    end
                catch
                    BitDepth = "16-bit integer";
                end

                % create player object
                if strcmp(app.ChannelMapMode, 'Property')
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerTest.SampleRate,...
                                                   'BitDepth', BitDepth,...
                                                   'ChannelMappingSource', app.ChannelMapMode,...
                                                   'ChannelMapping', app.ChannelMap);
                else
                    app.player = audioDeviceWriter('Device', app.audiodevice,...
                                                   'Driver', app.audiodriver,...
                                                   'SampleRate',...
                                                   app.readerTest.SampleRate,...
                                                   'BitDepth', BitDepth);
                end

                % check device can support file channels
                if info(app.player).MaximumOutputChannels < fileInfo.NumChannels
                    error("Error: audio device used does not support number of channels in file")
                elseif fileInfo.NumChannels == 1 && strcmp(app.ChannelMapMode, 'Property')
                    app.player.ChannelMapping = 1;
                elseif fileInfo.NumChannels < info(app.player).MaximumOutputChannels && strcmp(app.ChannelMapMode, 'Property')
                    error("Error: number of channels in file does not match channel mapping")
                end

                % Call setup to reduce the computational load of initialization in an audio stream loop.
                % openExample('dsp/ReadFromFileAndWriteToAudioDeviceExample')
                setup(app.player, zeros(app.SamplesPerFrame, fileInfo.NumChannels))

                % start playback functionality
                app.TogglePlay = true;
                
                % while loop to stream audio data frames to player object
                frameCount = 0;  % frame counter
                % count total number of frames in the reader file
                frameTotal = ceil(fileInfo.TotalSamples/app.readerTest.SamplesPerFrame);
                try  % avoids error on app exit deletion if stimulus still playing
                    while ~isDone(app.readerTest) && app.TogglePlay
                        drawnow()  % updates app values
                        frameCount = frameCount + 1;
                        % if filereader reaches end of file or stimulus
                        % switched off, toggle the switch and lamp, and
                        % release the file reader object
                        if strcmp(app.PlaySwitchVal, 'Stop')
                            app.TogglePlay = StimEnd(app, app.player, app.PlaySwitchA, app.LampA);
                            release(app.readerTest)
                            break
                        else  % keep playing file
                            audioData = app.readerTest();
                            app.player(audioData);  % scaled down from file by 15 dB

                        end  % end of if branch for end of stimulus file
                    end  % end of while loop for streaming audio to player
                    % check file played through
                    if frameCount == frameTotal
                        app.PlayComplete = 1;
                    end  % end of if branch for file played check
                    app.TogglePlay = StimEnd(app, app.player, app.PlaySwitchB, app.LampB);
                    release(app.readerTest)

                catch
                end  % end of try catch branch

                % evaluate SAM response check
                app.ResponseCheck = CheckResponseB(app);

                % if stimulus played and responses entered, enable next button
                if app.PlayComplete > 0 && app.ResponseCheck == 1
                    app.ProceedButtonB.Enable = 'on';
                else
                    app.ProceedButtonB.Enable = 'off';
                end  % end of if branch for enabling next button

            % if switched off, stop stimulus
            else
                app.TogglePlay = StimOff(app, app.player, app.PlaySwitchB, app.LampB);
                release(app.readerTest)
            end  % end of if branch for stimulus switched on
        end

        % Button pushed function: ProceedButtonB
        function ProceedButtonBPushed(app, event)
            % if start of test (not practice)
            if app.ProceedButtonB.Text == "Start Test"
                % lower practice flag
                app.PracticeFlag = false;
                % notify participant the test has started
                msgbox("This is the second part of the test - please commence with listening and rating the sounds", ...
                       "Test started", "help");
                % make controls visible
                app.PlaySwitchB.Visible = 'on';
                app.Valence1CheckB.Visible = 'on';
                app.Valence2CheckB.Visible = 'on';
                app.Valence3CheckB.Visible = 'on';
                app.Valence4CheckB.Visible = 'on';
                app.Valence5CheckB.Visible = 'on';
                app.Arousal1CheckB.Visible = 'on';
                app.Arousal2CheckB.Visible = 'on';
                app.Arousal3CheckB.Visible = 'on';
                app.Arousal4CheckB.Visible = 'on';
                app.Arousal5CheckB.Visible = 'on';
                app.AnnoyanceSliderB.Visible = 'on';
                app.InstructionPlayB_1.Visible = 'on';
                app.InstructionPlayB_2.Visible = 'on';
                app.InstructionPlayB_3.Visible = 'on';
                app.InstructionSAMB.Visible = 'on';
                app.AnnoyQBLabel.Visible = 'on';
                app.movethesliderBLabel.Visible = 'on';
                app.PlaySwitchLabelB.Visible = 'on';
                app.valence1img_2.Visible = 'on';
                app.valence2img_2.Visible = 'on';
                app.valence3img_2.Visible = 'on';
                app.valence4img_2.Visible = 'on';
                app.valence5img_2.Visible = 'on';
                app.arousal1img_2.Visible = 'on';
                app.arousal2img_2.Visible = 'on';
                app.arousal3img_2.Visible = 'on';
                app.arousal4img_2.Visible = 'on';
                app.arousal5img_2.Visible = 'on';
                app.NotatallLabel_2.Visible = 'on';
                app.ExtremelyLabel_2.Visible = 'on';
                app.LampB.Visible = 'on';
                
            end  % end of if branch for lowering practice flag

            % switch off stimulus, release player and file reader
            app.TogglePlay = StimOff(app, app.player, app.PlaySwitchB, app.LampB);
            release(app.readerTest)

            % reset flags to indicate stimuli played
            % app.PlaySwitched = 0;
            app.PlayComplete = 0;

            % reset proceed button for new trial
            app.ProceedButtonB.Text = "Next";
            app.ProceedButtonB.Enable = 'off';

            % if a test round, add data to table
            if ~app.PracticeFlag && ~app.StartTest
                ResponseNames = ["ID#", "Part", "Trial Number", "Stim File",...
                                 "Valence", "Arousal", "Annoyance"];

                % get filenames assigned to stimulus
                filepath = app.readerTest.Filename;
                [~, filename, ext] = fileparts(filepath);
                filename = {strcat(filename, ext)};

                % append participant test to table
                app.Participant_TestDataB = [app.Participant_TestDataB;...
                                             table(app.ParticipantNo, "B",...
                                                   app.nTrial, filename,...
                                                   app.Valence, app.Arousal,...
                                                   app.AnnoyanceSliderB.Value,...
                                                   'VariableNames', ResponseNames)];
                
                % append participant timing data to table
                app.t_date = datetime('now','Format','yyyy-MM-dd');
                app.t_time = datetime('now','Format','HH:mm:ss.SSS');
                
                app.TimescaleB = [app.TimescaleB;...
                                  table(app.ParticipantNo, "B",...
                                        app.nTrial, string(app.t_date), ...
                                        string(app.t_time), "Next Button",...
                                        'VariableNames',...
                                        ["ID#", "Part", "Trial Number",...
                                         "Date", "Time", ...
                                         "Button Pressed"])];
            
            end  % end of if branch for appending test data   
            
            % reset switch initial state
            app.PlaySwitchB.Value = 'Stop';

            % reset or randomise initial judgement scales
            ResetResponsesB(app)
            app.AnnoyanceSliderB.Value = randi(11) - 1;
           
            if ~app.StartTest
                % increment current trial number
                app.nTrial = app.nTrial + 1;
            end

            app.StartTest = false;

            % if total trial number is reached, end test and write data
            if app.nTrial > app.NTrials
                % make controls invisible
                app.PlaySwitchB.Visible = 'off';
                app.Valence1CheckB.Visible = 'off';
                app.Valence2CheckB.Visible = 'off';
                app.Valence3CheckB.Visible = 'off';
                app.Valence4CheckB.Visible = 'off';
                app.Valence5CheckB.Visible = 'off';
                app.Arousal1CheckB.Visible = 'off';
                app.Arousal2CheckB.Visible = 'off';
                app.Arousal3CheckB.Visible = 'off';
                app.Arousal4CheckB.Visible = 'off';
                app.Arousal5CheckB.Visible = 'off';
                app.AnnoyanceSliderB.Visible = 'off';
                app.InstructionPlayB_1.Visible = 'off';
                app.InstructionPlayB_2.Visible = 'off';
                app.InstructionPlayB_3.Visible = 'off';
                app.InstructionSAMB.Visible = 'off';
                app.AnnoyQBLabel.Visible = 'off';
                app.movethesliderBLabel.Visible = 'off';
                app.PlaySwitchLabelB.Visible = 'off';
                app.valence1img_2.Visible = 'off';
                app.valence2img_2.Visible = 'off';
                app.valence3img_2.Visible = 'off';
                app.valence4img_2.Visible = 'off';
                app.valence5img_2.Visible = 'off';
                app.arousal1img_2.Visible = 'off';
                app.arousal2img_2.Visible = 'off';
                app.arousal3img_2.Visible = 'off';
                app.arousal4img_2.Visible = 'off';
                app.arousal5img_2.Visible = 'off';
                app.NotatallLabel_2.Visible = 'off';
                app.ExtremelyLabel_2.Visible = 'off';
                app.LampB.Visible = 'off';
                
                if app.PracticeFlag
                    msgbox("You have completed the practice round - when you are ready, please push the 'Start Test' button below", ...
                           "Practice complete", "help");
                    
                    % reset proceed button
                    app.ProceedButtonB.Text = "Start Test";
                    app.ProceedButtonB.Enable = 'on';
                    
                    % reassign audio file folder
                    app.directory = pwd;  % current working dir
                    app.subdirTest = fullfile(app.directory, "Audio", "Part B", "Test");  % Test stimuli dir
        
                    % assign stimuli files to variables
                    app.StimuliTest = shuffle(audioDatastore(app.subdirTest,...
                                                             'FileExtensions',...
                                                             ".wav")).Files(1:end);

                    % calculate total number of trials
                    % number of test files
                    app.NTrials = length(dir(fullfile(app.subdirTest, '*.wav')));
                    
                    % set switch initial state
                    % app.PlaySwitched = 0;
                    % set play complete initial state
                    app.PlayComplete = 0;
        
                    % reset trial counter
                    app.nTrial = 1;
                    
                    % add initial trial count text to UI
                    app.CurrentNB.Text = "1";
                    app.TotalNB.Text = sprintf("%s", string(app.NTrials));
            
                    % re-initialise reader object
                    app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrial)));
                
                    app.StartTest = true;
                else
                    t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
                    writetable(app.Participant_TestDataB, ...
                               fullfile(app.directory, "Results",...
                               sprintf('%s_TestPtB_ParticipantNo%s_Response.xlsx',...
                               t_end, string(app.ParticipantNo))));
                    writetable(app.TimescaleB, ...
                               fullfile(app.directory, "Results",...
                               sprintf('%s_TestPtB_ParticipantNo%s_Timing.xlsx',...
                               t_end, string(app.ParticipantNo))));

                    msgbox("You have completed the second part - please complete the post-test questions", ...
                           "Part B complete", "help");

                    % open post-test tab and make part B tabs invisible
                    app.Posttest1Tab.Parent = app.TabGroup;
                    app.TabGroup.SelectedTab = app.Posttest1Tab;
                    app.SubmitPostButton.Enable = 'off';
                    app.READMEB1Tab.Parent = [];
                    app.READMEB2Tab.Parent = [];
                    app.READMEB3Tab.Parent = [];
                    app.PartBTab.Parent = [];

                    % get the handles of object, with the font size property 
                uitabhandle = findobj(app.Posttest1Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));

                end  % end of if branch for practice or test complete

            else  % if test files remaining, continue with next stimulus
                    
                % update trial count on UI
                app.CurrentNB.Text = string(app.nTrial);

                % re-initialise reader object
                app.readerTest = dsp.AudioFileReader(cell2mat(app.StimuliTest(app.nTrial)));

            end  % end of if branch for total number of trials reached
        end

        % Value changed function: Valence1CheckB
        function Valence1CheckBValueChanged(app, event)
            value = app.Valence1CheckB.Value;

            % if switched on, switch off others in group
            if value
                app.Valence2CheckB.Value = false;
                app.Valence3CheckB.Value = false;
                app.Valence4CheckB.Value = false;
                app.Valence5CheckB.Value = false;
                % assign result
                app.Valence = 1;
            end  % end of if branch for group toggle
            
            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Valence2CheckB
        function Valence2CheckBValueChanged(app, event)
            value = app.Valence2CheckB.Value;

            % if switched on, switch off others in group
            if value
                app.Valence1CheckB.Value = false;
                app.Valence3CheckB.Value = false;
                app.Valence4CheckB.Value = false;
                app.Valence5CheckB.Value = false;
                % assign result
                app.Valence = 2;
            end  % end of if branch for group toggle
            
            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Valence3CheckB
        function Valence3CheckBValueChanged(app, event)
            value = app.Valence3CheckB.Value;

            % if switched on, switch off others in group
            if value
                app.Valence1CheckB.Value = false;
                app.Valence2CheckB.Value = false;
                app.Valence4CheckB.Value = false;
                app.Valence5CheckB.Value = false;
                % assign result
                app.Valence = 3;
            end  % end of if branch for group toggle
            
            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button 
        end

        % Value changed function: Valence4CheckB
        function Valence4CheckBValueChanged(app, event)
            value = app.Valence4CheckB.Value;

            % if switched on, switch off others in group
            if value
                app.Valence1CheckB.Value = false;
                app.Valence2CheckB.Value = false;
                app.Valence3CheckB.Value = false;
                app.Valence5CheckB.Value = false;
                % assign result
                app.Valence = 4;
            end  % end of if branch for group toggle
            
            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button            
        end

        % Value changed function: Valence5CheckB
        function Valence5CheckBValueChanged(app, event)
            value = app.Valence5CheckB.Value;

            % if switched on, switch off others in group
            if value
                app.Valence1CheckB.Value = false;
                app.Valence2CheckB.Value = false;
                app.Valence3CheckB.Value = false;
                app.Valence4CheckB.Value = false;
                % assign result
                app.Valence = 5;
            end  % end of if branch for group toggle
            
            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Arousal1CheckB
        function Arousal1CheckBValueChanged(app, event)
            value = app.Arousal1CheckB.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal2CheckB.Value = false;
                app.Arousal3CheckB.Value = false;
                app.Arousal4CheckB.Value = false;
                app.Arousal5CheckB.Value = false;
                % assign result
                app.Arousal = 1;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button
        end

        % Value changed function: Arousal2CheckB
        function Arousal2CheckBValueChanged(app, event)
            value = app.Arousal2CheckB.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal1CheckB.Value = false;
                app.Arousal3CheckB.Value = false;
                app.Arousal4CheckB.Value = false;
                app.Arousal5CheckB.Value = false;
                % assign result
                app.Arousal = 2;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button            
        end

        % Value changed function: Arousal3CheckB
        function Arousal3CheckBValueChanged(app, event)
            value = app.Arousal3CheckB.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal1CheckB.Value = false;
                app.Arousal2CheckB.Value = false;
                app.Arousal4CheckB.Value = false;
                app.Arousal5CheckB.Value = false;
                % assign result
                app.Arousal = 3;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button            
        end

        % Value changed function: Arousal4CheckB
        function Arousal4CheckBValueChanged(app, event)
            value = app.Arousal4CheckB.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal1CheckB.Value = false;
                app.Arousal2CheckB.Value = false;
                app.Arousal3CheckB.Value = false;
                app.Arousal5CheckB.Value = false;
                % assign result
                app.Arousal = 4;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button            
        end

        % Value changed function: Arousal5CheckB
        function Arousal5CheckBValueChanged(app, event)
            value = app.Arousal5CheckB.Value;
            
            % if switched on, switch off others in group
            if value
                app.Arousal1CheckB.Value = false;
                app.Arousal2CheckB.Value = false;
                app.Arousal3CheckB.Value = false;
                app.Arousal4CheckB.Value = false;
                % assign result
                app.Arousal = 5;
            end  % end of if branch for group toggle

            % update SAM response check
            app.ResponseCheck = CheckResponseB(app);

            % if stimulus played and responses entered, enable next button
            if app.PlayComplete > 0 && app.ResponseCheck == 1
                app.ProceedButtonB.Enable = 'on';
            else
                app.ProceedButtonB.Enable = 'off';
            end  % end of if branch for enabling/disabling next button            
        end

        % Value changed function: AnnoyanceSliderB
        function AnnoyanceSliderBValueChanged(app, event)
            value = app.AnnoyanceSliderB.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.AnnoyanceSliderB.Value = event.Source.MajorTicks(minIdx);
        end

        % Button pushed function: PagePost2Button
        function PagePost2ButtonPushed(app, event)
            % select second post-test page
            app.Posttest2Tab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.Posttest2Tab;

            % if first time tab opened, scale fonts
            if app.PagePost2ButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.Posttest2Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for font scaling

            % set button pushed flag
            app.PagePost2ButtonPushedFlag = true;
        end

        % Button pushed function: PagePost3Button
        function PagePost3ButtonPushed(app, event)
            % select third post-test page
            app.Posttest3Tab.Parent = app.TabGroup;
            app.TabGroup.SelectedTab = app.Posttest3Tab;

            % if first time tab opened, scale fonts
            if app.PagePost3ButtonPushedFlag == false
                % get the handles of object, with the font size property 
                uitabhandle = findobj(app.Posttest3Tab, '-property', 'FontSize');
                % get the current font size
                tabhFontSize = cell2mat(get(uitabhandle, 'FontSize'));
                % change the font size using resize factor
                tabnewFontSize =  tabhFontSize*app.resizeFactor;
                % set the new font size
                set(uitabhandle, {'FontSize'}, num2cell(tabnewFontSize));
            end  % end of if branch for font scaling

            % set button pushed flag
            app.PagePost3ButtonPushedFlag = true;
        end

        % Button pushed function: SubmitPostButton
        function SubmitPostButtonPushed(app, event)
            % personal info
            % if age entered, assign value to output variable
            if ~app.PrefernotAgeCheckBox.Value
                ageResponse = app.AgeEditField.Value;
            else
                ageResponse = "No answer";
            end  % end of if branch for age output assignment

            % if sex entered, assign value to output variable
            if ~app.PrefernotSexCheckBox.Value
                sexResponse = app.SexDropDown.Value;
            else
                sexResponse = "No answer";
            end  % end of if branch for sex output assignment
            
            % if nationality entered, assign value to output variable
            if ~app.PrefernotNationCheckBox.Value
                nationResponse = app.NationalityEditField.Value;
            else
                nationResponse = "No answer";
            end  % end of if branch for nationality output assignment

            % language entered
            if app.EnglishLanguageCheckBox.Value == true
                langResponse = "English";
            else
                langResponse = app.LanguageEditField.Value;
            end  % end of if branch for language output assignment

            % home area classifications
            HomeClass = ["Urban", "Suburban", "Rural"];
            HomeClassCheck = [app.UrbanCheckBox.Value,...
                              app.SuburbanCheckBox.Value,...
                              app.RuralCheckBox.Value];
            HomeClass = HomeClass(HomeClassCheck);

            HomeScape = ["Vibrant", "Chaotic", "Calm", "Monotonous"];
            HomeScapeCheck = [app.VibrantCheckBox.Value,...
                              app.ChaoticCheckBox.Value,...
                              app.CalmCheckBox.Value,...
                              app.MonotonousCheckBox.Value];
            HomeScape = HomeScape(HomeScapeCheck);

            % noise sensitivity results
            % invert NSS question dictionary
            invNSSQuestions = dictionary(values(app.NSSQuestions), keys(app.NSSQuestions));

            tempkeys = [invNSSQuestions(app.NSSQ1Label.Text),...
                        invNSSQuestions(app.NSSQ2Label.Text),...
                        invNSSQuestions(app.NSSQ3Label.Text),...
                        invNSSQuestions(app.NSSQ4Label.Text),...
                        invNSSQuestions(app.NSSQ5Label.Text)];

            tempvalues = [app.NSSQ1Slider.Value, app.NSSQ2Slider.Value,...
                          app.NSSQ3Slider.Value, app.NSSQ4Slider.Value,...
                          app.NSSQ5Slider.Value];

            NSSResults = dictionary(tempkeys, tempvalues);
            % reverse score the NSS-SF item 8
            NSSResults("NSS8") = 7 - NSSResults("NSS8");
            NSSTotal = sum(values(NSSResults));
            NSSResults = NSSResults(app.NSSQitemNo);

            % attitude to AAM
            AttitudeAAM = ["Concerned", "Ambivalent", "Neutral", "Supportive"];
            AttitudeAAMCheck = [app.ConcernedCheckBox.Value,...
                                app.AmbivalentCheckBox.Value,...
                                app.NeutralCheckBox.Value,...
                                app.SupportiveCheckBox.Value];
            AttitudeAAM = AttitudeAAM(AttitudeAAMCheck);

            % compile results to table
            PostTestVariables = ["ID#", "Age", "Sex", "Nationality", "Language",...
                                 "Home Area", "Area soundscape",...
                                 "NSSTotal", app.NSSQitemNo,...
                                 "Response Considerations", "Source comments",...
                                 "AAM Exp", "AAM attitude", "General Feedback"];

            app.Participant_PostTestData = table(app.ParticipantNo,...
                                                 ageResponse, {sexResponse},...
                                                 {nationResponse}, {langResponse},...
                                                 {HomeClass}, {HomeScape},...
                                                 NSSTotal, NSSResults(1),...
                                                 NSSResults(2), NSSResults(3),...
                                                 NSSResults(4), NSSResults(5),...
                                                 {app.SceneConsiderationsEditField.Value},...
                                                 {app.SourceCommentsEditField.Value},...
                                                 {app.ExperienceEditField.Value},...
                                                 {AttitudeAAM},...
                                                 {app.FeedbackEditField.Value},...
                                                 'VariableNames', PostTestVariables);
            
            
            % write table to file
            t_end = datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss');
            writetable(app.Participant_PostTestData, ...
                       fullfile(app.directory, "Results",...
                       sprintf('%s_PostTestQ_ParticipantNo%s_Response.xlsx',...
                       t_end, string(app.ParticipantNo))));

            % close app
            app.UIFigure.Visible = 'off';
            msgbox("Thank you for completing the test", ...
                   "Test complete", "help");
            delete(app);
        end

        % Value changed function: NSSQ1Slider
        function NSSQ1SliderValueChanged(app, event)
            value = app.NSSQ1Slider.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.NSSQ1Slider.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: NSSQ2Slider
        function NSSQ2SliderValueChanged(app, event)
            value = app.NSSQ2Slider.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.NSSQ2Slider.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: NSSQ3Slider
        function NSSQ3SliderValueChanged(app, event)
            value = app.NSSQ3Slider.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.NSSQ3Slider.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: NSSQ4Slider
        function NSSQ4SliderValueChanged(app, event)
            value = app.NSSQ4Slider.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.NSSQ4Slider.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: NSSQ5Slider
        function NSSQ5SliderValueChanged(app, event)
            value = app.NSSQ5Slider.Value;
            
            % get the nearest slider discrete value
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));

            % shift slider to the nearest discrete value
            event.Source.Value = event.Source.MajorTicks(minIdx);

            % assign the discrete value to the slider object output
            app.NSSQ5Slider.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: PrefernotAgeCheckBox
        function PrefernotAgeCheckBoxValueChanged(app, event)
            value = app.PrefernotAgeCheckBox.Value;
            % switch off input field if checked
            if value == true
                app.AgeEditField.Enable = 'off';
            else
                app.AgeEditField.Enable = 'on';
            end  % end of if branch for enable/disable input field

            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: PrefernotSexCheckBox
        function PrefernotSexCheckBoxValueChanged(app, event)
            value = app.PrefernotSexCheckBox.Value;
            % switch off input field if checked
            if value == true
                app.SexDropDown.Enable = 'off';
            else
                app.SexDropDown.Enable = 'on';
            end  % end of if branch for enable/disable input field

            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: UrbanCheckBox
        function UrbanCheckBoxValueChanged(app, event)
            value = app.UrbanCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.SuburbanCheckBox.Value = false;
                app.RuralCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: SuburbanCheckBox
        function SuburbanCheckBoxValueChanged(app, event)
            value = app.SuburbanCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.RuralCheckBox.Value = false;
                app.UrbanCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: RuralCheckBox
        function RuralCheckBoxValueChanged(app, event)
            value = app.RuralCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.SuburbanCheckBox.Value = false;
                app.UrbanCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: CalmCheckBox
        function CalmCheckBoxValueChanged(app, event)
            value = app.CalmCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.VibrantCheckBox.Value = false;
                app.ChaoticCheckBox.Value = false;
                app.MonotonousCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: VibrantCheckBox
        function VibrantCheckBoxValueChanged(app, event)
            value = app.VibrantCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.CalmCheckBox.Value = false;
                app.ChaoticCheckBox.Value = false;
                app.MonotonousCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: ChaoticCheckBox
        function ChaoticCheckBoxValueChanged(app, event)
            value = app.ChaoticCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.VibrantCheckBox.Value = false;
                app.CalmCheckBox.Value = false;
                app.MonotonousCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: MonotonousCheckBox
        function MonotonousCheckBoxValueChanged(app, event)
            value = app.MonotonousCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.VibrantCheckBox.Value = false;
                app.ChaoticCheckBox.Value = false;
                app.CalmCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            %value = app.AgeEditField.Value;
            
            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)
            %value = app.SexDropDown.Value;
            
            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: SupportiveCheckBox
        function SupportiveCheckBoxValueChanged(app, event)
            value = app.SupportiveCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.ConcernedCheckBox.Value = false;
                app.AmbivalentCheckBox.Value = false;
                app.NeutralCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: ConcernedCheckBox
        function ConcernedCheckBoxValueChanged(app, event)
            value = app.ConcernedCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.SupportiveCheckBox.Value = false;
                app.AmbivalentCheckBox.Value = false;
                app.NeutralCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: AmbivalentCheckBox
        function AmbivalentCheckBoxValueChanged(app, event)
            value = app.AmbivalentCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.ConcernedCheckBox.Value = false;
                app.SupportiveCheckBox.Value = false;
                app.NeutralCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: NeutralCheckBox
        function NeutralCheckBoxValueChanged(app, event)
            value = app.NeutralCheckBox.Value;
            % if selected, deactivate other options in group
            if value == true
                app.ConcernedCheckBox.Value = false;
                app.AmbivalentCheckBox.Value = false;
                app.SupportiveCheckBox.Value = false;
            end  % end of if branch for deactivating other group options

            % check if post-test responses complete
            CheckPostTestResponse(app)            
        end

        % Value changed function: NationalityEditField
        function NationalityEditFieldValueChanged(app, event)
            %value = app.NationalityEditField.Value;
            
            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: PrefernotNationCheckBox
        function PrefernotNationCheckBoxValueChanged(app, event)
            value = app.PrefernotNationCheckBox.Value;
            
            % if selected, deactivate input field
            if value == true
                app.NationalityEditField.Enable = 'off';
            else
                app.NationalityEditField.Enable = 'on';
            end

            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: EnglishLanguageCheckBox
        function EnglishLanguageCheckBoxValueChanged(app, event)
            value = app.EnglishLanguageCheckBox.Value;
            
            % if selected, deselect other option and deactivate input field
            if value == true
                app.OtherLanguageCheckBox.Value = false;
                app.LanguageEditField.Enable = 'off';
            end

            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: OtherLanguageCheckBox
        function OtherLanguageCheckBoxValueChanged(app, event)
            value = app.OtherLanguageCheckBox.Value;
            
            % if selected, deselect other option and activate input field
            if value == true
                app.EnglishLanguageCheckBox.Value = false;
                app.LanguageEditField.Enable = 'on';
            else
                app.LanguageEditField.Enable = 'off';
            end

            % check if post-test responses complete
            CheckPostTestResponse(app)
        end

        % Value changed function: LanguageEditField
        function LanguageEditFieldValueChanged(app, event)
            %value = app.LanguageEditField.Value;
            
            % check if post-test responses complete
            CheckPostTestResponse(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PretestTab = uitab(app.TabGroup);
            app.PretestTab.Title = 'Pre-test';
            app.GridLayoutPreTest = uigridlayout(app.PretestTab);
            app.ReadbriefButton = uibutton(app.GridLayoutPreTest, 'push');
            app.ReadbriefButton.ButtonPushedFcn = createCallbackFcn(app, @ReadbriefButtonPushed, true);
            app.ReadbriefButton.Text = 'Read brief';
            app.PANASGroup = uipanel(app.GridLayoutPreTest);
            app.GridLayoutPreTestPANAS = uigridlayout(app.PANASGroup);
            app.InstructionPANAS_3 = uilabel(app.GridLayoutPreTestPANAS);
            app.InstructionPANAS_3.Text = 'You can use the TAB key to cycle through the answer boxes. Use the ENTER key to complete your responses.';
            app.InstructionPANAS_2 = uilabel(app.GridLayoutPreTestPANAS);
            app.InstructionPANAS_2.Text = {'Use the following scale to record your answers:'; ''; '0: very slightly or not at all'; '1: a little'; '2: moderately'; '3: quite a bit'; '4: extremely'; ''; 'If you are unsure of a word meaning, please rate it as -1.'};
            app.InstructionPANAS = uilabel(app.GridLayoutPreTestPANAS);
            app.InstructionPANAS.Text = {'How are you feeling?'; 'This scale consists of a number of words that describe different feelings and emotions. Please mark the appropriate answer in the space next to each word — indicate (by entering a number) to what extent you feel this way right now, that is, at the present moment.'};
            app.PANASPanel = uipanel(app.GridLayoutPreTestPANAS);
            app.GridLayoutPreTestPANASPanel = uigridlayout(app.PANASPanel);
            app.afraidFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.afraidFieldLabel.Text = 'afraid';
            app.activeFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.activeFieldLabel.Text = 'active';
            app.jitteryFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.jitteryFieldLabel.Text = 'jittery';
            app.attentiveFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.attentiveFieldLabel.Text = 'attentive';
            app.determinedFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.determinedFieldLabel.Text = 'determined';
            app.nervousFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.nervousFieldLabel.Text = 'nervous';
            app.inspiredFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.inspiredFieldLabel.Text = 'inspired';
            app.ashamedFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.ashamedFieldLabel.Text = 'ashamed';
            app.alertFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.alertFieldLabel.Text = 'alert';
            app.irritableFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.irritableFieldLabel.Text = 'irritable';
            app.proudFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.proudFieldLabel.Text = 'proud';
            app.enthusiasticFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.enthusiasticFieldLabel.Text = 'enthusiastic';
            app.hostileFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.hostileFieldLabel.Text = 'hostile';
            app.scaredFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.scaredFieldLabel.Text = 'scared';
            app.guiltyFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.guiltyFieldLabel.Text = 'guilty';
            app.strongFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.strongFieldLabel.Text = 'strong';
            app.upsetFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.upsetFieldLabel.Text = 'upset';
            app.excitedFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.excitedFieldLabel.Text = 'excited';
            app.distressedFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.distressedFieldLabel.Text = 'distressed';
            app.interestedFieldLabel = uilabel(app.GridLayoutPreTestPANASPanel);
            app.interestedFieldLabel.Text = 'interested';
            app.PANASinterestedField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASinterestedField.Limits = [-1 4];
            app.PANASinterestedField.ValueDisplayFormat = '%.0f';
            app.PANASinterestedField.ValueChangedFcn = createCallbackFcn(app, @PANASinterestedFieldValueChanged, true);
            app.PANASinterestedField.Value = -1;
            app.PANASdistressedField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASdistressedField.Limits = [-1 4];
            app.PANASdistressedField.ValueDisplayFormat = '%.0f';
            app.PANASdistressedField.ValueChangedFcn = createCallbackFcn(app, @PANASdistressedFieldValueChanged, true);
            app.PANASdistressedField.Value = -1;
            app.PANASexcitedField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASexcitedField.Limits = [-1 4];
            app.PANASexcitedField.ValueDisplayFormat = '%.0f';
            app.PANASexcitedField.ValueChangedFcn = createCallbackFcn(app, @PANASexcitedFieldValueChanged, true);
            app.PANASexcitedField.Value = -1;
            app.PANASupsetField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASupsetField.Limits = [-1 4];
            app.PANASupsetField.ValueDisplayFormat = '%.0f';
            app.PANASupsetField.ValueChangedFcn = createCallbackFcn(app, @PANASupsetFieldValueChanged, true);
            app.PANASupsetField.Value = -1;
            app.PANASstrongField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASstrongField.Limits = [-1 4];
            app.PANASstrongField.ValueDisplayFormat = '%.0f';
            app.PANASstrongField.ValueChangedFcn = createCallbackFcn(app, @PANASstrongFieldValueChanged, true);
            app.PANASstrongField.Value = -1;
            app.PANASguiltyField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASguiltyField.Limits = [-1 4];
            app.PANASguiltyField.ValueDisplayFormat = '%.0f';
            app.PANASguiltyField.ValueChangedFcn = createCallbackFcn(app, @PANASguiltyFieldValueChanged, true);
            app.PANASguiltyField.Value = -1;
            app.PANASscaredField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASscaredField.Limits = [-1 4];
            app.PANASscaredField.ValueDisplayFormat = '%.0f';
            app.PANASscaredField.ValueChangedFcn = createCallbackFcn(app, @PANASscaredFieldValueChanged, true);
            app.PANASscaredField.Value = -1;
            app.PANAShostileField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANAShostileField.Limits = [-1 4];
            app.PANAShostileField.ValueDisplayFormat = '%.0f';
            app.PANAShostileField.ValueChangedFcn = createCallbackFcn(app, @PANAShostileFieldValueChanged, true);
            app.PANAShostileField.Value = -1;
            app.PANASenthusiasticField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASenthusiasticField.Limits = [-1 4];
            app.PANASenthusiasticField.ValueDisplayFormat = '%.0f';
            app.PANASenthusiasticField.ValueChangedFcn = createCallbackFcn(app, @PANASenthusiasticFieldValueChanged, true);
            app.PANASenthusiasticField.Value = -1;
            app.PANASproudField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASproudField.Limits = [-1 4];
            app.PANASproudField.ValueDisplayFormat = '%.0f';
            app.PANASproudField.ValueChangedFcn = createCallbackFcn(app, @PANASproudFieldValueChanged, true);
            app.PANASproudField.Value = -1;
            app.PANASirritableField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASirritableField.Limits = [-1 4];
            app.PANASirritableField.ValueDisplayFormat = '%.0f';
            app.PANASirritableField.ValueChangedFcn = createCallbackFcn(app, @PANASirritableFieldValueChanged, true);
            app.PANASirritableField.Value = -1;
            app.PANASalertField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASalertField.Limits = [-1 4];
            app.PANASalertField.ValueDisplayFormat = '%.0f';
            app.PANASalertField.ValueChangedFcn = createCallbackFcn(app, @PANASalertFieldValueChanged, true);
            app.PANASalertField.Value = -1;
            app.PANASashamedField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASashamedField.Limits = [-1 4];
            app.PANASashamedField.ValueDisplayFormat = '%.0f';
            app.PANASashamedField.ValueChangedFcn = createCallbackFcn(app, @PANASashamedFieldValueChanged, true);
            app.PANASashamedField.Value = -1;
            app.PANASinspiredField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASinspiredField.Limits = [-1 4];
            app.PANASinspiredField.ValueDisplayFormat = '%.0f';
            app.PANASinspiredField.ValueChangedFcn = createCallbackFcn(app, @PANASinspiredFieldValueChanged, true);
            app.PANASinspiredField.Value = -1;
            app.PANASnervousField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASnervousField.Limits = [-1 4];
            app.PANASnervousField.ValueDisplayFormat = '%.0f';
            app.PANASnervousField.ValueChangedFcn = createCallbackFcn(app, @PANASnervousFieldValueChanged, true);
            app.PANASnervousField.Value = -1;
            app.PANASdeterminedField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASdeterminedField.Limits = [-1 4];
            app.PANASdeterminedField.ValueDisplayFormat = '%.0f';
            app.PANASdeterminedField.ValueChangedFcn = createCallbackFcn(app, @PANASdeterminedFieldValueChanged, true);
            app.PANASdeterminedField.Value = -1;
            app.PANASattentiveField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASattentiveField.Limits = [-1 4];
            app.PANASattentiveField.ValueDisplayFormat = '%.0f';
            app.PANASattentiveField.ValueChangedFcn = createCallbackFcn(app, @PANASattentiveFieldValueChanged, true);
            app.PANASattentiveField.Value = -1;
            app.PANASjitteryField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASjitteryField.Limits = [-1 4];
            app.PANASjitteryField.ValueDisplayFormat = '%.0f';
            app.PANASjitteryField.ValueChangedFcn = createCallbackFcn(app, @PANASjitteryFieldValueChanged, true);
            app.PANASjitteryField.Value = -1;
            app.PANASactiveField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASactiveField.Limits = [-1 4];
            app.PANASactiveField.ValueDisplayFormat = '%.0f';
            app.PANASactiveField.ValueChangedFcn = createCallbackFcn(app, @PANASactiveFieldValueChanged, true);
            app.PANASactiveField.Value = -1;
            app.PANASafraidField = uieditfield(app.GridLayoutPreTestPANASPanel, 'numeric');
            app.PANASafraidField.Limits = [-1 4];
            app.PANASafraidField.ValueDisplayFormat = '%.0f';
            app.PANASafraidField.ValueChangedFcn = createCallbackFcn(app, @PANASafraidFieldValueChanged, true);
            app.PANASafraidField.Value = -1;
            app.InstructionPreTest = uilabel(app.GridLayoutPreTest);
            app.InstructionPreTest.Text = 'Please answer the following two questions as indicated below, then click on the button to read the test briefing.';
            app.Panel = uipanel(app.GridLayoutPreTest);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.InstructionPreTestHear = uilabel(app.GridLayout2);
            app.InstructionPreTestHear.Text = {'Do you have any hearing impairments as far as you are aware?'; ''; 'If you answer Yes, please provide details in the box (and press the ENTER key).'};
            app.HearButtonGroup = uibuttongroup(app.GridLayout2);
            app.HearButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HearButtonGroupSelectionChanged, true);
            app.HearYesButton = uiradiobutton(app.HearButtonGroup);
            app.HearYesButton.Text = 'Yes';
            app.HearNoButton = uiradiobutton(app.HearButtonGroup);
            app.HearNoButton.Text = 'No';
            app.HearNoButton.Value = true;
            app.DetailsLabel = uilabel(app.GridLayout2);
            app.DetailsLabel.Text = 'Details';
            app.HearDetailsEditField = uieditfield(app.GridLayout2, 'text');
            app.HearDetailsEditField.ValueChangedFcn = createCallbackFcn(app, @HearDetailsEditFieldValueChanged, true);
            app.READMEA1Tab = uitab(app.TabGroup);
            app.READMEA1Tab.Title = 'README A-1';
            app.GridLayoutA1 = uigridlayout(app.READMEA1Tab);
            app.PageA2Button = uibutton(app.GridLayoutA1, 'push');
            app.PageA2Button.ButtonPushedFcn = createCallbackFcn(app, @PageA2ButtonPushed, true);
            app.PageA2Button.Text = 'Page 2';
            app.InstructionA1 = uilabel(app.GridLayoutA1);
            app.InstructionA1.Text = {'There are two parts to this test, between which you can take a break.'; ''; 'In each trial of this test, a sound scene is presented. Your task is to judge each sound scene and enter your reponses. In this first part of the test, you will also be asked to select the sounds that you noticed during the trial from a list.'; ''; 'Each sound is played back using the controls. To advance to the next trial, each sound must be played to completion once. When each sound has completed playing, the playback control will automatically switch off. Sounds can also be stopped prematurely using the control. Each sound can be played back as many times as desired. The play control always starts each sound from the beginning of the scene.'; ''; 'Please enter your rating for each sound using the controls. When you have entered your ratings and advanced to the next trial, you will not be able to return to a previous trial to change your ratings. There are no correct or incorrect answers in this test, only personal judgements of subjective impressions.'; ''; 'When evaluating the scenes and providing your responses, please imagine yourself within each presented sound environment, as if you were undertaking a desirable activity within each scene (for example: taking a walk, relaxing, thinking, reading a book, sightseeing, etc).'; ''; 'Please click the button below to move to the next set of instructions, which will describe the types of judgements you will be asked to make.'};
            app.READMEA2Tab = uitab(app.TabGroup);
            app.READMEA2Tab.Title = 'README A-2';
            app.GridLayoutA2 = uigridlayout(app.READMEA2Tab);
            app.PageA3Button = uibutton(app.GridLayoutA2, 'push');
            app.PageA3Button.ButtonPushedFcn = createCallbackFcn(app, @PageA3ButtonPushed, true);
            app.PageA3Button.Text = 'Page 3';
            app.InstructionA2_4 = uilabel(app.GridLayoutA2);
            app.InstructionA2_4.Text = {'The second type of scaled response comprises a numerical rating 0-10, based on how much you feel annoyed, bothered or disturbed while experiencing the sound scene, ranging from ''not at all'' (0) to ''extremely'' (10). You should move the slider to the whole number that is closest to your judgement while experiencing the sound scene. At the start of each trial, this judgement rating scale will begin with a RANDOMLY assigned value — this is an intentional feature.'; ''; 'Please click the button below to advance to the final page of instructions.'};
            app.ImageA2Arousal = uiimage(app.GridLayoutA2);
            app.ImageA2Arousal.ImageSource = fullfile(pathToMLAPP, 'arousal.gif');
            app.InstructionA2_3 = uilabel(app.GridLayoutA2);
            app.InstructionA2_3.Text = 'The following scale represents feelings ranging from ''calm, relaxed, sluggish, dull, or sleepy'' (on the left) to ''excited, stimulated, frenzied, jittery, or wide awake’ (on the right):';
            app.ImageA2Valence = uiimage(app.GridLayoutA2);
            app.ImageA2Valence.ImageSource = fullfile(pathToMLAPP, 'valence.gif');
            app.InstructionA2_2 = uilabel(app.GridLayoutA2);
            app.InstructionA2_2.Text = 'The following scale represents feelings ranging from ''unhappy, annoyed, dissatisfied, melancholic, or despairing'' (on the left) to ''happy, pleased, satisfied, contented, or hopeful’ (on the right):';
            app.InstructionA2_1 = uilabel(app.GridLayoutA2);
            app.InstructionA2_1.Text = {'In the first part of the test you will be asked to enter THREE TYPES of response: the first two response types require ratings to be entered according to your subjective judgements.'; ''; 'The first judgement rating comprises two sets of pictograms, as shown below. Each set of pictograms represents a scale of human feelings. You should select the image in each scale that is closest to your feelings while experiencing the sound scene.'};
            app.READMEA3Tab = uitab(app.TabGroup);
            app.READMEA3Tab.Title = 'README A-3';
            app.GridLayoutA3 = uigridlayout(app.READMEA3Tab);
            app.StartPracticeAButton = uibutton(app.GridLayoutA3, 'push');
            app.StartPracticeAButton.ButtonPushedFcn = createCallbackFcn(app, @StartPracticeButtonAPushed, true);
            app.StartPracticeAButton.Text = 'Start Practice';
            app.InstructionA3 = uilabel(app.GridLayoutA3);
            app.InstructionA3.Text = {'The third type of judgement requires selection of sound sources according to those you have noticed while listening to the scene — a multiple selection list is provided, along with a free text entry box for any other sound sources you noticed that are not listed.'; ''; 'Before you begin the first part of the test, you will be presented with a short practice round. This will help you to get familiar with the types of sounds and the controls used in the test.'; ''; 'The responses you enter in the practice round will not affect the main results of the test.'; ''; 'Please click the start button below to begin the practice round.'; ''; 'You can review the instructions at any time during the test by clicking on the ''README'' tabs above.'};
            app.PartATab = uitab(app.TabGroup);
            app.PartATab.Title = 'Part A';
            app.GridLayoutTestA = uigridlayout(app.PartATab);
            app.trialsLabel = uilabel(app.GridLayoutTestA);
            app.trialsLabel.Text = 'trials';
            app.TotalNA = uilabel(app.GridLayoutTestA);
            app.TotalNA.Text = 'N';
            app.ofLabel = uilabel(app.GridLayoutTestA);
            app.ofLabel.Text = 'of';
            app.CurrentNA = uilabel(app.GridLayoutTestA);
            app.CurrentNA.Text = 'n';
            app.ProceedButtonA = uibutton(app.GridLayoutTestA, 'push');
            app.ProceedButtonA.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonAPushed, true);
            app.ProceedButtonA.Text = 'Next';
            app.PanelTypology = uipanel(app.GridLayoutTestA);
            app.GridLayout3 = uigridlayout(app.PanelTypology);
            app.SourceTypeLabel = uilabel(app.GridLayout3);
            app.SourceTypeLabel.Text = 'Please select the sound sources that you noticed:';
            app.PeopleCheckBox = uicheckbox(app.GridLayout3);
            app.PeopleCheckBox.ValueChangedFcn = createCallbackFcn(app, @PeopleCheckBoxValueChanged, true);
            app.PeopleCheckBox.Text = 'People';
            app.BirdsCheckBox = uicheckbox(app.GridLayout3);
            app.BirdsCheckBox.ValueChangedFcn = createCallbackFcn(app, @BirdsCheckBoxValueChanged, true);
            app.BirdsCheckBox.Text = 'Birds';
            app.RoadvehiclesCheckBox = uicheckbox(app.GridLayout3);
            app.RoadvehiclesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RoadvehiclesCheckBoxValueChanged, true);
            app.RoadvehiclesCheckBox.Text = 'Road vehicles';
            app.AircraftCheckBox = uicheckbox(app.GridLayout3);
            app.AircraftCheckBox.ValueChangedFcn = createCallbackFcn(app, @AircraftCheckBoxValueChanged, true);
            app.AircraftCheckBox.Text = 'Aircraft';
            app.OtherCheckBox = uicheckbox(app.GridLayout3);
            app.OtherCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtherCheckBoxValueChanged, true);
            app.OtherCheckBox.Text = 'Other';
            app.MusicCheckBox = uicheckbox(app.GridLayout3);
            app.MusicCheckBox.ValueChangedFcn = createCallbackFcn(app, @MusicCheckBoxValueChanged, true);
            app.MusicCheckBox.Text = 'Music';
            app.WindleavesCheckBox = uicheckbox(app.GridLayout3);
            app.WindleavesCheckBox.ValueChangedFcn = createCallbackFcn(app, @WindleavesCheckBoxValueChanged, true);
            app.WindleavesCheckBox.Text = 'Wind / leaves';
            app.otherpleasespecifyLabel = uilabel(app.GridLayout3);
            app.otherpleasespecifyLabel.Text = '(Other) — please specify [Enter]:';
            app.OtherspecifyEditField = uieditfield(app.GridLayout3, 'text');
            app.OtherspecifyEditField.ValueChangedFcn = createCallbackFcn(app, @OtherspecifyEditFieldValueChanged, true);
            app.PanelAnnoyanceA = uipanel(app.GridLayoutTestA);
            app.GridLayoutAnnoyanceA = uigridlayout(app.PanelAnnoyanceA);
            app.AnnoyanceSliderA = uislider(app.GridLayoutAnnoyanceA);
            app.AnnoyanceSliderA.Limits = [0 10];
            app.AnnoyanceSliderA.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AnnoyanceSliderA.ValueChangedFcn = createCallbackFcn(app, @AnnoyanceSliderAValueChngd, true);
            app.AnnoyanceSliderA.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AnnoyQALabel = uilabel(app.GridLayoutAnnoyanceA);
            app.AnnoyQALabel.Text = {'If you find the sound annoying, bothersome, or disturbing,'; 'how annoying, bothersome, or disturbing do you judge the sound to be?'};
            app.ExtremelyLabel = uilabel(app.GridLayoutAnnoyanceA);
            app.ExtremelyLabel.Text = 'Extremely';
            app.NotatallLabel = uilabel(app.GridLayoutAnnoyanceA);
            app.NotatallLabel.Text = 'Not at all';
            app.movethesliderALabel = uilabel(app.GridLayoutAnnoyanceA);
            app.movethesliderALabel.Text = '(move the slider)';
            app.PanelSAMA = uipanel(app.GridLayoutTestA);
            app.GridLayoutSAMA = uigridlayout(app.PanelSAMA);
            app.arousal1img = uiimage(app.GridLayoutSAMA);
            app.arousal1img.ImageSource = fullfile(pathToMLAPP, 'arousal1.gif');
            app.arousal2img = uiimage(app.GridLayoutSAMA);
            app.arousal2img.ImageSource = fullfile(pathToMLAPP, 'arousal2.gif');
            app.arousal3img = uiimage(app.GridLayoutSAMA);
            app.arousal3img.ImageSource = fullfile(pathToMLAPP, 'arousal3.gif');
            app.arousal4img = uiimage(app.GridLayoutSAMA);
            app.arousal4img.ImageSource = fullfile(pathToMLAPP, 'arousal4.gif');
            app.arousal5img = uiimage(app.GridLayoutSAMA);
            app.arousal5img.ImageSource = fullfile(pathToMLAPP, 'arousal5.gif');
            app.valence1img = uiimage(app.GridLayoutSAMA);
            app.valence1img.ImageSource = fullfile(pathToMLAPP, 'valence1.gif');
            app.valence2img = uiimage(app.GridLayoutSAMA);
            app.valence2img.ImageSource = fullfile(pathToMLAPP, 'valence2.gif');
            app.valence3img = uiimage(app.GridLayoutSAMA);
            app.valence3img.ImageSource = fullfile(pathToMLAPP, 'valence3.gif');
            app.valence4img = uiimage(app.GridLayoutSAMA);
            app.valence4img.ImageSource = fullfile(pathToMLAPP, 'valence4.gif');
            app.valence5img = uiimage(app.GridLayoutSAMA);
            app.valence5img.ImageSource = fullfile(pathToMLAPP, 'valence5.gif');
            app.Valence1CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Valence1CheckA.ValueChangedFcn = createCallbackFcn(app, @Valence1CheckAValueChanged, true);
            app.Valence1CheckA.Text = '';
            app.Valence2CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Valence2CheckA.ValueChangedFcn = createCallbackFcn(app, @Valence2CheckAValueChanged, true);
            app.Valence2CheckA.Text = '';
            app.Valence3CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Valence3CheckA.ValueChangedFcn = createCallbackFcn(app, @Valence3CheckAValueChanged, true);
            app.Valence3CheckA.Text = '';
            app.Valence4CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Valence4CheckA.ValueChangedFcn = createCallbackFcn(app, @Valence4CheckAValueChanged, true);
            app.Valence4CheckA.Text = '';
            app.Valence5CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Valence5CheckA.ValueChangedFcn = createCallbackFcn(app, @Valence5CheckAValueChanged, true);
            app.Valence5CheckA.Text = '';
            app.Arousal1CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Arousal1CheckA.ValueChangedFcn = createCallbackFcn(app, @Arousal1CheckAValueChanged, true);
            app.Arousal1CheckA.Text = '';
            app.Arousal2CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Arousal2CheckA.ValueChangedFcn = createCallbackFcn(app, @Arousal2CheckAValueChanged, true);
            app.Arousal2CheckA.Text = '';
            app.Arousal3CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Arousal3CheckA.ValueChangedFcn = createCallbackFcn(app, @Arousal3CheckAValueChanged, true);
            app.Arousal3CheckA.Text = '';
            app.Arousal4CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Arousal4CheckA.ValueChangedFcn = createCallbackFcn(app, @Arousal4CheckAValueChanged, true);
            app.Arousal4CheckA.Text = '';
            app.Arousal5CheckA = uicheckbox(app.GridLayoutSAMA);
            app.Arousal5CheckA.ValueChangedFcn = createCallbackFcn(app, @Arousal5CheckAValueChanged, true);
            app.Arousal5CheckA.Text = '';
            app.InstructionSAMA = uilabel(app.GridLayoutSAMA);
            app.InstructionSAMA.Text = 'Please select one image from each row:';
            app.PanelPlayA = uipanel(app.GridLayoutTestA);
            app.GridLayoutPlayA = uigridlayout(app.PanelPlayA);
            app.LampA = uilamp(app.GridLayoutPlayA);
            app.PlaySwitchA = uiswitch(app.GridLayoutPlayA, 'slider');
            app.PlaySwitchA.Items = {'Stop', 'Play'};
            app.PlaySwitchA.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchAValueChanged, true);
            app.PlaySwitchA.Value = 'Stop';
            app.PlaySwitchLabel = uilabel(app.GridLayoutPlayA);
            app.PlaySwitchLabel.Text = 'Sound playback switch';
            app.InstructionPlayA_3 = uilabel(app.GridLayoutPlayA);
            app.InstructionPlayA_3.Text = 'Imagine yourself within each presented sound environment, as if you were undertaking a desirable activity within the scene';
            app.InstructionPlayA_2 = uilabel(app.GridLayoutPlayA);
            app.InstructionPlayA_2.Text = '(You can listen to the scene more than once, but the scene must be played through once to advance.)';
            app.InstructionPlayA_1 = uilabel(app.GridLayoutPlayA);
            app.InstructionPlayA_1.Text = 'Listen to the sound scene, then enter your judgement ratings. ';
            app.READMEB1Tab = uitab(app.TabGroup);
            app.READMEB1Tab.Title = 'README B-1';
            app.GridLayoutB1 = uigridlayout(app.READMEB1Tab);
            app.PageB2Button = uibutton(app.GridLayoutB1, 'push');
            app.PageB2Button.ButtonPushedFcn = createCallbackFcn(app, @PageB2ButtonPushed, true);
            app.PageB2Button.Text = 'Page 2';
            app.InstructionB1 = uilabel(app.GridLayoutB1);
            app.InstructionB1.Text = {'This is the second part of the test. For each trial in this part, a longer sound scene will be presented (there will also be fewer trials). Your task is again to judge each sound scene and enter your reponses. The responses you are asked to enter are identical with the first part of the test, except you will not be asked to select sound sources that you noticed during the scene.'; ''; 'To recap, each sound is played back using the controls. To advance to the next trial, each sound must be played to completion once. When each sound has completed playing, the playback control will automatically switch off. Sounds can also be stopped prematurely using the control. Each sound can be played back as many times as desired. The play control always starts each sound from the beginning of the scene.'; ''; 'Once again, please enter your rating for each sound using the controls. When you have entered your ratings and advanced to the next trial, you will not be able to return to a previous trial to change your ratings. There are no correct or incorrect answers in this test, only personal judgements of subjective impressions.'; ''; 'As before, when evaluating the scenes and providing your responses, please imagine yourself within each presented sound environment, as if you were undertaking a desirable activity within each scene (for example: taking a walk, relaxing, thinking, reading a book, sightseeing, etc).'; ''; 'Please click the button below to move to the next set of instructions, which will again describe the types of judgements you will be asked to make.'};
            app.READMEB2Tab = uitab(app.TabGroup);
            app.READMEB2Tab.Title = 'README B-2';
            app.GridLayoutB2 = uigridlayout(app.READMEB2Tab);
            app.PageB3Button = uibutton(app.GridLayoutB2, 'push');
            app.PageB3Button.ButtonPushedFcn = createCallbackFcn(app, @PageB3ButtonPushed, true);
            app.PageB3Button.Text = 'Page 3';
            app.InstructionB2_4 = uilabel(app.GridLayoutB2);
            app.InstructionB2_4.Text = {'Once again, the second type of scaled response comprises a numerical rating 0-10, based on how much you feel annoyed, bothered or disturbed while experiencing the sound scene, ranging from ''not at all'' (0) to ''extremely'' (10). You should move the slider to the whole number that is closest to your judgement while experiencing the sound scene. At the start of each trial, this judgement rating scale will begin with a RANDOMLY assigned value — this is an intentional feature.'; ''; 'Please click the button below to advance to the final page of instructions.'};
            app.ImageB2Arousal = uiimage(app.GridLayoutB2);
            app.ImageB2Arousal.ImageSource = fullfile(pathToMLAPP, 'arousal.gif');
            app.InstructionB2_3 = uilabel(app.GridLayoutB2);
            app.InstructionB2_3.Text = 'As previously, the following scale represents feelings ranging from ''calm, relaxed, sluggish, dull, or sleepy'' (on the left) to ''excited, stimulated, frenzied, jittery, or wide awake’ (on the right):';
            app.ImageB2Valence = uiimage(app.GridLayoutB2);
            app.ImageB2Valence.ImageSource = fullfile(pathToMLAPP, 'valence.gif');
            app.InstructionB2_2 = uilabel(app.GridLayoutB2);
            app.InstructionB2_2.Text = 'As before, the following scale represents feelings ranging from ''unhappy, annoyed, dissatisfied, melancholic, or despairing'' (on the left) to ''happy, pleased, satisfied, contented, or hopeful’ (on the right):';
            app.InstructionB2_1 = uilabel(app.GridLayoutB2);
            app.InstructionB2_1.Text = {'In the second part of the test you will be asked to enter TWO TYPES of response: these are both the same as for the first part of the test, and require ratings to be entered according to your subjective judgements.'; ''; 'As a reminder, the first judgement rating comprises two sets of pictograms, as shown below. Each set of pictograms represents a scale of human feelings. You should select the image in each scale that is closest to your feelings while experiencing the sound scene.'};
            app.READMEB3Tab = uitab(app.TabGroup);
            app.READMEB3Tab.Title = 'README B-3';
            app.GridLayoutB3 = uigridlayout(app.READMEB3Tab);
            app.StartPracticeBButton = uibutton(app.GridLayoutB3, 'push');
            app.StartPracticeBButton.ButtonPushedFcn = createCallbackFcn(app, @StartPracticeBButtonPushed, true);
            app.StartPracticeBButton.Text = 'Start Practice';
            app.InstructionB3 = uilabel(app.GridLayoutB3);
            app.InstructionB3.Text = {'Before you begin the second part of the test, you will be presented with a short practice round. This will help you to get familiar with the types of sounds and the controls used in the test.'; ''; 'The responses you enter in the practice round will not affect the main results of the test.'; ''; 'Please click the start button below to begin the practice round.'; ''; 'You can review the instructions at any time during the test by clicking on the ''README'' tabs above.'};
            app.PartBTab = uitab(app.TabGroup);
            app.PartBTab.Title = 'Part B';
            app.GridLayoutTestB = uigridlayout(app.PartBTab);
            app.trialsLabel_2 = uilabel(app.GridLayoutTestB);
            app.trialsLabel_2.Text = 'trials';
            app.TotalNB = uilabel(app.GridLayoutTestB);
            app.TotalNB.Text = 'N';
            app.ofLabel_2 = uilabel(app.GridLayoutTestB);
            app.ofLabel_2.Text = 'of';
            app.CurrentNB = uilabel(app.GridLayoutTestB);
            app.CurrentNB.Text = 'n';
            app.ProceedButtonB = uibutton(app.GridLayoutTestB, 'push');
            app.ProceedButtonB.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonBPushed, true);
            app.ProceedButtonB.Text = 'Next';
            app.PanelAnnoyanceB = uipanel(app.GridLayoutTestB);
            app.GridLayoutAnnoyanceB = uigridlayout(app.PanelAnnoyanceB);
            app.AnnoyanceSliderB = uislider(app.GridLayoutAnnoyanceB);
            app.AnnoyanceSliderB.Limits = [0 10];
            app.AnnoyanceSliderB.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AnnoyanceSliderB.ValueChangedFcn = createCallbackFcn(app, @AnnoyanceSliderBValueChanged, true);
            app.AnnoyanceSliderB.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.AnnoyQBLabel = uilabel(app.GridLayoutAnnoyanceB);
            app.AnnoyQBLabel.Text = {'If you find the sound annoying, bothersome, or disturbing,'; 'how annoying, bothersome, or disturbing do you judge the sound to be?'};
            app.ExtremelyLabel_2 = uilabel(app.GridLayoutAnnoyanceB);
            app.ExtremelyLabel_2.Text = 'Extremely';
            app.NotatallLabel_2 = uilabel(app.GridLayoutAnnoyanceB);
            app.NotatallLabel_2.Text = 'Not at all';
            app.movethesliderBLabel = uilabel(app.GridLayoutAnnoyanceB);
            app.movethesliderBLabel.Text = '(move the slider)';
            app.PanelSAMB = uipanel(app.GridLayoutTestB);
            app.GridLayoutSAMB = uigridlayout(app.PanelSAMB);
            app.arousal1img_2 = uiimage(app.GridLayoutSAMB);
            app.arousal1img_2.ImageSource = fullfile(pathToMLAPP, 'arousal1.gif');
            app.arousal2img_2 = uiimage(app.GridLayoutSAMB);
            app.arousal2img_2.ImageSource = fullfile(pathToMLAPP, 'arousal2.gif');
            app.arousal3img_2 = uiimage(app.GridLayoutSAMB);
            app.arousal3img_2.ImageSource = fullfile(pathToMLAPP, 'arousal3.gif');
            app.arousal4img_2 = uiimage(app.GridLayoutSAMB);
            app.arousal4img_2.ImageSource = fullfile(pathToMLAPP, 'arousal4.gif');
            app.arousal5img_2 = uiimage(app.GridLayoutSAMB);
            app.arousal5img_2.ImageSource = fullfile(pathToMLAPP, 'arousal5.gif');
            app.valence1img_2 = uiimage(app.GridLayoutSAMB);
            app.valence1img_2.ImageSource = fullfile(pathToMLAPP, 'valence1.gif');
            app.valence2img_2 = uiimage(app.GridLayoutSAMB);
            app.valence2img_2.ImageSource = fullfile(pathToMLAPP, 'valence2.gif');
            app.valence3img_2 = uiimage(app.GridLayoutSAMB);
            app.valence3img_2.ImageSource = fullfile(pathToMLAPP, 'valence3.gif');
            app.valence4img_2 = uiimage(app.GridLayoutSAMB);
            app.valence4img_2.ImageSource = fullfile(pathToMLAPP, 'valence4.gif');
            app.valence5img_2 = uiimage(app.GridLayoutSAMB);
            app.valence5img_2.ImageSource = fullfile(pathToMLAPP, 'valence5.gif');
            app.Valence1CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Valence1CheckB.ValueChangedFcn = createCallbackFcn(app, @Valence1CheckBValueChanged, true);
            app.Valence1CheckB.Text = '';
            app.Valence2CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Valence2CheckB.ValueChangedFcn = createCallbackFcn(app, @Valence2CheckBValueChanged, true);
            app.Valence2CheckB.Text = '';
            app.Valence3CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Valence3CheckB.ValueChangedFcn = createCallbackFcn(app, @Valence3CheckBValueChanged, true);
            app.Valence3CheckB.Text = '';
            app.Valence4CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Valence4CheckB.ValueChangedFcn = createCallbackFcn(app, @Valence4CheckBValueChanged, true);
            app.Valence4CheckB.Text = '';
            app.Valence5CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Valence5CheckB.ValueChangedFcn = createCallbackFcn(app, @Valence5CheckBValueChanged, true);
            app.Valence5CheckB.Text = '';
            app.Arousal1CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Arousal1CheckB.ValueChangedFcn = createCallbackFcn(app, @Arousal1CheckBValueChanged, true);
            app.Arousal1CheckB.Text = '';
            app.Arousal2CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Arousal2CheckB.ValueChangedFcn = createCallbackFcn(app, @Arousal2CheckBValueChanged, true);
            app.Arousal2CheckB.Text = '';
            app.Arousal3CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Arousal3CheckB.ValueChangedFcn = createCallbackFcn(app, @Arousal3CheckBValueChanged, true);
            app.Arousal3CheckB.Text = '';
            app.Arousal4CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Arousal4CheckB.ValueChangedFcn = createCallbackFcn(app, @Arousal4CheckBValueChanged, true);
            app.Arousal4CheckB.Text = '';
            app.Arousal5CheckB = uicheckbox(app.GridLayoutSAMB);
            app.Arousal5CheckB.ValueChangedFcn = createCallbackFcn(app, @Arousal5CheckBValueChanged, true);
            app.Arousal5CheckB.Text = '';
            app.InstructionSAMB = uilabel(app.GridLayoutSAMB);
            app.InstructionSAMB.Text = 'Please select one image from each row:';
            app.PanelPlayB = uipanel(app.GridLayoutTestB);
            app.GridLayoutPlayB = uigridlayout(app.PanelPlayB);
            app.LampB = uilamp(app.GridLayoutPlayB);
            app.PlaySwitchB = uiswitch(app.GridLayoutPlayB, 'slider');
            app.PlaySwitchB.Items = {'Stop', 'Play'};
            app.PlaySwitchB.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchBValueChanged, true);
            app.PlaySwitchB.Value = 'Stop';
            app.PlaySwitchLabelB = uilabel(app.GridLayoutPlayB);
            app.PlaySwitchLabelB.Text = 'Sound playback switch';
            app.InstructionPlayB_3 = uilabel(app.GridLayoutPlayB);
            app.InstructionPlayB_3.Text = 'Imagine yourself within each presented sound environment, as if you were undertaking a desirable activity within the scene';
            app.InstructionPlayB_2 = uilabel(app.GridLayoutPlayB);
            app.InstructionPlayB_2.Text = '(You can listen to the scene more than once, but the scene must be played through once to advance.)';
            app.InstructionPlayB_1 = uilabel(app.GridLayoutPlayB);
            app.InstructionPlayB_1.Text = 'Listen to the sound scene, then enter your judgement ratings. ';
            app.Posttest1Tab = uitab(app.TabGroup);
            app.Posttest1Tab.Title = 'Post-test-1';
            app.GridLayoutPost1 = uigridlayout(app.Posttest1Tab);
            app.PagePost2Button = uibutton(app.GridLayoutPost1, 'push');
            app.PagePost2Button.ButtonPushedFcn = createCallbackFcn(app, @PagePost2ButtonPushed, true);
            app.PagePost2Button.Text = 'Page 2';
            app.FeedbackEditField = uieditfield(app.GridLayoutPost1, 'text');
            app.PanelPostAttitude = uipanel(app.GridLayoutPost1);
            app.GridLayoutPostAttitude = uigridlayout(app.PanelPostAttitude);
            app.Label = uilabel(app.GridLayoutPostAttitude);
            app.Label.Text = 'Overall, which of these options is closest to your personal attitude towards the use of novel electric aircraft in civil or commercial applications (such as deliveries and transportation)?';
            app.SupportiveCheckBox = uicheckbox(app.GridLayoutPostAttitude);
            app.SupportiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SupportiveCheckBoxValueChanged, true);
            app.SupportiveCheckBox.Text = 'Supportive';
            app.NeutralCheckBox = uicheckbox(app.GridLayoutPostAttitude);
            app.NeutralCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeutralCheckBoxValueChanged, true);
            app.NeutralCheckBox.Text = 'Neutral';
            app.AmbivalentCheckBox = uicheckbox(app.GridLayoutPostAttitude);
            app.AmbivalentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AmbivalentCheckBoxValueChanged, true);
            app.AmbivalentCheckBox.Text = 'Ambivalent';
            app.ConcernedCheckBox = uicheckbox(app.GridLayoutPostAttitude);
            app.ConcernedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConcernedCheckBoxValueChanged, true);
            app.ConcernedCheckBox.Text = 'Concerned';
            app.EditFieldLabel = uilabel(app.GridLayoutPost1);
            app.EditFieldLabel.Text = '*What considerations prompted or governed your responses to the sound scenes?';
            app.EditFieldLabel_2 = uilabel(app.GridLayoutPost1);
            app.EditFieldLabel_2.Text = '*Do you have any thoughts, feelings or comments about the sound sources you recognised during either part of the test? ';
            app.EditFieldLabel_3 = uilabel(app.GridLayoutPost1);
            app.EditFieldLabel_3.Text = '*Do you have any other comments or feedback about the test you''ve undertaken?';
            app.EditFieldLabel_4 = uilabel(app.GridLayoutPost1);
            app.EditFieldLabel_4.Text = '*If you have any personal experience or involvement with novel electric aircraft (such as ''drones'' or ''air-taxis'') could you summarise in a few sentences? ';
            app.ExperienceEditField = uieditfield(app.GridLayoutPost1, 'text');
            app.SourceCommentsEditField = uieditfield(app.GridLayoutPost1, 'text');
            app.SceneConsiderationsEditField = uieditfield(app.GridLayoutPost1, 'text');
            app.InstructionPostTest1 = uilabel(app.GridLayoutPost1);
            app.InstructionPostTest1.Text = {'Please answer the following questions as indicated below, then click on the button to advance to the next page. '; ''; 'Questions marked with a star (*) are OPTIONAL.'};
            app.Posttest2Tab = uitab(app.TabGroup);
            app.Posttest2Tab.Title = 'Post-test-2';
            app.GridLayoutPost2 = uigridlayout(app.Posttest2Tab);
            app.PagePost3Button = uibutton(app.GridLayoutPost2, 'push');
            app.PagePost3Button.ButtonPushedFcn = createCallbackFcn(app, @PagePost3ButtonPushed, true);
            app.PagePost3Button.Text = 'Page 3';
            app.NSSQ5Slider = uislider(app.GridLayoutPost2);
            app.NSSQ5Slider.Limits = [1 6];
            app.NSSQ5Slider.MajorTicks = [1 2 3 4 5 6];
            app.NSSQ5Slider.ValueChangedFcn = createCallbackFcn(app, @NSSQ5SliderValueChanged, true);
            app.NSSQ5Slider.MinorTicks = [];
            app.NSSQ5Slider.Value = 1;
            app.DisagreestronglyLabel_10 = uilabel(app.GridLayoutPost2);
            app.DisagreestronglyLabel_10.Text = 'Disagree strongly';
            app.AgreestronglyLabel_10 = uilabel(app.GridLayoutPost2);
            app.AgreestronglyLabel_10.Text = 'Agree strongly';
            app.NSSQ5Label = uilabel(app.GridLayoutPost2);
            app.NSSQ5Label.Text = 'I get used to most noises without difficulty';
            app.NSSQ4Slider = uislider(app.GridLayoutPost2);
            app.NSSQ4Slider.Limits = [1 6];
            app.NSSQ4Slider.MajorTicks = [1 2 3 4 5 6];
            app.NSSQ4Slider.ValueChangedFcn = createCallbackFcn(app, @NSSQ4SliderValueChanged, true);
            app.NSSQ4Slider.MinorTicks = [];
            app.NSSQ4Slider.Value = 1;
            app.DisagreestronglyLabel_9 = uilabel(app.GridLayoutPost2);
            app.DisagreestronglyLabel_9.Text = 'Disagree strongly';
            app.AgreestronglyLabel_9 = uilabel(app.GridLayoutPost2);
            app.AgreestronglyLabel_9.Text = 'Agree strongly';
            app.NSSQ4Label = uilabel(app.GridLayoutPost2);
            app.NSSQ4Label.Text = 'I get annoyed when my neighbours are noisy';
            app.NSSQ3Slider = uislider(app.GridLayoutPost2);
            app.NSSQ3Slider.Limits = [1 6];
            app.NSSQ3Slider.MajorTicks = [1 2 3 4 5 6];
            app.NSSQ3Slider.ValueChangedFcn = createCallbackFcn(app, @NSSQ3SliderValueChanged, true);
            app.NSSQ3Slider.MinorTicks = [];
            app.NSSQ3Slider.Value = 1;
            app.DisagreestronglyLabel_8 = uilabel(app.GridLayoutPost2);
            app.DisagreestronglyLabel_8.Text = 'Disagree strongly';
            app.AgreestronglyLabel_8 = uilabel(app.GridLayoutPost2);
            app.AgreestronglyLabel_8.Text = 'Agree strongly';
            app.NSSQ3Label = uilabel(app.GridLayoutPost2);
            app.NSSQ3Label.Text = 'I get mad at people who make noise that keeps me from falling asleep or getting work done';
            app.NSSQ2Slider = uislider(app.GridLayoutPost2);
            app.NSSQ2Slider.Limits = [1 6];
            app.NSSQ2Slider.MajorTicks = [1 2 3 4 5 6];
            app.NSSQ2Slider.ValueChangedFcn = createCallbackFcn(app, @NSSQ2SliderValueChanged, true);
            app.NSSQ2Slider.MinorTicks = [];
            app.NSSQ2Slider.Value = 1;
            app.DisagreestronglyLabel_7 = uilabel(app.GridLayoutPost2);
            app.DisagreestronglyLabel_7.Text = 'Disagree strongly';
            app.AgreestronglyLabel_7 = uilabel(app.GridLayoutPost2);
            app.AgreestronglyLabel_7.Text = 'Agree strongly';
            app.NSSQ2Label = uilabel(app.GridLayoutPost2);
            app.NSSQ2Label.Text = 'I find it hard to relax in a place that''s noisy';
            app.NSSQ1Slider = uislider(app.GridLayoutPost2);
            app.NSSQ1Slider.Limits = [1 6];
            app.NSSQ1Slider.MajorTicks = [1 2 3 4 5 6];
            app.NSSQ1Slider.ValueChangedFcn = createCallbackFcn(app, @NSSQ1SliderValueChanged, true);
            app.NSSQ1Slider.MinorTicks = [];
            app.NSSQ1Slider.Value = 1;
            app.DisagreestronglyLabel_6 = uilabel(app.GridLayoutPost2);
            app.DisagreestronglyLabel_6.Text = 'Disagree strongly';
            app.AgreestronglyLabel_6 = uilabel(app.GridLayoutPost2);
            app.AgreestronglyLabel_6.Text = 'Agree strongly';
            app.NSSQ1Label = uilabel(app.GridLayoutPost2);
            app.NSSQ1Label.Text = 'I am sensitive to noise';
            app.InstructionPostTest2_1 = uilabel(app.GridLayoutPost2);
            app.InstructionPostTest2_1.Text = {'Please indicate how much you agree or disagree with the statements in italics below, then click on the button to advance to the final page.'; ''; 'Each input control starts with a RANDOMLY assigned value — this is intentional.'};
            app.Posttest3Tab = uitab(app.TabGroup);
            app.Posttest3Tab.Title = 'Post-test-3';
            app.GridLayoutPost3 = uigridlayout(app.Posttest3Tab);
            app.SubmitPostButton = uibutton(app.GridLayoutPost3, 'push');
            app.SubmitPostButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitPostButtonPushed, true);
            app.SubmitPostButton.Text = 'Submit';
            app.CalmCheckBox = uicheckbox(app.GridLayoutPost3);
            app.CalmCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalmCheckBoxValueChanged, true);
            app.CalmCheckBox.Text = 'Calm';
            app.ChaoticCheckBox = uicheckbox(app.GridLayoutPost3);
            app.ChaoticCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChaoticCheckBoxValueChanged, true);
            app.ChaoticCheckBox.Text = 'Chaotic';
            app.VibrantCheckBox = uicheckbox(app.GridLayoutPost3);
            app.VibrantCheckBox.ValueChangedFcn = createCallbackFcn(app, @VibrantCheckBoxValueChanged, true);
            app.VibrantCheckBox.Text = 'Vibrant';
            app.MonotonousCheckBox = uicheckbox(app.GridLayoutPost3);
            app.MonotonousCheckBox.ValueChangedFcn = createCallbackFcn(app, @MonotonousCheckBoxValueChanged, true);
            app.MonotonousCheckBox.Text = 'Monotonous';
            app.HomeScapeLabel = uilabel(app.GridLayoutPost3);
            app.HomeScapeLabel.Text = 'Which one of these options is closest to how you might describe the typical soundscape of the area in which your current home or residence is located (ie, the sound environment as you typically experience it)?';
            app.UrbanCheckBox = uicheckbox(app.GridLayoutPost3);
            app.UrbanCheckBox.ValueChangedFcn = createCallbackFcn(app, @UrbanCheckBoxValueChanged, true);
            app.UrbanCheckBox.Text = 'Urban';
            app.SuburbanCheckBox = uicheckbox(app.GridLayoutPost3);
            app.SuburbanCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuburbanCheckBoxValueChanged, true);
            app.SuburbanCheckBox.Text = 'Suburban';
            app.RuralCheckBox = uicheckbox(app.GridLayoutPost3);
            app.RuralCheckBox.ValueChangedFcn = createCallbackFcn(app, @RuralCheckBoxValueChanged, true);
            app.RuralCheckBox.Text = 'Rural';
            app.HomeClassLabel = uilabel(app.GridLayoutPost3);
            app.HomeClassLabel.Text = 'Which one of these options is closest to how you would classify the area in which your current home or residence is located?';
            app.PrefernotSexCheckBox = uicheckbox(app.GridLayoutPost3);
            app.PrefernotSexCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrefernotSexCheckBoxValueChanged, true);
            app.PrefernotSexCheckBox.Text = 'Prefer not to answer';
            app.SexDropDownLabel = uilabel(app.GridLayoutPost3);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GridLayoutPost3);
            app.SexDropDown.Items = {'Female', 'Male'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Value = 'Female';
            app.AgeEditFieldLabel = uilabel(app.GridLayoutPost3);
            app.AgeEditFieldLabel.Text = 'Age';
            app.PrefernotAgeCheckBox = uicheckbox(app.GridLayoutPost3);
            app.PrefernotAgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrefernotAgeCheckBoxValueChanged, true);
            app.PrefernotAgeCheckBox.Text = 'Prefer not to answer';
            app.AgeEditField = uieditfield(app.GridLayoutPost3, 'numeric');
            app.AgeEditField.Limits = [18 118];
            app.AgeEditField.RoundFractionalValues = 'on';
            app.AgeEditField.ValueDisplayFormat = '%d';
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeEditField.Value = 18;
            app.InstructionPostTest3 = uilabel(app.GridLayoutPost3);
            app.InstructionPostTest3.Text = 'Please enter the information about yourself as indicated below, then click on the button to submit your responses.';
            app.NationalityEditFieldLabel = uilabel(app.GridLayoutPost3);
            app.NationalityEditFieldLabel.Text = 'Nationality';
            app.NationalityEditField = uieditfield(app.GridLayoutPost3, 'text');
            app.NationalityEditField.ValueChangedFcn = createCallbackFcn(app, @NationalityEditFieldValueChanged, true);
            app.PrefernotNationCheckBox = uicheckbox(app.GridLayoutPost3);
            app.PrefernotNationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrefernotNationCheckBoxValueChanged, true);
            app.PrefernotNationCheckBox.Text = 'Prefer not to answer';
            app.WhatisyourfirstnativelanguageLabel = uilabel(app.GridLayoutPost3);
            app.WhatisyourfirstnativelanguageLabel.Text = 'What is your first (native) language?';
            app.EnglishLanguageCheckBox = uicheckbox(app.GridLayoutPost3);
            app.EnglishLanguageCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnglishLanguageCheckBoxValueChanged, true);
            app.EnglishLanguageCheckBox.Text = 'English';
            app.OtherLanguageCheckBox = uicheckbox(app.GridLayoutPost3);
            app.OtherLanguageCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtherLanguageCheckBoxValueChanged, true);
            app.OtherLanguageCheckBox.Text = 'Other';
            app.pleasespecifyEditFieldLabel = uilabel(app.GridLayoutPost3);
            app.pleasespecifyEditFieldLabel.Text = '(please specify:)';
            app.LanguageEditField = uieditfield(app.GridLayoutPost3, 'text');
            app.LanguageEditField.ValueChangedFcn = createCallbackFcn(app, @LanguageEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REFMAP_Listest1_GUI_2023a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end